classdef NewGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataAnalysisGUI_UIFigure        matlab.ui.Figure
        MainmenuMenu                    matlab.ui.container.Menu
        OpensettingsfileMenu            matlab.ui.container.Menu
        GoonefolderupMenu               matlab.ui.container.Menu
        EntercurrentfolderMenu          matlab.ui.container.Menu
        ReloaddatasetMenu               matlab.ui.container.Menu
        LoadfitfromfileMenu             matlab.ui.container.Menu
        ShowbasichelpMenu               matlab.ui.container.Menu
        ExitprogramMenu                 matlab.ui.container.Menu
        SteadyStateSpectraMenu          matlab.ui.container.Menu
        LoadSSdataMenu                  matlab.ui.container.Menu
        ShowSSdataMenu                  matlab.ui.container.Menu
        ClearSSdataMenu                 matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        ExportPPdataaspdatMenu          matlab.ui.container.Menu
        Export2DIRdataMenu              matlab.ui.container.Menu
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        VersionTextLabel                matlab.ui.control.Label
        StatusMessage                   matlab.ui.control.EditField
        StatusEditFieldLabel            matlab.ui.control.Label
        InteractivemodeLamp             matlab.ui.control.Lamp
        InteractivemodeLampLabel        matlab.ui.control.Label
        SSSploadedLamp                  matlab.ui.control.Lamp
        SSSploadedLampLabel             matlab.ui.control.Label
        DataStatusLamp                  matlab.ui.control.Lamp
        DataloadingstatusLampLabel      matlab.ui.control.Label
        MainTabs                        matlab.ui.container.TabGroup
        PumpProbeTab                    matlab.ui.container.Tab
        PP_SampleInfo                   matlab.ui.control.TextArea
        PP_PlotcontrolsPanel            matlab.ui.container.Panel
        PP_PlotScaleNumber              matlab.ui.control.NumericEditField
        PP_ContourFormat                matlab.ui.control.EditField
        PP_NskipContours                matlab.ui.control.NumericEditField
        PP_NContours                    matlab.ui.control.NumericEditField
        PP_ShowContours_tick            matlab.ui.control.CheckBox
        NofcontoursEditFieldLabel       matlab.ui.control.Label
        PP_PlotScaleSlider              matlab.ui.control.Slider
        ScaleSliderLabel                matlab.ui.control.Label
        PP_RestoredefaultlimitsButton   matlab.ui.control.Button
        PP_MaxDeltaAbs                  matlab.ui.control.EditField
        MaxAbsmODLabel                  matlab.ui.control.Label
        PP_LogTime_tick                 matlab.ui.control.CheckBox
        PP_FilledContours_tick          matlab.ui.control.CheckBox
        PP_SymmetricColourScale         matlab.ui.control.CheckBox
        PP_DeltaAbsPlotRange            matlab.ui.control.EditField
        AbsrangeEditFieldLabel          matlab.ui.control.Label
        MaxtimeLabel                    matlab.ui.control.Label
        MaxWLEditFieldLabel             matlab.ui.control.Label
        PP_ColourScheme                 matlab.ui.control.DropDown
        ColourschemeDropDownLabel       matlab.ui.control.Label
        PP_NWhites                      matlab.ui.control.NumericEditField
        NofwhitelevelsLabel             matlab.ui.control.Label
        PP_MaxTime                      matlab.ui.control.NumericEditField
        PP_MinTime                      matlab.ui.control.NumericEditField
        MintimeLabel                    matlab.ui.control.Label
        PP_MaxWL                        matlab.ui.control.NumericEditField
        PP_MinWL                        matlab.ui.control.NumericEditField
        MinWLEditFieldLabel             matlab.ui.control.Label
        PP_ProbeDetSwitch               matlab.ui.control.Spinner
        PP_ProbeDetectorLabel           matlab.ui.control.Label
        PP_AnalysissubroutinesPanel     matlab.ui.container.Panel
        PP_ShiftT0_button               matlab.ui.control.Button
        PP_Anisotropy_button            matlab.ui.control.Button
        PP_PlotDifference_button        matlab.ui.control.Button
        PP_ConcPowerDep_button          matlab.ui.control.Button
        PP_ILTfit_button                matlab.ui.control.Button
        PP_RemoveOffset_button          matlab.ui.control.Button
        PP_SVDtoLabel                   matlab.ui.control.Label
        PP_SVDend                       matlab.ui.control.Spinner
        PP_SVDstart                     matlab.ui.control.Spinner
        PP_GlobalFitSVD_button          matlab.ui.control.Button
        PP_ShowSVD_button               matlab.ui.control.Button
        PP_ClearSSdataButton            matlab.ui.control.Button
        PP_ShowSSdataButton             matlab.ui.control.Button
        PP_LoadSSdataButton             matlab.ui.control.Button
        PP_AnalyseindividualscansPanel  matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        PP_RecalcScans_Button           matlab.ui.control.Button
        PP_SpectraPerScan_Button        matlab.ui.control.Button
        PP_NBinScans                    matlab.ui.control.Spinner
        BinscansSpinnerLabel            matlab.ui.control.Label
        PP_KineticsPerScan_Button       matlab.ui.control.Button
        PP_OverlayPlots_check           matlab.ui.control.CheckBox
        PP_SavetracesSwitch             matlab.ui.control.Switch
        SavetracesSwitchLabel           matlab.ui.control.Label
        PP_InteractivemodeSwitch        matlab.ui.control.Switch
        InteractivemodeSwitch_2Label    matlab.ui.control.Label
        PP_PlotsandcutsPanel            matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        PP_NormaliseCheckBox            matlab.ui.control.CheckBox
        PP_IncludeSS_CheckBox           matlab.ui.control.CheckBox
        PP_PlotTrSpectraButton          matlab.ui.control.Button
        PP_SurfaceplotButton            matlab.ui.control.Button
        PP_PlotKineticsButton           matlab.ui.control.Button
        PP_ContourplotButton            matlab.ui.control.Button
        PP_PreprocessingPanel           matlab.ui.container.Panel
        PP_PlotNoiseButton              matlab.ui.control.Button
        PP_MaskprobeWLButton            matlab.ui.control.Button
        PP_PlotBackgroundButton         matlab.ui.control.Button
        PP_BackgroundSubtract_timeLabel  matlab.ui.control.Label
        PP_Background_end               matlab.ui.control.NumericEditField
        PP_Background_start             matlab.ui.control.NumericEditField
        PP_SubtractBackground_checkbox  matlab.ui.control.CheckBox
        PP_SvtBkgSubtract_button        matlab.ui.control.Button
        PP_LoadChirpCorr_button         matlab.ui.control.Button
        PP_FitChirp_button              matlab.ui.control.Button
        PP_SelectdatasetPanel           matlab.ui.container.Panel
        PP_DataFolderList               matlab.ui.control.ListBox
        PP_ReloadButton                 matlab.ui.control.Button
        PP_DataFormat                   matlab.ui.control.DropDown
        DataformatDropDownLabel         matlab.ui.control.Label
        PumpProbeAxes                   matlab.ui.control.UIAxes
        Interferometric2DIRTab          matlab.ui.container.Tab
        I2D_SampleInfo                  matlab.ui.control.TextArea
        I2D_PlotControls_Panel          matlab.ui.container.Panel
        I2D_PlotScaleNumber             matlab.ui.control.NumericEditField
        I2D_ContourLineStyle            matlab.ui.control.EditField
        I2D_NskipContours               matlab.ui.control.NumericEditField
        I2D_ShowContours_tick           matlab.ui.control.CheckBox
        I2D_NContours                   matlab.ui.control.NumericEditField
        NofcontoursEditFieldLabel_2     matlab.ui.control.Label
        I2D_PlotScaleSlider             matlab.ui.control.Slider
        ScaleSliderLabel_2              matlab.ui.control.Label
        I2D_SquareAspectRatio_tick      matlab.ui.control.CheckBox
        I2D_TextBackground_tick         matlab.ui.control.CheckBox
        I2D_MaxDeltaAbs                 matlab.ui.control.EditField
        MaxAauLabel                     matlab.ui.control.Label
        I2D_FilledContours_tick         matlab.ui.control.CheckBox
        I2D_SynclimitsButton            matlab.ui.control.Button
        I2D_RestoredefaultlimitsButton  matlab.ui.control.Button
        I2D_SymmetricColourScale        matlab.ui.control.CheckBox
        I2D_CutPlot_tick                matlab.ui.control.CheckBox
        I2D_DeltaAbsPlotRange           matlab.ui.control.EditField
        ArangeLabel                     matlab.ui.control.Label
        I2D_NWhites                     matlab.ui.control.NumericEditField
        NofwhitelvlsLabel               matlab.ui.control.Label
        I2D_MaxWL_probe                 matlab.ui.control.NumericEditField
        I2D_MinWL_probe                 matlab.ui.control.NumericEditField
        I2D_MaxWL_pump                  matlab.ui.control.NumericEditField
        I2D_MinWL_pump                  matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        I2D_ColourScheme                matlab.ui.control.DropDown
        ColSchemeLabel                  matlab.ui.control.Label
        I2D_PumpAxisOrientation         matlab.ui.control.DropDown
        PumpAxisLabel                   matlab.ui.control.Label
        I2D_PlotLegendStyle             matlab.ui.control.DropDown
        LegendLabel                     matlab.ui.control.Label
        PumpWLProbeWLLabel              matlab.ui.control.Label
        I2D_AnalysissubroutinesPanel    matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        I2D_MakeMovie_button            matlab.ui.control.Button
        I2D_SpectralDiffusion_button    matlab.ui.control.Button
        I2D_SubtractSpectra_button      matlab.ui.control.Button
        I2D_TDFit_button                matlab.ui.control.Button
        I2D_2DGCFit_button              matlab.ui.control.Button
        I2D_IntegralDynamics_button     matlab.ui.control.Button
        I2D_ProbeDetSwitch              matlab.ui.control.Spinner
        I2D_ProbeDetectorLabel          matlab.ui.control.Label
        I2D_SubTabs                     matlab.ui.container.TabGroup
        I2D_ProcessingandplottingTab    matlab.ui.container.Tab
        I2D_SavetracesSwitch            matlab.ui.control.Switch
        SavetracesSwitchLabel_2         matlab.ui.control.Label
        I2D_InteractivemodeSwitch       matlab.ui.control.Switch
        InteractivemodeSwitch_2Label_2  matlab.ui.control.Label
        I2D_PlotsandcutsPanel           matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        I2D_NormaliseCheckBox           matlab.ui.control.CheckBox
        I2D_IncludeFTIRCheckBox         matlab.ui.control.CheckBox
        I2D_PlotSlicesButton            matlab.ui.control.Button
        I2D_SurfaceplotButton           matlab.ui.control.Button
        I2D_PlotKineticsButton          matlab.ui.control.Button
        I2D_ContourplotButton           matlab.ui.control.Button
        I2D_ProcessingAndPlottingPanel  matlab.ui.container.Panel
        I2D_SMswitch                    matlab.ui.control.Spinner
        SMDuLabel                       matlab.ui.control.Label
        I2D_ZeropadToNext2K             matlab.ui.control.CheckBox
        I2D_ZeropadFactor               matlab.ui.control.Spinner
        I2D_DoZeropaddingCheckBox       matlab.ui.control.CheckBox
        I2D_ApodisationFunction         matlab.ui.control.DropDown
        I2D_PhaseCoeffs                 matlab.ui.control.EditField
        CoeffsLabel                     matlab.ui.control.Label
        ApodisationDropDownLabel        matlab.ui.control.Label
        cmLabel                         matlab.ui.control.Label
        I2D_pmLabel                     matlab.ui.control.Label
        I2D_PhaseFitRangeEdit           matlab.ui.control.NumericEditField
        I2D_PhaseFitFunction            matlab.ui.control.DropDown
        PhasefitDropDownLabel           matlab.ui.control.Label
        I2D_SaveProbeCalibration_button  matlab.ui.control.Button
        I2D_PumpcorrectionCheckBox      matlab.ui.control.CheckBox
        I2D_AutocalibrateprobeaxisCheckBox  matlab.ui.control.CheckBox
        I2D_ApplychangesButton          matlab.ui.control.Button
        I2D_ScatteringSubDelay          matlab.ui.control.Spinner
        I2D_SubtractScatteringCheckBox  matlab.ui.control.CheckBox
        OtherPlotsTab                   matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        I2D_sec_ShowPixelLine           matlab.ui.control.CheckBox
        I2D_TDPixel                     matlab.ui.control.Spinner
        PixelSpinnerLabel               matlab.ui.control.Label
        I2D_ExtraPlotOption             matlab.ui.container.ButtonGroup
        I2D_sec_PC                      matlab.ui.control.ToggleButton
        I2D_sec_PH                      matlab.ui.control.ToggleButton
        I2D_sec_TD                      matlab.ui.control.ToggleButton
        I2D_SecondaryAxes               matlab.ui.control.UIAxes
        Resultstr2DTab                  matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        I2D_SimulationextraplotsPanel   matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        I2D_S_Plot1Dspec                matlab.ui.control.Button
        I2D_S_PlotAngleHist             matlab.ui.control.Button
        I2D_S_CalcGr                    matlab.ui.control.Button
        I2D_S_WhichTotal                matlab.ui.container.ButtonGroup
        I2D_S_PlotTraject_Single        matlab.ui.control.RadioButton
        I2D_S_PlotTraject_All           matlab.ui.control.RadioButton
        I2D_S_PlotRelConc               matlab.ui.control.Button
        I2D_S_randomLabel               matlab.ui.control.Label
        I2D_S_WhichTraject              matlab.ui.control.Spinner
        I2D_S_PlotTrajectory            matlab.ui.control.Button
        I2D_Transient2Dtype             matlab.ui.control.DropDown
        Transient2DcalcLabel            matlab.ui.control.Label
        I2D_Show2DGCResultsType         matlab.ui.container.ButtonGroup
        I2D_Show2DGC_Residuals          matlab.ui.control.ToggleButton
        I2D_Show2DGC_Fit                matlab.ui.control.ToggleButton
        I2D_LoadFit                     matlab.ui.control.Button
        I2D_Show2DGCresults             matlab.ui.control.CheckBox
        I2D_ShowSpecDiff                matlab.ui.control.CheckBox
        I2D_SelectdatasetPanel          matlab.ui.container.Panel
        I2D_t2delays                    matlab.ui.control.ListBox
        tdelaypsLabel                   matlab.ui.control.Label
        I2D_DataFolderList              matlab.ui.control.ListBox
        I2D_ReloadButton                matlab.ui.control.Button
        I2D_Dataformat                  matlab.ui.control.DropDown
        DataformatDropDownLabel_2       matlab.ui.control.Label
        TwoDAxes                        matlab.ui.control.UIAxes
        SpectrometerCalibration         matlab.ui.container.Tab
        CAL_ShaperCalibrationPanel      matlab.ui.container.Panel
        CAL_Sh_ResetButton              matlab.ui.control.Button
        CAL_Sh_SaveButton               matlab.ui.control.Button
        CAL_Sh_AutoCalButton            matlab.ui.control.Button
        CAL_Sh_FitButton                matlab.ui.control.Button
        CAL_Sh_Lamp4                    matlab.ui.control.Lamp
        CAL_Sh_LoadMaskScansButton      matlab.ui.control.Button
        CAL_Sh_Lamp3                    matlab.ui.control.Lamp
        CAL_Sh_LoadFTIRCalibrationButton  matlab.ui.control.Button
        CAL_Sh_Lamp2                    matlab.ui.control.Lamp
        CAL_Sh_LoadSolventSpectrumButton  matlab.ui.control.Button
        CAL_Sh_Lamp1                    matlab.ui.control.Lamp
        CAL_Sh_LoadPumpSpectrumButton   matlab.ui.control.Button
        CAL_SpectrometerCalibrationPanel  matlab.ui.container.Panel
        CAL_Sp_ResetButton              matlab.ui.control.Button
        CAL_Sp_SaveButton               matlab.ui.control.Button
        CAL_Sp_AutoCalButton            matlab.ui.control.Button
        CAL_Sp_FitButton                matlab.ui.control.Button
        CAL_Sp_Lamp4                    matlab.ui.control.Lamp
        CAL_Sp_InputCalibrationPointsButton  matlab.ui.control.Button
        CAL_Sp_Lamp3                    matlab.ui.control.Lamp
        CAL_Sp_LoadFTIRCalibrationButton  matlab.ui.control.Button
        CAL_Sp_Lamp2                    matlab.ui.control.Lamp
        CAL_Sp_LoadSolventSpectrumButton  matlab.ui.control.Button
        CAL_Sp_Lamp1                    matlab.ui.control.Lamp
        CAL_Sp_LoadProbeSpectrumButton  matlab.ui.control.Button
        CAL_AxesC2                      matlab.ui.control.UIAxes
        CAL_AxesC1                      matlab.ui.control.UIAxes
        CAL_Axes21                      matlab.ui.control.UIAxes
        CAL_Axes11                      matlab.ui.control.UIAxes
        CAL_Axes22                      matlab.ui.control.UIAxes
        CAL_Axes12                      matlab.ui.control.UIAxes
        RootfolderPanel                 matlab.ui.container.Panel
        RootDirEditField                matlab.ui.control.EditField
        CurrentdirectoryEditFieldLabel  matlab.ui.control.Label
        GototodayButton                 matlab.ui.control.Button
        BrowserootdirButton             matlab.ui.control.Button
        UpDirectory                     matlab.ui.control.Button
        Refresh                         matlab.ui.control.Button
    end

    
    properties (Access = public)
        rootdir         % Current root directory
        defaultTRIRdir  % Default TRIR data directory
        defaultTAdir    % Default TA data directory
        default2DIRdir  % Default 2D-IR data directory
        defaultSSdir    % Default FTIR data directory
        defaultFLUPSdir % Default FLUPS data directory
        defaultPPcolormap = 'DkRd/Wh/DkBl';
        default2Dcolormap = 'DkRd/Wh/DkBl';
        SelectKnownDataTypes = false;
        defaultPPtype     = 'TRIR (Sheffield)';

        PP_Data         % Pump-probe data, a structure containing all the pump-probe data for the current dataset
        FTIR_Data       % FT-IR data, a structure containing all the FT-IR data currently loaded
        I2D_Data        % 2D-IR data, a structure containing all the 2D-IR data for the current dataset
        CAL_data
        
        scriptPath      = mfilename('fullpath')    % Path to this script
        SettingsPath    % Path to the settings file
        figNum          = 1;
        version         = "v12.1e / 2022.04.10 / Ricardo J. Fernández-Terán";
    end
    
    properties (Access = private)
    end
    
    methods (Access = public)
        
        function UpdatePlot_PP(app,PlotAxes)
            switch app.PP_LogTime_tick.Value
                case 0
                    app.PP_Data.linlog = 'lin';
                case 1
                    app.PP_Data.linlog = 'log';
            end
            switch app.PP_SubtractBackground_checkbox.Value
                case 1
                    app.PP_Data.rawcorr='CORRECTED';
                    plot2D(app,app.PP_Data.corrdata,PlotAxes,'On',app.PP_ProbeDetSwitch.Value);
                case 0
                    app.PP_Data.rawcorr='RAW';
                    plot2D(app,app.PP_Data.rawsignal,PlotAxes,'On',app.PP_ProbeDetSwitch.Value);
            end
            
        end
        
        function UpdateDataFolderList_PP(app,varargin)
            if isempty(app.rootdir) == 0
                switch app.PP_DataFormat.Value
                case 'Processed DAT files'
                     % Get a list of all files and folders in this folder.
                    files = dir(app.rootdir);
                    % Get only those that are *.pdat
                    files = files(contains({files.name},".pdat",'IgnoreCase',1));
                    % Sort the names and create variables for the list
                    [sorted_names,~]            = sortrows({files.name}');
                    file_names                  = sorted_names;
                case {'TRES (*.dat)';'Lab 5 (*.dat)'} % *.dat files
                    % Get a list of all files and folders in this folder.
                    files = dir(app.rootdir);
                    % Get only those that are *.dat
                    files = files(contains({files.name},".dat",'IgnoreCase',1));
                    % Sort the names and create variables for the list
                    [sorted_names,~]            = sortrows({files.name}');
                    file_names                  = sorted_names;
                case 'Helios TA'
                    % Get a list of all files and folders in this folder.
                    files                       = dir(app.rootdir);
                    % Extract only those that are directories.
                    subFolders                  = files([files.isdir]);
                    if app.SelectKnownDataTypes % remove 2D-IR data from this list
                        subFolders = subFolders(~contains({subFolders.name},"2D",'IgnoreCase',1));
                    end
                    % Sort the names and create variables for the list
                    [sorted_names,~]            = sortrows({subFolders.name}');
                    file_names                  = sorted_names;
                    if ~isempty(file_names)
                        % Update DatafoldersList, removing ".." and "." from the visible list
                        file_names(1:2,:)           = [];
                    end
                    
                    % Get a list of all files and folders in this folder.
                    files = dir(app.rootdir);
                    % Get only those that are *.csv and not "scan" or "fit" files
                    files = files(contains({files.name},".csv",'IgnoreCase',1));
                    files = files(~contains({files.name},"scan",'IgnoreCase',1));
                    files = files(~contains({files.name},"fit",'IgnoreCase',1));
                    % Sort the names and create variables for the list
                    [sorted_names,~]            = sortrows({files.name}');
                    file_names                  = [file_names; sorted_names];
                case {'UniGE TA';'UniGE nsTA'}
                    % Get a list of all files and folders in this folder.
                    files                       = dir(app.rootdir);
                    % Extract only those that are directories.
                    subFolders                  = files([files.isdir]);
                    if app.SelectKnownDataTypes % remove 2D-IR data from this list
                        subFolders = subFolders(~contains({subFolders.name},"2D",'IgnoreCase',1));
                    end
                    % Sort the names and create variables for the list
                    [sorted_names,~]            = sortrows({subFolders.name}');
                    file_names                  = sorted_names;
                    if ~isempty(file_names)
                        % Update DatafoldersList, removing ".." and "." from the visible list
                        file_names(1:2,:)           = [];
                    end
                    
                    % Get a list of all files and folders in this folder.
                    files = dir(app.rootdir);
                    % Get only those that are *.csv and not "scan" or "fit" files
                    files = files(contains({files.name},".dat",'IgnoreCase',1));
                    files = files(~contains({files.name},"scan",'IgnoreCase',1));
                    files = files(~contains({files.name},"fit",'IgnoreCase',1));
                    % Sort the names and create variables for the list
                    [sorted_names,~]            = sortrows({files.name}');
                    file_names                  = [file_names; sorted_names];
                case 'TRIR (Sheffield)'
                    % Get a list of all files and folders in this folder.
                    files                       = dir(app.rootdir);
                    % Extract only those that are directories.
                    subFolders                  = files([files.isdir]);
                    if app.SelectKnownDataTypes % remove 2D-IR data from this list
                        subFolders = subFolders(~contains({subFolders.name},"2D",'IgnoreCase',1));
                    end
                    % Sort the names and create variables for the list
                    [sorted_names,~]            = sortrows({subFolders.name}');
                    file_names                  = sorted_names;
                    if ~isempty(file_names)
                        % Update DatafoldersList, removing ".." and "." from the visible list
                        file_names(1:2,:)           = [];
                    end
                otherwise % = 1,2,3
                    % Get a list of all files and folders in this folder.
                    files                       = dir(app.rootdir);
                    % Extract only those that are directories.
                    subFolders                  = files([files.isdir]);
                    if app.SelectKnownDataTypes % remove 2D-IR data from this list
                        subFolders = subFolders(~contains({subFolders.name},"2D",'IgnoreCase',1));
                    end
                    % Sort the names and create variables for the list
                    [sorted_names,~]            = sortrows({subFolders.name}');
                    file_names                  = sorted_names;
                    if ~isempty(file_names)
                        % Update DatafoldersList, removing ".." and "." from the visible list
                        file_names(1:2,:)           = [];
                    end
                end
                    app.PP_DataFolderList.Items = file_names;
                    if ~isempty(file_names) && isempty(varargin)
                        app.PP_DataFolderList.Value = file_names{1};
                    end
            else
                app.PP_DataFolderList.Items  = {'Available data folders will appear here';'once a root folder is selected'};
            end
        end
        
        function UpdateDataFolderList_I2D(app,varargin)
            if app.rootdir ~= 0 
                switch app.I2D_Dataformat.Value
                    case '2D-IR [Lab 5]'
                        % Get a list of all files and folders in this folder.
                        files = dir(app.rootdir);
                        % Get only those that are *.dat
                        files = files(contains({files.name},".dat",'IgnoreCase',1));
                        % Sort the names and create variables for the list
                        [sorted_names,~]            = sortrows({files.name}');
                        file_names                  = sorted_names;
                        [~,file_names,~] = fileparts(file_names);
                        app.I2D_DataFolderList.Items = file_names;
                        if ~isempty(file_names) && isempty(varargin)
                            app.I2D_DataFolderList.Value = file_names{1};
                        end
                    case {'2D-IR (Sheffield)','2D-IR (RAL/CLF raw)','2D-IR (RAL/CLF proc.)'}
                        % Put list of subfolders into subFolders
                        % Get a list of all files and folders in this folder.
                        files           = dir(app.rootdir);
                        % Extract only those that are directories.
                        subFolders      = files([files.isdir]);
                        if app.SelectKnownDataTypes
                            % Select only data containing "2D" in the name
                            subFolders  = subFolders(contains({subFolders.name},"2D",'IgnoreCase',1) | contains({subFolders.name},"scat",'IgnoreCase',1) | contains({subFolders.name},"cal",'IgnoreCase',1));
                            % Sort the names and create variables for the list
                            dir_struct      = subFolders;
                            [file_names,~]  = sortrows({dir_struct.name}');
                        else
                            % Sort the names and create variables for the list
                            dir_struct      = subFolders;
                            [file_names,~]  = sortrows({dir_struct.name}');
                            % Update DatafoldersList, removing ".." and "." from the visible list
                            file_names(1:2,:)           = [];
                        end
                        app.I2D_DataFolderList.Items = file_names;
                        if ~isempty(file_names) && isempty(varargin)
                            app.I2D_DataFolderList.Value = file_names{1};
                        end
                    otherwise
                        % Put list of subfolders into subFolders
                        % Get a list of all files and folders in this folder.
                        files           = dir(app.rootdir);
                        % Extract only those that are directories.
                        subFolders      = files([files.isdir]);
                        if app.SelectKnownDataTypes
                            % Don't show the temp folders in the list
                            subFolders      = subFolders(~contains({subFolders.name},"temp",'IgnoreCase',1));
                            % Select only data containing "2D" in the name
                            subFolders  = subFolders(contains({subFolders.name},"2D",'IgnoreCase',1) | contains({subFolders.name},"scat",'IgnoreCase',1) | contains({subFolders.name},"cal",'IgnoreCase',1));
                            % Sort the names and create variables for the list
                            dir_struct      = subFolders;
                            [file_names,~]  = sortrows({dir_struct.name}');
                        else
                            % Sort the names and create variables for the list
                            dir_struct      = subFolders;
                            [file_names,~]  = sortrows({dir_struct.name}');
                            % Update DatafoldersList, removing ".." and "." from the visible list
                            file_names(1:2,:)           = [];
                        end
                        app.I2D_DataFolderList.Items = file_names;
                        if ~isempty(file_names) && isempty(varargin)
                            app.I2D_DataFolderList.Value = file_names{1};
                        end
                end
            else
                app.I2D_DataFolderList.Items    = {'Available data folders will appear here';'once a root folder is selected'};
                app.I2D_t2delays.Items          = {'Select';'t₂ delay'};
            end
        end    
 
        function [exitcode,varargout] = LoadProcessI2DData(app,varargin)
            % Write datafilename to the data structure
            if ~isempty(varargin)
                app.I2D_Data.datafilename = varargin{1};
            else
                app.I2D_Data.datafilename = app.I2D_DataFolderList.Value;    
            end
            
            app.I2D_Data.rootdir      = app.rootdir;
            switch app.I2D_Dataformat.Value
                case 'Transient 2D-IR'
                    app.I2D_Data.Transient2D        = 1;
                    app.I2D_Transient2Dtype.Enable  = 'on';
                    app.I2D_Data.Transient2D_mode   = app.I2D_Transient2Dtype.Value;
                otherwise
                    app.I2D_Data.Transient2D        = 0;
                    app.I2D_Transient2Dtype.Enable  = 'off';
            end
            
            app.I2D_ProbeDetSwitch.Value = 1; % Reset to detector 1
            % Load the data (replace handles) and update the main handles
            try
               % Check the number of t2 delays
               OldNt2delays = length(app.I2D_t2delays.Items);
               
               %%% Load the data
               app.DataStatusLamp.Color     = [0.93,0.69,0.13];
               switch app.I2D_Dataformat.Value
                   case '2D-IR (Sheffield)'
                       app.I2D_Data = load2DIR_UoS(app.I2D_Data);
                   case '2D-IR (RAL/CLF proc.)'
                       app.I2D_Data = load2DIR_RALproc(app.I2D_Data);
                   case '2D-IR (RAL/CLF raw)'
                       app.I2D_Data = load2DIR_RALraw(app.I2D_Data);
                   case {'2D-IR [Lab 1 & Lab 4]', 'Transient 2D-IR'}
                       app.I2D_Data = load2DIRlab1(app.I2D_Data);
                   case '2D-IR simulation'
                       app.I2D_Data = load2DIRsimu(app.I2D_Data);
                       app.I2D_S_WhichTraject.Limits = [0,app.I2D_Data.simData.Nsamples];
                   case '2D-IR [Lab 3]'
                       app.I2D_Data = load2DIRlab3(app.I2D_Data);
                   case '2D-IR [Lab 5]'
                       app.I2D_Data = load2DIRlab5(app.I2D_Data);
               end
               

               %%% Process the data
               app.DataStatusLamp.Color     = [1 1 0];
               bkgIdx = app.I2D_ScatteringSubDelay.Value;
               switch app.I2D_Dataformat.Value
                   case '2D-IR (Sheffield)'
                       app.I2D_Data = process2DIR_UoS(app,app.I2D_Data,0,bkgIdx);
                   case '2D-IR (RAL/CLF raw)'
                       app.I2D_Data = process2DIR_RALraw(app.I2D_Data,bkgIdx);
                   case {'2D-IR (RAL/CLF proc.)', '2D-IR [Lab 3]', '2D-IR simulation'}
                        % Processing is done before...
                   case '2D-IR [Lab 5]'
                        app.I2D_Data = process2DIR_shaper(app,app.I2D_Data,0,bkgIdx);
                   otherwise
                        app.I2D_Data = process2DIR(app,app.I2D_Data,0,bkgIdx);
               end
               
               % Set t2 delay control values
               switch app.I2D_Dataformat.Value
                   case 'Transient 2D-IR'
                       app.I2D_t2delays.Items       = string(num2str(app.I2D_Data.t2delays));
                   otherwise
                       app.I2D_t2delays.Items       = string(app.I2D_Data.t2delays);
               end
               app.I2D_t2delays.ItemsData   = 1:1:size(app.I2D_Data.t2delays,1);
               % Ensure that datasets with less t2 delays are loaded correctly
               NewNt2delays = size(app.I2D_Data.t2delays,1);
               if NewNt2delays < OldNt2delays
                   app.I2D_Data.I2D_t2delays.Value = app.I2D_Data.t2delays(1);
               end
               
               % Delete progress bar
               delete(app.I2D_Data.WBfigure);
               % Clear error string
               app.StatusMessage.Value      = 'Data loaded and processed successfully!';
               app.DataStatusLamp.Color     = [0 1 0];
               app.I2D_SubTabs.Visible                      = 'on';
               app.I2D_AnalysissubroutinesPanel.Visible     = 'on';
               app.I2D_PlotControls_Panel.Visible           = 'on';
               app.I2D_SampleInfo.Visible                   = 'on';
               app.I2D_t2delays.Enable                      = 'on';
               app.I2D_LoadFit.Enable                       = 'on';
               app.LoadfitfromfileMenu.Enable               = 'on';
               
               switch app.I2D_Dataformat.Value
                   case {'2D-IR (RAL/CLF proc.)', '2D-IR [Lab 3]', '2D-IR simulation'}
                       app.I2D_ProcessingAndPlottingPanel.Enable    = 'off';                    
                   otherwise
                       app.I2D_ProcessingAndPlottingPanel.Enable    = 'on';
               end
               
               switch app.I2D_Dataformat.Value
                   case {'2D-IR (RAL/CLF proc.)','2D-IR (RAL/CLF raw)','2D-IR (Sheffield)'}
                       app.I2D_ProbeDetSwitch.Visible               = 'on';
                       app.I2D_ProbeDetectorLabel.Visible           = 'on';
                       app.I2D_ProbeDetSwitch.Enable                = 'on';
                       app.I2D_ProbeDetectorLabel.Enable            = 'on';
                       app.I2D_PumpcorrectionCheckBox.Value         = false;
                       app.I2D_SquareAspectRatio_tick.Value         = false;
                       app.I2D_sec_PH.Enable                        = 'off';
                       app.I2D_sec_TD.Value                         = true;
                       app.I2D_sec_PC.Value                         = false;
                       app.I2D_TDPixel.Value                        = 1;
                   otherwise
                       app.I2D_ProbeDetSwitch.Visible               = 'off';
                       app.I2D_ProbeDetectorLabel.Visible           = 'off';
                       app.I2D_ProbeDetSwitch.Enable                = 'off';
                       app.I2D_ProbeDetectorLabel.Enable            = 'off';
                       app.I2D_PumpcorrectionCheckBox.Value         = true;
                       app.I2D_SquareAspectRatio_tick.Value         = true;
                       app.I2D_sec_PH.Enable                        = 'on';
                       app.I2D_sec_PH.Value                         = true;
               end
               app.I2D_ScatteringSubDelay.Enable            = 'on';
               app.I2D_ScatteringSubDelay.Visible           = 'on';
               app.I2D_ScatteringSubDelay.Limits            = [1 app.I2D_Data.Ndelays];
               
               %%% Show special simulation extra plot options
               if app.I2D_Data.isSimulation == 1
                   app.I2D_SimulationextraplotsPanel.Visible= 'on';
               else
                   app.I2D_SimulationextraplotsPanel.Visible= 'off';
               end
               
               %%% Copy Processed data to potential vargout (for subtraction of datasets)
               varargout{1} = app.I2D_Data.PROC_2D_DATA;
               
               exitcode=0;             
            catch err
               app.StatusMessage.Value      = string(err.message);
               app.DataStatusLamp.Color     = [1 0 0];
               cla(app.TwoDAxes);
               app.TwoDAxes.Visible                         = 'off';
               app.I2D_SubTabs.Visible                      = 'off';
               app.I2D_AnalysissubroutinesPanel.Visible     = 'off';
               app.I2D_PlotControls_Panel.Visible           = 'off';
               app.I2D_SampleInfo.Visible                   = 'off';
               app.I2D_t2delays.Enable                      = 'off';
               app.I2D_Transient2Dtype.Enable               = 'off';
               app.I2D_LoadFit.Enable                       = 'off';
               app.LoadfitfromfileMenu.Enable               = 'off';
               app.I2D_SimulationextraplotsPanel.Visible    = 'off';
               exitcode=1;
               delete(app.I2D_Data.WBfigure);
               error(getReport(err));
               app.I2D_Data                 = [];
               app.I2D_ProbeDetSwitch.Visible               = 'off';
               app.I2D_ProbeDetectorLabel.Visible           = 'off';
               app.I2D_ProbeDetSwitch.Enable                = 'off';
               app.I2D_ProbeDetectorLabel.Enable            = 'off';
               app.I2D_ScatteringSubDelay.Enable            = 'off';
               app.I2D_ScatteringSubDelay.Visible           = 'off';
            end
            % Reset Results & T2D tab
               app.I2D_Show2DGCresults.Enable               = 'off';
               app.I2D_Show2DGC_Fit.Enable                  = 'off';
               app.I2D_Show2DGC_Residuals.Enable            = 'off';
               app.I2D_Show2DGCresults.Value                = 0;
               app.I2D_Show2DGC_Fit.Value                   = 1;
        end
    
        function exitcode = LoadPPData(app,specdisplay,anisotropytype,recalcScans,recalcBkg)
            data                            = app.PP_Data;
            data.rootdir                    = app.rootdir;
            data.datafilename               = app.PP_DataFolderList.Value;
            data.chirpCorr                  = 0;
            
            app.StatusMessage.Value         = 'Loading data...';
            app.DataStatusLamp.Color        = [0.93 0.69 0.13];
            
            if isempty(specdisplay)
                specdisplay = 1;
            end
            
            if isempty(anisotropytype)
                anisotropytype = 'NONE';
            end
            
            if isempty(recalcScans)
                recalcScans = 0;
            end
            
            if isempty(recalcBkg)
                data.recalcBkg              = 0;
            else
                data.mintimeBkg             = app.PP_Background_start.Value;
                data.maxtimeBkg             = app.PP_Background_end.Value;
                data.recalcBkg              = recalcBkg;
            end
            
            app.DataStatusLamp.Color     = [1 1 0];
            
            try            
               switch app.PP_DataFormat.Value
                   case 'Processed DAT files'
                       data                 = LoadPDAT(data);
                       % Probe units and other settings are defined in LoadPDAT, since they are read from the file.
                       
                   case 'TRIR (Sheffield)'
                       data                 = LoadDataIR_UoS(data,recalcScans);
                       data.probeunits      = 'Wavenumbers';
                       data.Xunits          = 'cm^{-1}';
                   case 'Helios TA'   
                       data                 = LoadDataHelios(data);
                       data.probeunits      = 'Wavelength';
                       data.Xunits          = 'nm';
                   case 'UniGE TA'   
                       data                 = LoadDataTAUniGE(data,recalcScans);
                       data.probeunits      = 'Wavelength';
                       data.Xunits          = 'nm';
                   case 'UniGE nsTA'   
                       data                 = LoadData_nsTAUniGE(data);
                       data.probeunits      = 'Wavelength';
                       data.Xunits          = 'nm';
                   case 'Pump-Probe [Lab 2]'
                       data                 = LoadDataIR2(data,recalcScans);                     
                       data.probeunits      = 'Wavenumbers';
                       data.Xunits          = 'cm^{-1}';
                   case 'Pump-Probe [Lab 1 & Lab 4]'
                       data                 = LoadDataIR1(data,specdisplay,anisotropytype,recalcScans);
                       data.probeunits      = 'Wavenumbers';
                       data.Xunits          = 'cm^{-1}';
                   case 'Pump-Probe [TRUVIS]' % TRUVIS
                       data                 = LoadDataTRUVIS(data,1);
                       data.probeunits      = 'Wavelength';
                       data.Xunits          = 'nm';
                   
                   case 'TRES (*.dat)'
                       
                       
                   case 'Lab 5 (*.dat)'
                       data                 = LoadDataIR5(data);
                       data.probeunits      = 'Wavenumbers';
                       data.Xunits          = 'cm^{-1}';                       
               end
               % Clear error string
               app.StatusMessage.Value      = 'Data loaded successfully!';
               app.DataStatusLamp.Color     = [0 1 0];
               app.PP_Data                  = data;
               exitcode=0;
               % Show controls
               app.PP_PreprocessingPanel.Visible            = 'on';
               app.PP_PlotsandcutsPanel.Visible             = 'on';
               app.PP_AnalyseindividualscansPanel.Visible   = 'on';
               app.PP_AnalysissubroutinesPanel.Visible      = 'on';
               app.PP_PlotcontrolsPanel.Visible             = 'on';
               app.PP_SampleInfo.Visible                    = 'on';
               app.PP_OverlayPlots_check.Visible            = 'on';
               
               app.PP_ProbeDetSwitch.Value = 1;
               switch app.PP_DataFormat.Value
                   case 'TRIR (Sheffield)'
                       app.PP_ProbeDetSwitch.Visible        = 'on';
                       app.PP_ProbeDetectorLabel.Visible    = 'on';
                       app.PP_ProbeDetSwitch.Enable         = 'on';
                       app.PP_ProbeDetectorLabel.Enable     = 'on';
                   otherwise
                       app.PP_ProbeDetSwitch.Visible        = 'off';
                       app.PP_ProbeDetectorLabel.Visible    = 'off';
                       app.PP_ProbeDetSwitch.Enable         = 'off';
                       app.PP_ProbeDetectorLabel.Enable     = 'off';
               end
               
               % Set the timescale units
               app.PP_BackgroundSubtract_timeLabel.Text     = app.PP_Data.timescale;
            catch err
               app.StatusMessage.Value      = string(err.message);
               app.DataStatusLamp.Color     = [1 0 0];
               cla(app.PumpProbeAxes);
               app.PumpProbeAxes.Visible    = 'off';
               exitcode=1;            
               % Hide controls
               app.PP_PreprocessingPanel.Visible            = 'off';
               app.PP_PlotsandcutsPanel.Visible             = 'off';
               app.PP_AnalyseindividualscansPanel.Visible   = 'off';
               app.PP_AnalysissubroutinesPanel.Visible      = 'off';
               app.PP_PlotcontrolsPanel.Visible             = 'off';
               app.PP_SampleInfo.Visible                    = 'off';
               app.PP_OverlayPlots_check.Visible            = 'off';
               app.PP_ProbeDetSwitch.Visible                = 'off';
               app.PP_ProbeDetectorLabel.Visible            = 'off';
               app.PP_ProbeDetSwitch.Enable                 = 'off';
               app.PP_ProbeDetectorLabel.Enable             = 'off';
            end
            return
        end
        
        function UpdateInfo_PP(app,mode)
            k = app.PP_ProbeDetSwitch.Value;
            switch mode
                case 'Write'
                    plotranges = app.PP_Data.plotranges{k};
                    app.PP_MinTime.Value            = plotranges(1);
                    app.PP_MaxTime.Value            = plotranges(2);
                    app.PP_MinWL.Value              = plotranges(3);
                    app.PP_MaxWL.Value              = plotranges(4);
                    app.PP_MaxDeltaAbs.Value        = [num2str(plotranges(5),'%.3f') ', ' num2str(plotranges(6),'%.3f')];
                    app.PP_NContours.Value          = plotranges(7);
                    plotPercent                     = app.PP_PlotScaleSlider.Value/100;
                    zminmax                         = round(max([abs(plotranges(5)) abs(plotranges(6))]),3);
                    if app.PP_SymmetricColourScale.Value == 1
                        minplot = -zminmax*plotPercent;
                        maxplot = +zminmax*plotPercent;
                    else
                        minplot = plotranges(5)*plotPercent;
                        maxplot = plotranges(6)*plotPercent;
                    end
                    app.PP_DeltaAbsPlotRange.Value  = [num2str(minplot,'%.3f') ', ' num2str(maxplot,'%.3f')];
                case 'Read'
                    plotranges                      = app.PP_Data.plotranges{k};
                    plotranges(1)                   = app.PP_MinTime.Value;
                    plotranges(2)                   = app.PP_MaxTime.Value;
                    plotranges(3)                   = app.PP_MinWL.Value;
                    plotranges(4)                   = app.PP_MaxWL.Value;
                    plotPercent                     = app.PP_PlotScaleSlider.Value/100;
                    zminmax                         = round(max([abs(plotranges(5)) abs(plotranges(6))]),3);
                    if app.PP_SymmetricColourScale.Value == 1
                        minplot = -zminmax*plotPercent;
                        maxplot = +zminmax*plotPercent;
                    else
                        minplot = plotranges(5)*plotPercent;
                        maxplot = plotranges(6)*plotPercent;
                    end
                    app.PP_DeltaAbsPlotRange.Value  = [num2str(minplot,'%.3f') ', ' num2str(maxplot,'%.3f')];
                    app.PP_Data.plotranges{k}       = plotranges;
            end
        end
        
        function UpdateInfo_I2D(app,mode)
            k = app.I2D_ProbeDetSwitch.Value;
            m = app.I2D_t2delays.Value;
            switch mode
                case 'Init' % Initialise controls
                    app.I2D_CutPlot_tick.Value      = 1;
                    app.I2D_MaxWL_probe.Limits      = [min(app.I2D_Data.ProbeAxis{k}) max(app.I2D_Data.ProbeAxis{k})];
                    app.I2D_MinWL_probe.Limits      = [min(app.I2D_Data.ProbeAxis{k}) max(app.I2D_Data.ProbeAxis{k})];
                    app.I2D_MinWL_probe.Value       = min(app.I2D_Data.ProbeAxis{k});
                    app.I2D_MaxWL_probe.Value       = max(app.I2D_Data.ProbeAxis{k});
                    
                    app.I2D_NContours.Value         = 40;
                    app.I2D_PlotScaleSlider.Value   = 100;
                    app.I2D_PlotScaleNumber.Value   = 100;
                    app.I2D_NWhites.Value           = 2;
%                     if unique(diff(app.I2D_Data.ProbeAxis{k})) == 1
%                         minindex                = 1;
%                         maxindex                = round(length(app.I2D_Data.PumpAxis{m,k})/2);
%                     else
                        minindex                = findClosestId2Val(app.I2D_Data.PumpAxis{m,k},min(app.I2D_Data.ProbeAxis{k}));
                        maxindex                = findClosestId2Val(app.I2D_Data.PumpAxis{m,k},max(app.I2D_Data.ProbeAxis{k}));
%                     end
                    
%                     switch app.I2D_Dataformat.Value
%                         case '2D-IR (Sheffield)'
%                             minindex = 1;
%                     end
                    Nbins = length(app.I2D_Data.PumpAxis{m,k});
                    app.I2D_MinWL_pump.Limits       = [0 max(app.I2D_Data.PumpAxis{m,k}(Nbins/2))];
                    app.I2D_MaxWL_pump.Limits       = [0 max(app.I2D_Data.PumpAxis{m,k}(Nbins/2))];
                    app.I2D_MinWL_pump.Value        = app.I2D_Data.PumpAxis{m,k}(minindex);
                    app.I2D_MaxWL_pump.Value        = app.I2D_Data.PumpAxis{m,k}(maxindex);
                    maxabs                          = max(app.I2D_Data.PROC_2D_DATA{m,k}(minindex:maxindex,:),[],'all');
                    minabs                          = min(app.I2D_Data.PROC_2D_DATA{m,k}(minindex:maxindex,:),[],'all');
                    app.I2D_MaxDeltaAbs.Value       = [num2str(minabs,'%.4g') ', ' num2str(maxabs,'%.4g')];
                    app.I2D_DeltaAbsPlotRange.Value = [num2str(minabs,'%.4g') ', ' num2str(maxabs,'%.4g')];
                    app.I2D_ShowSpecDiff.Enable     = 'off';
                    app.I2D_ShowSpecDiff.Value      = 0;
                    app.I2D_PhaseCoeffs.Value       = num2str(app.I2D_Data.phase_coeff{m,k},'%.3g');
                case 'Update' % Write to the controls
%                     if unique(diff(app.I2D_Data.ProbeAxis{k})) == 1
%                         minindex                = 1;
%                         maxindex                = round(length(app.I2D_Data.PumpAxis{m,k})/2);
%                     else
                        minindex                = findClosestId2Val(app.I2D_Data.PumpAxis{m,k},min(app.I2D_Data.ProbeAxis{k}));
                        maxindex                = findClosestId2Val(app.I2D_Data.PumpAxis{m,k},max(app.I2D_Data.ProbeAxis{k}));
%                     end
                    maxabs                          = max(app.I2D_Data.PROC_2D_DATA{m,k}(minindex:maxindex,:),[],'all');
                    minabs                          = min(app.I2D_Data.PROC_2D_DATA{m,k}(minindex:maxindex,:),[],'all');
                    app.I2D_MaxDeltaAbs.Value       = [num2str(minabs,'%.4g') ', ' num2str(maxabs,'%.4g')];
                    app.I2D_DeltaAbsPlotRange.Value = [num2str(app.I2D_Data.PlotRanges.min_cut,'%.4g') ', ' num2str(app.I2D_Data.PlotRanges.max_cut,'%.4g')];
                    app.I2D_PhaseCoeffs.Value       = num2str(app.I2D_Data.phase_coeff{m,k},'%.3g');
                case 'Read' % Read from the GUI to the ContourPlot_2DIR subroutine
                    plotOptions.cut                 = app.I2D_CutPlot_tick.Value;
                    plotOptions.minWL_pump          = app.I2D_MinWL_pump.Value;
                    plotOptions.maxWL_pump          = app.I2D_MaxWL_pump.Value;
                    plotOptions.minWL_probe         = app.I2D_MinWL_probe.Value;
                    plotOptions.maxWL_probe         = app.I2D_MaxWL_probe.Value;
                    plotOptions.plot_pumpdirection  = app.I2D_PumpAxisOrientation.Value;
                    plotOptions.plot_contourfill    = app.I2D_FilledContours_tick.Value;
                    plotOptions.plot_Nwhites        = app.I2D_NWhites.Value;
                    plotOptions.Ncontours           = app.I2D_NContours.Value;
                    plotOptions.ContourLineStyle    = app.I2D_ContourLineStyle.Value;
                    plotOptions.plot_axislegend     = app.I2D_PlotLegendStyle.Value;
                    plotOptions.plot_colourrange    = app.I2D_PlotScaleNumber.Value;
                    plotOptions.plot_colourscheme   = app.I2D_ColourScheme.Value;
                    plotOptions.plot_showcontours   = app.I2D_ShowContours_tick.Value;
                    plotOptions.plot_skiplevels     = app.I2D_NskipContours.Value;
                    plotOptions.symcolrange         = app.I2D_SymmetricColourScale.Value;
                    plotOptions.popdelay            = app.I2D_t2delays.Value;
                    plotOptions.ShowSpecDiff        = app.I2D_ShowSpecDiff.Value;
                    plotOptions.plotFitResults      = app.I2D_Show2DGCresults.Value;
                    plotOptions.plotResidualsFit    = app.I2D_Show2DGC_Residuals.Value;
                    plotOptions.TextBackground      = app.I2D_TextBackground_tick.Value;
                    plotOptions.squarebox           = app.I2D_SquareAspectRatio_tick.Value;
                    
                    % Get the full path of this file
                    path = getFilePath(app);
            
                    % Read the default directories from the GUIoptions file
                    if exist([path filesep 'GUIoptions.txt'],'file')==2
                        plotOptions.plotVersion     = readParam('2DIRContourPlotVersion',[path filesep 'GUIoptions.txt']);
                    else
                        plotOptions.plotVersion     = '2';
                    end
                    
                    app.I2D_Data.plotOptions        = plotOptions;
                    app.I2D_TDPixel.Limits          = [0,length(app.I2D_Data.ProbeAxis{k})];
            end
        end
    
        function string = ReadPPSampleInfo(app)
            DET = app.PP_ProbeDetSwitch.Value;
            string={};
            string{1} = sprintf('Avg. Noise: %.4f mOD \t Max Noise: %.4f mOD',app.PP_Data.AvgNoise,app.PP_Data.MaxNoise);
            if isnan(app.PP_Data.Nscans)
                scanstr = 'Avg. Data.';
            else
                scanstr = sprintf('Nº of Scans: %i',app.PP_Data.Nscans);
            end
            string{2} = [sprintf('SNR: %.2f / ',app.PP_Data.SNR) scanstr sprintf(' / %i delays ',length(app.PP_Data.delays)) sprintf('/ Res.: %.2f %s',mean(diff(app.PP_Data.cmprobe{DET}))),app.PP_Data.Xunits];
            
            sampleinfoFilename = [app.rootdir filesep app.PP_Data.datafilename filesep app.PP_Data.datafilename '_SampleInfo.txt'];
            if exist(sampleinfoFilename,'file') == 2
               string{3} = 'Sample information (see below):';
               sampleinfo_Table     = readtable(sampleinfoFilename,'delimiter',':','readvariablenames',false);
               sampleinfo_Cell      = table2cell(sampleinfo_Table);
               sampleinfo_strings   = cell(1,size(sampleinfo_Cell,1));
               for i=1:size(sampleinfo_Cell,1)
                   if ~isempty(sampleinfo_Cell{i,2})
                       sampleinfo_strings{i}    = [sampleinfo_Cell{i,1} ': ' sampleinfo_Cell{i,2}];    
                   else
                       sampleinfo_strings{i}    = [sampleinfo_Cell{i,1} ': N/A'];
                   end
               end
               string       = [string, sampleinfo_strings];
            else
               string{3}    = 'No additional sample info available.';
            end
        end
    
        function string = ReadI2DSampleInfo(app,varargin)
            m           = app.I2D_t2delays.Value;
            k           = app.I2D_ProbeDetSwitch.Value;
            % Show resolution statistics
            resW1       = app.I2D_Data.PumpAxis{m,k}(2)-app.I2D_Data.PumpAxis{m,k}(1);
            resW3       = mean(diff(app.I2D_Data.ProbeAxis{k}));
            string={};
            string{1} = sprintf('ω1 res.: %.2f cm⁻¹   ω3 res.: %.2f cm⁻¹   FT size: %u pts.',resW1,resW3,length(app.I2D_Data.PumpAxis{m,k}));
            string{2} = sprintf('Bin zero: %u \t Phase: %.4f rad. \t %u t₂ delays.',app.I2D_Data.binzero{m,k},wrapToPi(app.I2D_Data.ZP_phase{m,k}(app.I2D_Data.binspecmax(m,k))),length(app.I2D_Data.t2delays));
            
            if isempty(varargin)
                sampleinfoFilename = [app.rootdir filesep app.I2D_Data.datafilename filesep app.I2D_Data.datafilename '_SampleInfo.txt'];
                if exist(sampleinfoFilename,'file') == 2
                   string{3} = 'Sample information (see below):';
                   sampleinfo_Table     = readtable(sampleinfoFilename,'delimiter',':','readvariablenames',false);
                   sampleinfo_Cell      = table2cell(sampleinfo_Table);
                   sampleinfo_strings   = cell(1,size(sampleinfo_Cell,1));
                   for i=1:size(sampleinfo_Cell,1)
                       if ~isempty(sampleinfo_Cell{i,2})
                           sampleinfo_strings{i}    = [sampleinfo_Cell{i,1} ': ' sampleinfo_Cell{i,2}];    
                       else
                           sampleinfo_strings{i}    = [sampleinfo_Cell{i,1} ': N/A'];
                       end
                   end
                   string       = [string, sampleinfo_strings];
                else
                   string{3}    = 'No additional sample info available.';
                end
            else
                string      = [string, app.I2D_SampleInfo.Value{3:end}];
            end
        end
        
        function UpdatePlot_I2D(app,PlotAxes,varargin)
            k = app.I2D_ProbeDetSwitch.Value;
            app.I2D_Data.PlotRanges = ContourPlot_2DIR(app.I2D_Data.plotOptions,app.I2D_Data,PlotAxes,k);
            app.TwoDAxes.Visible    = 'On';
            switch app.I2D_SquareAspectRatio_tick.Value
                case 1
                    app.TwoDAxes.PlotBoxAspectRatio     = [1 1 1];
                    app.TwoDAxes.DataAspectRatio        = [1 1 1];
                    app.TwoDAxes.DataAspectRatioMode    = 'manual';
                    app.TwoDAxes.PlotBoxAspectRatioMode = 'manual';
                case 0
                    app.TwoDAxes.DataAspectRatioMode    = 'auto';
                    app.TwoDAxes.PlotBoxAspectRatioMode = 'auto';
            end
            if strcmp(varargin{1},'Update') && app.I2D_Data.isSimulation ~= 1 && app.I2D_Data.isShaper ~= 1
                % Generate the secondary plot (show phasing data by default)
                app.I2D_sec_PH.Value    = 1;
                k=app.I2D_ProbeDetSwitch.Value;
                SecondaryPlot(app.I2D_Data,app.I2D_SecondaryAxes,'ph',k);
            end
        end
       
    end
    
    methods (Access = private)
        
        function p = getFilePath(app)
            if isdeployed % If compiled and deployed
                [~, result] = system('path');
                p = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once')); % Get run path
            else
                [p,~,~] = fileparts(app.scriptPath);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Show app version label
            app.VersionTextLabel.Text = app.version;
            
            % Get the full path of this file
            path = getFilePath(app);
            
            % Read the default directories from the GUIoptions file
            if exist([path filesep 'GUIoptions.txt'],'file')==2
                try
                    app.SettingsPath        = [path filesep 'GUIoptions.txt'];
                    app.defaultTRIRdir      = readParam('defaultTRIRdir',[path filesep 'GUIoptions.txt']);
                    app.defaultTAdir        = readParam('defaultTAdir',[path filesep 'GUIoptions.txt']);
                    app.default2DIRdir      = readParam('default2DIRdir',[path filesep 'GUIoptions.txt']);
                    app.defaultFLUPSdir     = readParam('defaultFLUPSdir',[path filesep 'GUIoptions.txt']);
                    app.defaultSSdir        = readParam('defaultSSdir',[path filesep 'GUIoptions.txt']);
                    app.defaultPPcolormap   = readParam('defaultPPcolormap',[path filesep 'GUIoptions.txt']);
                    app.default2Dcolormap   = readParam('default2Dcolormap',[path filesep 'GUIoptions.txt']);
                    app.defaultPPtype     = readParam('defaultPPtype',[path filesep 'GUIoptions.txt']);
                    app.SelectKnownDataTypes= str2num(readParam('SelectKnownDataTypes',[path filesep 'GUIoptions.txt']));
                catch
                    % Do nothing, fall back to defaults.
                end
            end
            % Set the default values of some controls
            %% Pump-Probe
                app.PP_ColourScheme.Value   = app.defaultPPcolormap;
                app.PP_DataFormat.Value     = app.defaultPPtype;
            %% 2D-IR
                app.I2D_ColourScheme.Value  = app.default2Dcolormap;
            % Disable annoying warnings
            warning('off','MATLAB:Axes:NegativeLimitsInLogAxis');
            warning('off','MATLAB:Axes:NegativeDataInLogAxis');
            warning('off','MATLAB:legend:IgnoringExtraEntries');
            warning('off','MATLAB:table:ModifiedAndSavedVarnames');
            warning('off','MATLAB:appdesigner:appdesigner:SaveObjWarning');
        end

        % Menu selected function: ExitprogramMenu
        function ExitprogramMenuSelected(app, event)
            closereq
        end

        % Button pushed function: PP_ContourplotButton
        function PP_ContourplotButtonPushed(app, event)
            fh      = figure();
            newaxes = axes('Parent',fh);
            UpdatePlot_PP(app,newaxes)
            % Make the plot format constant
            fh.Units='pixels';
            fh.Position(2)=fh.Position(2).*0.7;
            fh.Position(3)=590; % Width in pixels
            fh.Position(4)=510; % Height in pixels
            fh.Color = [1 1 1];
            newaxes.FontSize = 12;
            newaxes.Units = 'pixels';
            newaxes.Position = [75 75 420 385];
            % Make the axes resizable
            newaxes.Units = 'normalized';
        end

        % Button pushed function: PP_SurfaceplotButton
        function PP_SurfaceplotButtonPushed(app, event)
            DET     = app.PP_ProbeDetSwitch.Value;
            fh      = figure();
            newaxes = axes('Parent',fh);
            switch app.PP_Data.rawcorr
                case 'RAW'
                    plot3D(app,app.PP_Data.rawsignal,newaxes,'Off',DET)
                case 'CORRECTED'
                    plot3D(app,app.PP_Data.corrdata,newaxes,'Off',DET)
            end
            set(newaxes,'yscale',app.PP_Data.linlog)
            % Make the plot format constant
            fh.Units='pixels';
            fh.Position(2)=fh.Position(2).*0.7;
            fh.Position(3)=590; % Width in pixels
            fh.Position(4)=510; % Height in pixels
            fh.Color = [1 1 1];
            newaxes.FontSize = 12;
            newaxes.Units = 'pixels';
            newaxes.Position = [75 75 420 385];
            % Make uniform, consistent format
            newaxes.LineWidth = 1;
            newaxes.TickLength = [0.015 0.035];
            
            % Make the axes resizable
            newaxes.Units = 'normalized';          
        end

        % Value changed function: I2D_InteractivemodeSwitch, 
        % ...and 1 other component
        function PP_InteractivemodeSwitchValueChanged(app, event)
            value = event.Value;
            switch value
                case 'On'
                    app.InteractivemodeLamp.Color = [0 1 0];
                case 'Off'
                    app.InteractivemodeLamp.Color = [1 0 0];
            end
        end

        % Value changing function: PP_PlotScaleSlider
        function PP_PlotScaleSliderValueChanging(app, event)
            app.PP_PlotScaleNumber.Value = event.Value;
        end

        % Value changed function: PP_ColourScheme, PP_ContourFormat, 
        % ...and 14 other components
        function PP_PlotScaleSliderValueChanged(app, event)
            app.PP_PlotScaleSlider.Value = app.PP_PlotScaleNumber.Value;
            UpdateInfo_PP(app,'Read')
            UpdatePlot_PP(app,app.PumpProbeAxes)
        end

        % Button pushed function: BrowserootdirButton
        function BrowserootdirButtonPushed(app, event)
            switch app.MainTabs.SelectedTab.Title
                case 'Pump-Probe'
                    switch app.PP_DataFormat.Value
                        case {'Helios TA','UniGE TA','UniGE nsTA','Pump-Probe [TRUVIS]'}
                            startDir = app.defaultTAdir;
                        case {'TRIR (Sheffield)','Pump-Probe [Lab 1 & Lab 4]','Pump-Probe [Lab 2]','Lab 5 (*.dat)'}
                            startDir = app.defaultTRIRdir;
                        case 'FLUPS (Sheffield)'
                            startDir = app.defaultFLUPSdir;
                        otherwise
                            startDir = app.defaultSSdir;
                    end
                case 'Interferometric 2D-IR'
                    startDir = app.default2DIRdir;
                otherwise
                    startDir = pwd;
            end
            app.rootdir = uigetdir(startDir,'Select a root folder...');
%             figure(app.DataAnalysisGUI_UIFigure);
            if app.rootdir == 0
                figure(app.DataAnalysisGUI_UIFigure);
                app.RootDirEditField.Value = 'No root folder selected :(';
                return
            else
                app.RootDirEditField.Value = app.rootdir;
                switch app.MainTabs.SelectedTab.Title
                    case 'Pump-Probe'
                        UpdateDataFolderList_PP(app);
                    case 'Interferometric 2D-IR'
                        UpdateDataFolderList_I2D(app);  
                end
            end
            figure(app.DataAnalysisGUI_UIFigure);
        end

        % Value changed function: RootDirEditField
        function RootDirEditFieldValueChanged(app, event)
            newRootDir = app.RootDirEditField.Value;
            if exist(newRootDir,'dir')
                app.rootdir = newRootDir;
                UpdateDataFolderList_PP(app);
                UpdateDataFolderList_I2D(app);
            else
                app.StatusMessage.Value = 'The specified root directory does not exist!';
                return
            end
        end

        % Callback function: PP_DataFolderList, PP_ReloadButton
        function PP_DataFolderListValueChanged(app, event)
            % Reload the folder list (just in case)
            UpdateDataFolderList_PP(app,'NoReselect');
            % Load the pump-probe data
            error = LoadPPData(app,1,'NONE',0,0);
            if error == 0
                % Initialise defaults and update the Edits for the first time ('Write'):
                UpdateInfo_PP(app,'Write');
                % Initialise the background subtraction part
                app.PP_Background_start.Value   = app.PP_Data.delays(1);
                nNeg        = find(app.PP_Data.delays>0);
                id_bkg_end  = max([nNeg(1)-5,2]);
                app.PP_Background_end.Value     = app.PP_Data.delays(id_bkg_end);
                app.PP_BackgroundSubtract_timeLabel.Text = app.PP_Data.timescale;
                % Override default w/current status of linlog tick
                switch app.PP_LogTime_tick.Value
                    case 0
                        app.PP_Data.linlog = 'lin';
                    case 1
                        app.PP_Data.linlog = 'log';
                end
                % Disable the corresponding panel if no single-scan data is available
                if isnan(app.PP_Data.Nscans)
                    app.PP_AnalyseindividualscansPanel.Visible = 'off';
                else
                    app.PP_AnalyseindividualscansPanel.Visible = 'on';
                end
                % Show sample information, noise statistics and other
                app.PP_SampleInfo.Value = ReadPPSampleInfo(app);
                % Plot the data in the main window (for preview)
                UpdatePlot_PP(app,app.PumpProbeAxes);
            end
        end

        % Callback function: GoonefolderupMenu, UpDirectory
        function UpDirectoryButtonPushed(app, event)
            [app.rootdir,~]             = fileparts(app.rootdir);
            app.RootDirEditField.Value  = app.rootdir;
            UpdateDataFolderList_PP(app);
            UpdateDataFolderList_I2D(app);
        end

        % Menu selected function: EntercurrentfolderMenu
        function EntercurrentfolderMenuSelected(app, event)
            switch app.MainTabs.SelectedTab.Title
                case 'Pump-Probe'
                    newdir = app.PP_DataFolderList.Value;
                    if isfolder([app.rootdir filesep newdir])
                        app.rootdir                 = [app.rootdir filesep newdir];
                        app.RootDirEditField.Value  = app.rootdir;
                        UpdateDataFolderList_PP(app);
                    else
                        return
                    end
                case 'Interferometric 2D-IR'
                    newdir = app.I2D_DataFolderList.Value;
                    if isfolder([app.rootdir filesep newdir])
                        app.rootdir                 = [app.rootdir filesep newdir];
                        app.RootDirEditField.Value  = app.rootdir;
                        UpdateDataFolderList_I2D(app);
                    else
                        return
                    end
            end           
        end

        % Button pushed function: PP_PlotBackgroundButton
        function PP_PlotBackgroundButtonPushed(app, event)
            DET = app.PP_ProbeDetSwitch.Value;
            
            % Create a new figure with consistent format
            fh = figure();
            fh.Position(3)  = 800;
            fh.Position(4)  = 425;
            fh.Color        = [1 1 1];
            % Define the axes
            ax              = axes('Parent',fh);
            ax.Units        = 'pixels';
            ax.Position     = [80 65 680 320];
            % Do the plot
            plot(ax,app.PP_Data.cmprobe{DET},app.PP_Data.bkg{DET},'LineWidth',1.5,'Color','r')
            % Formatting
            axis(ax,'tight');
            ax.FontSize     = 12;
            ProbeLabel      = [ app.PP_Data.probeunits ' (' app.PP_Data.Xunits ')'];
            xlabel(ax, ProbeLabel,'FontSize',13,'FontWeight','bold');
            ylabel(ax,'Background signal (m\DeltaOD)','FontSize',13,'FontWeight','bold');
            title(ax,['Background of ',app.PP_Data.datafilename],'Interpreter','none','FontSize',12);
            yline(ax,0,'Color',0.5*[1 1 1],'HandleVisibility','off');
            fh.Units        = 'normalized';
            ax.Units        = 'normalized';
        end

        % Callback function: I2D_Dataformat, PP_DataFormat, Refresh
        function RefreshButtonPushed(app, event)
            UpdateDataFolderList_PP(app);
            UpdateDataFolderList_I2D(app);
        end

        % Button pushed function: PP_Anisotropy_button
        function PP_Anisotropy_buttonButtonPushed(app, event)
            % Ask the user what to plot
            anisotropy_options = {'UV anisotropy','IR anisotropy','UV magic angle','IR magic angle'};
            [anisotropy_typeindx,doplot] = listdlg('ListString',anisotropy_options,'OKstring','Plot','SelectionMode','single','ListSize',[150,120],'PromptString','Select slice type to plot:');
            
            if doplot == 0
                return
            end

            AnisotropyType  = anisotropy_options{anisotropy_typeindx};
            % Reload the folder list (just in case)
            UpdateDataFolderList_PP(app,'NoReselect');
            
            % Reload the pump-probe data and process according to the selected anisotropy type
            error = LoadPPData(app,[],AnisotropyType,0,1);
            if error == 0
                % Initialise defaults and update the Edits for the first time ('Write'):
                UpdateInfo_PP(app,'Write');
                % Initialise the background subtraction part
                app.PP_Background_start.Value   = app.PP_Data.delays(1);
                app.PP_Background_end.Value     = app.PP_Data.delays(1);
                app.PP_BackgroundSubtract_timeLabel.Text = app.PP_Data.timescale;
                % Override default w/current status of linlog tick
                switch app.PP_LogTime_tick.Value
                    case 0
                        app.PP_Data.linlog = 'lin';
                    case 1
                        app.PP_Data.linlog = 'log';
                end
                % Disable the corresponding panel if no single-scan data is available
                if isnan(app.PP_Data.Nscans)
                    app.PP_AnalyseindividualscansPanel.Visible = 'off';
                else
                    app.PP_AnalyseindividualscansPanel.Visible = 'on';
                end
                % Plot the data in the main window (for preview)
                UpdatePlot_PP(app,app.PumpProbeAxes);
            end
        end

        % Button pushed function: PP_PlotKineticsButton
        function PP_PlotKineticsButtonPushed(app, event)
            DET                     = app.PP_ProbeDetSwitch.Value; 
            interactive             = app.PP_InteractivemodeSwitch.Value;
            app.PP_Data.SelTraces   = [];
            switch interactive
                case 'On'
                    % Patch because ginput doesn't work on UIFigures
                    fh = figure;
                    fh.Position = app.PumpProbeAxes.Position;
                    fh.Position(1:2) = fh.Position(1:2) + app.DataAnalysisGUI_UIFigure.Position(1:2);
                    newaxes = axes('Parent',fh);
                    UpdatePlot_PP(app,newaxes);
                    
                    % Select traces interactively, to finish hit RETURN
                    try 
                        [app.PP_Data,aborted] = SelectTraces(app.PP_Data);
                        if aborted == 1
                            return
                        end
                    catch
                        % Do nothing
                    end
                    
                    % Delete the extra figure
                    if ishandle(fh)
                        close(fh)  
                    end
                    
                    if isempty(app.PP_Data.SelTraces)
                        return
                    end
                case 'Off'
                    app.PP_Data.SelTraces = inputdlg('Enter space-separated wavenumbers to plot:',...
                         'Input desired wavenumbers', [1 50]);
                    if isempty(app.PP_Data.SelTraces)
                        return
                    end
                    app.PP_Data.SelTraces = str2num(app.PP_Data.SelTraces{:})';
                    app.PP_Data.SelTraces = sort(app.PP_Data.SelTraces,1);
            end
            % Check if RAW or CORR and give DATA
            switch app.PP_Data.rawcorr
                case 'RAW'
                    data = app.PP_Data.rawsignal{DET};
                case 'CORRECTED'
                    data = app.PP_Data.corrdata{DET};
            end
            % ONCE EVERYTHING IS THERE, THEN DO STUFF
            % Get the values to find in the WAVELENGTH vector
            value = app.PP_Data.SelTraces(:,1); % column 1= Wavelength
            % k = index of the closest match for each selected trace
            k = unique(findClosestId2Val(app.PP_Data.cmprobe{DET},transpose(value)));
            L = length(k);
            i = 0;
            while i < L
                % Get the actual vectors from the Z matrix (one by one)
                index(i+1) = k(1,i+1);
                switch app.PP_NormaliseCheckBox.Value
                    case 0
                        ydata(:,i+1) = data(:,index(i+1));
                        label = '\DeltaAbs (mOD)';
                        caption(i+1,1) = string([num2str(app.PP_Data.cmprobe{DET}(index(i+1))) ' ' app.PP_Data.Xunits]);
                    case 1
                        maxval = max(data(:,index(i+1)));
                        minval = min(data(:,index(i+1)));
                        if abs(maxval) >= abs(minval)
                            ydata(:,i+1) = data(:,index(i+1))./maxval;
                            caption(i+1,1) = string([num2str(app.PP_Data.cmprobe{DET}(index(i+1))) ' ' app.PP_Data.Xunits]);
                        else
                            ydata(:,i+1) = data(:,index(i+1))./minval;
                            caption(i+1,1) = string([num2str(app.PP_Data.cmprobe{DET}(index(i+1))) ' ' app.PP_Data.Xunits '\times -1']);
                        end
                        label = 'Normalised \DeltaAbs';
                end
                i=i+1;
            end
            if app.PP_OverlayPlots_check.Value == 1
                h =  findobj('type','figure');
                n = length(h);
                if n > 0
                    app.figNum = n;
                else
                    app.figNum = 1;    
                end
                fh      = figure(app.figNum);
                
                if isempty(fh.Children)
                    axes2   = axes('Parent',fh);
                else
                    axes2   = fh.Children(end);
                end
                
                hold(axes2,'on')
                axes(axes2);
            else
                % Create a new figure
                fh = figure;
                % % Define the axes
                axes2 = axes('Parent',fh);
                axes(axes2);
                
                % % Plot in two axes 
                % figure;
                % ax1 = axes('OuterPosition',[0 0 0.3 1],'Units','Normalized');
                % ax2 = axes('OuterPosition',[0.1 0 0.8 1],'Units','Normalized','YTickLabel',[]);
            end
           
            fh.Position(3)  = 920;
            fh.Position(4)  = 425;
            fh.Color        = [1 1 1];
            
            % Plot the data
            cmap=colormap(axes2,(othercolor('Mrainbow',L)));
            for n=1:L
               plot(axes2,app.PP_Data.delays,ydata(:,n),'LineWidth',2,'Marker','o','MarkerSize',2,'color',cmap(n,:),'DisplayName',[num2str(app.PP_Data.cmprobe{DET}(index(n)),'%.1f') ' ' app.PP_Data.Xunits]);
               hold(axes2,'on');
            end
            
            % Nice formatting
            axes2.FontSize = 16;
            title(axes2,{app.PP_Data.datafilename;[app.PP_Data.rawcorr,' DATA - KINETICS';'']},'Interpreter','none','FontSize',12);
            xlabel(axes2,['Delays',' (',app.PP_Data.timescale,')'],'FontSize',13,'FontWeight','bold');
            ylabel(axes2,label,'FontSize',13,'FontWeight','bold');
            axis(axes2,'tight');
%             if L == 1
%                 legend(axes2,[string(strcat(num2str(round(app.PP_Data.cmprobe(index),0)),[' ' app.PP_Data.Xunits])),''],'FontSize',12)
%             else
%                 legend(axes2,caption,'FontSize',12)
%             end
            legend(axes2,'show')
            legend(axes2,'boxoff')
            legend(axes2,'Location','bestoutside')
            axes2.XScale    = app.PP_Data.linlog;
            yline(axes2,0,'Color',[0.5 0.5 0.5],'HandleVisibility','off');
            xline(axes2,0,'Color',[0.5 0.5 0.5],'HandleVisibility','off');
            axes2.Units     = 'pixels';
            axes2.Position  = [75 60 675 320];
            axes2.Units     = 'normalized';
            axes2.Layer     = 'top';
            axes2.Box       = 'on';
            
            if app.PP_OverlayPlots_check.Value == 1
               Nplots = length(fh.Children(end).Children);
               cmap = othercolor('Mrainbow',Nplots);
               for j=1:Nplots
                   fh.Children(end).Children(j).Color = cmap(j,:);
               end
               if Nplots > 1
                   title(axes2,'Overlay plot');
               end
            end
            
            % Decide whether to save the plotted traces or not
            if strcmp(app.PP_SavetracesSwitch.Value,'On')
                wavenumbers = transpose(app.PP_Data.cmprobe{DET}(k));
                filename    = [app.PP_Data.rootdir filesep app.PP_Data.datafilename '_Kin-traces_DET' num2str(DET) '.dat'];
                writematrix([[0;app.PP_Data.delays] [wavenumbers;ydata]],filename,'Delimiter','\t')
                helpdlg(["Saved traces to:";filename],'Traces have been saved!')
            end
            
            where.TickLength=[0.015 0.025];
        end

        % Button pushed function: PP_PlotTrSpectraButton
        function PP_PlotTrSpectraButtonPushed(app, event)
            interactive             = app.PP_InteractivemodeSwitch.Value;
            app.PP_Data.SelTraces   = [];
            DET                     = app.PP_ProbeDetSwitch.Value;
            
            FontSize = 18; % 18 for papers (single column figure), 14 for presentations (can vary)
            
            switch interactive
                case 'On'
                    % Patch because ginput doesn't work on UIFigures
                    fh = figure;
                    fh.Position = app.PumpProbeAxes.Position;
                    fh.Position(1:2) = fh.Position(1:2) + app.DataAnalysisGUI_UIFigure.Position(1:2);
                    newaxes = axes('Parent',fh);
                    UpdatePlot_PP(app,newaxes);
                    
                    % Select traces interactively, to finish hit RETURN
                    try 
                        [app.PP_Data,aborted] = SelectTraces(app.PP_Data);
                        if aborted == 1
                            return
                        end
                    catch
                        % Do nothing
                    end
                    
                    % Delete the extra figure
                    if ishandle(fh)
                        close(fh)  
                    end
                    
                    if isempty(app.PP_Data.SelTraces)
                        return
                    end
                    
                    % Get the values to find in the TIME vector
                    value = app.PP_Data.SelTraces(:,2); % column 2 = Time
                case 'Off'
                    timescale = app.PP_Data.timescale;
                    app.PP_Data.SelTraces = inputdlg('Enter space-separated times to plot the transient spectra:',...
                         ['Input desired times (in ',timescale,')'], [1 50],{'0.2 0.5 1 2 5 10 20 50 100 200 500 1000 2000 5000'});
                    if isempty(app.PP_Data.SelTraces)
                        return
                    end
                    app.PP_Data.SelTraces = str2num(app.PP_Data.SelTraces{:})';
                    app.PP_Data.SelTraces = sort(app.PP_Data.SelTraces,1);
                     % Get the values to find in the TIME vector
                    value = app.PP_Data.SelTraces(:,1); % column 1 = Time (the only one)
            end
            % Check if RAW or CORR and give DATA
            switch app.PP_Data.rawcorr
                case 'RAW'
                    data = app.PP_Data.rawsignal{DET}';
                case 'CORRECTED'
                    data = app.PP_Data.corrdata{DET}';
            end
            % ONCE EVERYTHING IS THERE, THEN DO STUFF
            % k = index of the closest match for each selected trace
            k = findClosestId2Val(app.PP_Data.delays,transpose(value));
            k = unique(k,'stable');
            L = length(k);
            ydata   = zeros(size(data,1),L);
            caption = strings(L,1);
            for n=1:L
                % Check the delays and convert the labels and numbers to appropriate units
                if app.PP_Data.delays(k(n)) >= 1000 && ~strcmp(app.PP_Data.timescale,'s')
                   newdelays = app.PP_Data.delays(k(n))/1000;
                   switch app.PP_Data.timescale
                       case 'fs'
                           newtimescale = 'ps';
                       case 'ps'
                           newtimescale = 'ns';
                       case 'ns'
                           newtimescale = ['\mu' 's'];
                   end
                elseif app.PP_Data.delays(k(n)) < 1 && ~strcmp(app.PP_Data.timescale,'s')
                   newdelays = app.PP_Data.delays(k(n)).*1000;
                   switch app.PP_Data.timescale
                       case 'ps'
                           newtimescale = 'fs';
                       case 'ns'
                           newtimescale = 'ps';
                   end
                else
                   newdelays    = app.PP_Data.delays(k(n));
                   newtimescale = app.PP_Data.timescale;
                end
                
                % Get the actual vectors from the Z matrix (one by one)
                % together with their legend captions and corresponding ylabel
                switch app.PP_NormaliseCheckBox.Value
                    case 0
                        ydata(:,n) = data(:,k(n));
                        label = '\DeltaAbs (mOD)';
                    case 1
                        ydata(:,n) = data(:,k(n))./max(max(abs(data(:,k(n)))));
                        label = 'Normalised \DeltaAbs';
                end
                caption(n,1) = string([num2str(round(newdelays,2,'significant')) ' ' newtimescale]);
            end
               
            switch app.PP_IncludeSS_CheckBox.Value
                case 1
                    % Create a new figure with consistent format
                    try
                        scale   = str2double(readParam('SSpecPlotSizePercent',app.SettingsPath))/100;
                        filledSS= logical(str2double(readParam('SSpecPlotFillArea',app.SettingsPath)));
                        faceCol = str2num(readParam('SSpecPlotFillAreaCol',app.SettingsPath));
                    catch
                        scale   = 60/100;
                        filledSS= false;
                        faceCol = 0.9*[1 1 1];
                    end
                    fh = figure();
                    fh.Units        = 'pixels';
                    fh.Position(2)  = fh.Position(2)-450*scale;
                    fh.Position(3)  = 890;
                    fh.Position(4)  = 425+325*scale;
                    fh.Color        = [1 1 1];
                    % Create the SS axis
                    ax1             = axes('Parent',fh);
                    % Create the transient spectra axis
                    ax2             = axes('Parent',fh);
                    linkaxes([ax1,ax2],'x');
                    where = ax2;
                case 0
                    % Create a new figure with consistent format
                    fh = figure();
                    fh.Name = [app.PP_Data.rawcorr ' DATA - TRANSIENT SPECTRA of "' app.PP_Data.datafilename '"'];
                    fh.Position(3)  = 890;
                    fh.Position(4)  = 425;
                    fh.Color        = [1 1 1];
                    % Define the axes
                    ax2 = axes('Parent',fh);
                    axes(ax2);
                    ax2.Units     = 'pixels';
                    where = ax2;
            end

            cm=colormap(where,othercolor('Mrainbow',L));

            % Automatically detect discontinuities in the probe axis (e.g. masked pump scatter)
            % and plot them accordingly
            probe   = app.PP_Data.cmprobe{DET};
            dProbe  = diff(probe) - mean(diff(probe));
            jump_ID = dProbe >= 5*mean(diff(probe));
            ydata(jump_ID,:) = NaN;

            % Plot the data
            hold(where,'on')
            for n=1:L
               plot(where,probe,ydata(:,n),'LineWidth',1.5,'Marker','none','color',cm(n,:),'DisplayName',caption(n));
            end
            hold(where,'off')
            % Nice formatting
            xlabel(where,[ app.PP_Data.probeunits ' (' app.PP_Data.Xunits ')'],'FontSize',13,'FontWeight','bold')
            ylabel(where,label,'FontSize',FontSize,'FontWeight','bold')
            axis(where,'tight')

            if L > 15
                cb = colorbar(where);
                colormap(where,othercolor('Mrainbow',L));
                caxis(where,[min(app.PP_Data.delays(k)) max(app.PP_Data.delays(k))]);
                ylabel(cb,['Delay (' app.PP_Data.timescale ')'],'FontWeight','bold');
                cb.LineWidth       = 0.1;
                cb.TickLength      = 0.025;
                cb.TickDirection   = 'both';
            else
                legend(where,'show');
                legend('boxoff')
                legend('Location','bestoutside')
                legend(ax2,{},'FontSize',FontSize)
            end

            where.Units       = 'pixels';
            where.Position    = [80 75 675 320];
            where.Units       = 'normalized';
            where.FontSize=FontSize;

            % Refline
            hline = yline(where,0,'HandleVisibility','off'); hline.Color = [0.5 0.5 0.5];
            ylim(where,'padded');
            
            % Nice formatting for the combined plot
            if app.PP_IncludeSS_CheckBox.Value == 1
                xl          = xlim(where);
                xl_idx      = findClosestId2Val(app.PP_Data.SSx,xl);
                xl_idx      = sort(xl_idx);
                SSy         = app.PP_Data.SSy - min(app.PP_Data.SSy(xl_idx(1):xl_idx(2)));

                if app.PP_NormaliseCheckBox.Value == 0 && max(abs(SSy(xl_idx(1):xl_idx(2)))) ~= 1
                        Yname   = 'Abs. (mOD)';
                elseif app.PP_NormaliseCheckBox.Value == 1
                        SSy     = SSy./max(abs(SSy(xl_idx(1):xl_idx(2))));
                        Yname   = 'Norm. Abs.';
                elseif max(abs(SSy(xl_idx(1):xl_idx(2)))) == 1
                        Yname   = 'Norm. Abs.';
                end

                minmaxIRy   = [min(SSy(xl_idx(1):xl_idx(2))) max(SSy(xl_idx(1):xl_idx(2)))];
                
                % Convert to OD if more than 500 mOD
                if max(abs(minmaxIRy)) >= 500
                    SSy     = SSy./1000;
                    Yname   = 'Abs. (OD)';
                end

                switch app.PP_Data.probeunits
                    case 'Wavelength'
                        SSpecName = 'UV-Vis';
                    case 'Wavenumbers'
                        SSpecName = 'FT-IR';
                    otherwise
                        SSpecName = 'SS';
                end
                
                if filledSS == 0
                    plot(ax1,app.PP_Data.SSx,SSy,'k','LineWidth',1.5,'DisplayName',SSpecName);
                else
                    area(ax1,app.PP_Data.SSx,SSy,'LineWidth',1.5,'EdgeColor','k','FaceColor',faceCol,'DisplayName',SSpecName);
                end

                xlim(ax1,xl);
                ylim(ax1,minmaxIRy);
                set(ax1, 'XTickLabel', []); % To remove only the labels, otherwise use XTick
            %   set(ax1, 'XTick', []);
                ax1.FontSize = FontSize;
                ax2.FontSize = FontSize;
                ylabel(ax1,Yname,'FontWeight','bold','FontSize',FontSize)
                legend(ax1,'show');
                legend(ax1,'boxoff')
                legend(ax1,'Location','bestoutside')
                hline2 = yline(ax1,0,'HandleVisibility','off'); hline2.Color = [0.5 0.5 0.5];
                ylim(ax1,'padded');
                ax1.Units       = 'pixels';
                ax1.Position    = [80 400 675 320*scale];
                ax1.Units       = 'normalized';
                ax1.TickLength  = [0.015 0.025];
            end
            % Decide whether to save the plotted traces or not
            if strcmp(app.PP_SavetracesSwitch.Value,'On')
                filename    = [app.PP_Data.rootdir filesep app.PP_Data.datafilename '_Sp-traces_DET' num2str(DET) '.dat'];
                probe = app.PP_Data.cmprobe{DET};
                writematrix([[0;app.PP_Data.delays(k)],[probe(:)';ydata']]',filename,'Delimiter','\t')
                helpdlg(["Saved traces to:";filename],'Traces have been saved!')
            end
          
            where.TickLength=[0.015 0.025];
            where.Box = 'on';
        end

        % Callback function: LoadSSdataMenu, PP_LoadSSdataButton
        function PP_LoadFTIRdataButtonPushed(app, event)
            app.PP_Data = LoadSteadySpec(app.PP_Data,app.SettingsPath);
            if app.PP_Data.SSloaded == 1
                app.PP_ClearSSdataButton.Enable     = 'on';
                app.PP_ShowSSdataButton.Enable      = 'on';
                app.PP_IncludeSS_CheckBox.Enable    = 'on';
                app.I2D_IncludeFTIRCheckBox.Enable  = 'on';        
                app.ShowSSdataMenu.Enable           = 'on';
                app.ClearSSdataMenu.Enable          = 'on';
                app.SSSploadedLamp.Color            = [0 1 0];
            else
                app.PP_ClearSSdataButton.Enable     = 'off';
                app.PP_ShowSSdataButton.Enable      = 'off';
                app.PP_IncludeSS_CheckBox.Enable    = 'off';
                app.I2D_IncludeFTIRCheckBox.Enable  = 'off';
                app.ShowSSdataMenu.Enable           = 'off';
                app.ClearSSdataMenu.Enable          = 'off';
                app.PP_IncludeSS_CheckBox.Value     = 0;
                app.SSSploadedLamp.Color            = [0.8 0.8 0.8];
            end
        end

        % Callback function: PP_ShowSSdataButton, ShowSSdataMenu
        function PP_ShowFTIRdataButtonPushed(app, event)
            % New figure and axes
            fh              = figure();
            axes2           = axes('Parent',fh);
            % Plot the data
            plot(app.PP_Data.SSx,app.PP_Data.SSy,'LineWidth',1.5,'Marker','none','color','b');
            % Nice formatting
            axes2.FontSize  = 12;
            title(axes2,'ABSORPTION SPECTRUM','FontSize',12)

            % An attempt of a smart way to figure out what the units of the probe axis are, based on "common sense"
            switch app.MainTabs.SelectedTab.Title
                case 'Pump-Probe'
                    if isfield(app.PP_Data,'probeunits') == 0
                        if max(app.PP_Data.SSx(:))<=2000
                            xname = 'Wavelength';
                        else
                            xname = 'Wavenumbers';
                        end
                    else
                        xname = app.PP_Data.probeunits;
                    end
                     
                    switch xname
                        case 'Wavelength'
                            xunits = 'nm';
                        case 'Wavenumbers'
                            xunits = 'cm^{-1}';
                        otherwise
                            xunits = '??';
                    end
                otherwise
                    xname  = 'Wavenumbers';
                    xunits = 'cm^{-1}';
            end
            
            xlabel(axes2,[xname '(' xunits ')'],'FontSize',13,'FontWeight','bold')
            ylabel(axes2,'Abs (mOD)','FontSize',13,'FontWeight','bold')
            axis(axes2,'tight')
            hline = yline(axes2,0,'HandleVisibility','off'); hline.Color = [0.5 0.5 0.5];
        end

        % Callback function: ClearSSdataMenu, PP_ClearSSdataButton
        function PP_ClearFTIRdataButtonPushed(app, event)
            app.PP_Data.SSloaded                = 0;
            app.PP_Data.SSx                     = [];
            app.PP_Data.SSy                     = [];
            app.PP_ClearSSdataButton.Enable     = 'off';
            app.PP_ShowSSdataButton.Enable      = 'off';
            app.ShowSSdataMenu.Enable           = 'off';
            app.ClearSSdataMenu.Enable          = 'off';
            app.PP_IncludeSS_CheckBox.Enable    = 'off';
            app.I2D_IncludeFTIRCheckBox.Enable  = 'off';
            
            app.PP_IncludeSS_CheckBox.Value     = 0;
            app.SSSploadedLamp.Color            = [0.8 0.8 0.8];
        end

        % Button pushed function: PP_ShowSVD_button
        function PP_ShowSVD_buttonButtonPushed(app, event)
            DET = app.PP_ProbeDetSwitch.Value;
            % Check if RAW or CORR and give DATA
            switch app.PP_Data.rawcorr
                case 'RAW'
                    data = app.PP_Data.rawsignal{DET};
                case 'CORRECTED'
                    data = app.PP_Data.corrdata{DET};
            end
            try
                [T,SV,WL] = svd(data);
            catch err
                errordlg(err.message,'Error doing SVD!')
                return
            end
            % Get the Singular values to plot
            n           = 1:1:app.PP_SVDend.Value;
            % Plot the Singular Values
            fh = figure();
            sv_axes     = subplot(2,2,[3 4],'parent',fh);
            cmap        = colormap(sv_axes,lines(length(n)));
            SVAL        = diag(SV);
            sum_SVAL    = sum(SVAL);
            SVAL        = SVAL(n,:);
            SV_diag     = diag(SVAL,0);
            scatter(sv_axes,n,SVAL,75,cmap,'filled','LineWidth',0.75);
            sv_axes.YScale = 'log';
            axis(sv_axes,'tight'); yl = ylim(sv_axes); ylim(sv_axes,[yl(1) 1.1.*yl(2)]); sv_axes.FontSize = 10; sv_axes.XTick = n;
            %title(['Singular values of ',handles.datafilename],'Interpreter','none','FontWeight','bold');
            xlabel(sv_axes,'SVD index','FontWeight','bold');
            ylabel(sv_axes,'SVD magnitude','FontWeight','bold');
            % Plot the RH vectors (Wavelength)
            ax=subplot(2,2,1);
            plot(ax,app.PP_Data.cmprobe{DET},WL(:,n)); axis(ax,'tight');
            hold(ax,'on');
            plot(ax,[app.PP_Data.cmprobe{DET}(1) app.PP_Data.cmprobe{DET}(end)],[0,0],'-','LineWidth',0.75,'Color',[0.5 0.5 0.5])
            hold(ax,'off');
            xlabel(ax,[ app.PP_Data.probeunits ' (' app.PP_Data.Xunits ')'],'FontWeight','bold');
            ylabel(ax,'\DeltaAbs (mOD)','FontWeight','bold');
            % Plot the LH vectors (Time)
            ax=subplot(2,2,2);
            plot(ax,app.PP_Data.delays,T(:,n)*SV_diag); axis tight;
            xlabel(['Delays (',app.PP_Data.timescale,')'],'FontWeight','bold');
            ax.XScale   = app.PP_Data.linlog;
            xl = xlim(ax);
            hold(ax,'on');
            plot(ax,[xl(1) xl(2)],[0,0],'-','LineWidth',0.75,'Color',[0.5 0.5 0.5])
            hold(ax,'off');
            
            %Add the cumulative information content as function of increasing SVD
            %components
            axes(sv_axes);
            yyaxis(sv_axes,'right')
            sv_axes.YColor = 'r';
            cumulSV=zeros(length(n),1);
            for q=1:length(n)
                cumulSV(q,1)=sum(SVAL(1:q))/sum_SVAL*100;
            end
            plot(transpose(n),cumulSV,'-or','LineWidth',0.75);
            text(transpose(n),cumulSV,num2str(cumulSV,3),'HorizontalAlignment','left','VerticalAlignment','top')
            ylabel('Cumulative content (%)','FontWeight','bold');
            ylim([1 100])
        end

        % Callback function: I2D_DataFolderList, I2D_ReloadButton
        function I2D_DataFolderListValueChanged(app, event)
            % Reload the folder list (just in case)
            UpdateDataFolderList_I2D(app,'NoReselect');
            
            % Load and process the 2D-IR data
            error = LoadProcessI2DData(app);
            
            % Plot it on the main axes
            if error == 0
                % Initialise defaults and update the Edits for the first time ('Init'):
                UpdateInfo_I2D(app,'Init');
                % Read other edits into the plotOptions structure ('Read'):
                UpdateInfo_I2D(app,'Read');
                % Show sample information, noise statistics and other
                app.I2D_SampleInfo.Value = ReadI2DSampleInfo(app);
                % Plot the data in the main window (for preview)
                UpdatePlot_I2D(app,app.TwoDAxes,'update');
            end
        end

        % Value changing function: I2D_PlotScaleSlider
        function I2D_PlotScaleSliderValueChanging(app, event)
            app.I2D_PlotScaleNumber.Value = event.Value;
        end

        % Callback function: I2D_ColourScheme, I2D_ContourLineStyle, 
        % ...and 18 other components
        function I2D_UpdatePlotOther(app, event)
            UpdateInfo_I2D(app,'Read');
            UpdatePlot_I2D(app,app.TwoDAxes,'NoChange')
            UpdateInfo_I2D(app,'Update');
            I2D_ExtraPlotOptionSelectionChanged(app,event);
        end

        % Callback function: I2D_ExtraPlotOption, I2D_TDPixel, 
        % ...and 1 other component
        function I2D_ExtraPlotOptionSelectionChanged(app, event)
            selectedButton = app.I2D_ExtraPlotOption.SelectedObject;
            switch selectedButton.Text
                case 'Time-domain data'
                    what    = 'td';
                    pixel   = app.I2D_TDPixel.Value;
                    pixLine = app.I2D_sec_ShowPixelLine.Value;
                case 'Phasing'
                    what    = 'ph';
                    pixel   = 0;
                    pixLine = 0;
                case 'Probe calibration'
                    what    = 'pc';
                    pixel   = 0;
                    pixLine = 0;
            end
            k=app.I2D_ProbeDetSwitch.Value;
            SecondaryPlot(app.I2D_Data,app.I2D_SecondaryAxes,what,k,pixel,pixLine,app.TwoDAxes);
        end

        % Value changed function: I2D_t2delays
        function I2D_t2delaysValueChanged(app, event)
            UpdateInfo_I2D(app,'Read');
            UpdatePlot_I2D(app,app.TwoDAxes,'NoChange')
            UpdateInfo_I2D(app,'Update');
            app.I2D_SampleInfo.Value = ReadI2DSampleInfo(app,'NoUpdateSampleInfo');
            I2D_ExtraPlotOptionSelectionChanged(app,event);
        end

        % Button pushed function: I2D_ApplychangesButton
        function I2D_ApplychangesButtonPushed(app, event)
            try
               %%% Process the data
               app.DataStatusLamp.Color     = [1 1 0];
               bkgIdx = app.I2D_ScatteringSubDelay.Value;
               switch app.I2D_Dataformat.Value
                   case {'2D-IR [Lab 3]','2D-IR (RAL/CLF proc.)'}
                    % Processing is done before...
                   case '2D-IR [Lab 5]'
                    app.I2D_Data = process2DIR_shaper(app,app.I2D_Data,0,bkgIdx);
                   case '2D-IR (Sheffield)'
                       app.I2D_Data = process2DIR_UoS(app,app.I2D_Data,0,bkgIdx);
                   otherwise
                    app.I2D_Data = process2DIR(app,app.I2D_Data,1,bkgIdx);
               end
               
               % Delete progress bar
               delete(app.I2D_Data.WBfigure);
               exitcode=0;             
            catch err
               app.StatusMessage.Value      = string(err.message);
               app.DataStatusLamp.Color     = [1 0 0];
               cla(app.TwoDAxes);
               app.TwoDAxes.Visible         = 'off';
               exitcode=1;
            end
            
            if exitcode == 0
                UpdateInfo_I2D(app,'Init');
                UpdateInfo_I2D(app,'Read');
                UpdatePlot_I2D(app,app.TwoDAxes,'NoChange')
                UpdateInfo_I2D(app,'Update');
                app.I2D_SampleInfo.Value = ReadI2DSampleInfo(app);
                I2D_ExtraPlotOptionSelectionChanged(app,event);
                % Clear error string
                app.StatusMessage.Value      = 'Data successfully loaded! - New processing settings applied.';
                app.DataStatusLamp.Color     = [0 1 0];
            end
        end

        % Button pushed function: I2D_ContourplotButton
        function I2D_ContourplotButtonPushed(app, event)
            % Copy current plot limits    
                xl= xlim(app.TwoDAxes);
                yl= ylim(app.TwoDAxes);
            % Create figure    
                switch app.I2D_IncludeFTIRCheckBox.Value
                    case 1
                        path = getFilePath(app);
                        % Create a new figure with consistent format
                        try
                            scale   = str2double(readParam('SSpecPlotSizePercent2D',[path filesep 'GUIoptions.txt']))/100;
                        catch
                            scale   = 50/100;
                        end
                        fh              = figure();
                        % Create the 2D-IR spectrum axis
                        ax2D            = axes('Parent',fh);
                        ax2D.Units      = 'pixels';
                        pos1            = ax2D.Position; % Store its current position
                        
                        fh.Position(4)  = fh.Position(4)*(1+scale);
                        ax2D.Position   = pos1;
                        % Create the FTIR axis
                        axFTIR          = axes('Parent',fh);
                        axFTIR.Units    = 'pixels';
                        
                        axFTIR.PositionConstraint = 'innerposition';
                        ax2D.PositionConstraint = 'innerposition';

                        axFTIR.Position = [pos1(1:3) pos1(4)*scale];
                        axFTIR.Position(2) = axFTIR.Position(2)+pos1(4)+15;

                        axFTIR.Position(1:2) = axFTIR.Position(1:2) + [10 20];
                        ax2D.Position(1:2)   = ax2D.Position(1:2) + [10 20];

                        fh.Units        = 'pixels';
                        movegui(fh,'onscreen');
                        
                        linkaxes([axFTIR,ax2D],'x');
                    case 0
                        % Create a new figure with consistent format
                        fh          = figure;
                        ax2D          = axes(fh);
                        ax2D.Visible  ='Off';
                end
            % Plot the 2D data
                UpdateInfo_I2D(app,'Read');
                UpdatePlot_I2D(app,ax2D,'NoChange');
            % Make uniform, consistent format
                fh.Color          = [1 1 1];    
                ax2D.FontSize     = 14;
                ax2D.LineWidth    = 1;
                ax2D.TickLength   = [0.02 0.035];
                ax2D.Visible      = 'On';
            % Restore axes limits
                xlim(ax2D,xl)
                ylim(ax2D,yl)
            % Add FTIR spectra on top (if requested)
            if app.I2D_IncludeFTIRCheckBox.Value == 1
                xl          = xlim(ax2D);
                xl_idx      = findClosestId2Val(app.PP_Data.SSx,xl);
                xl_idx      = sort(xl_idx);
                IRy         = app.PP_Data.SSy - min(app.PP_Data.SSy(xl_idx(1):xl_idx(2)));
                
                if max(abs(IRy)) >= 500
                    IRy = IRy./1000;
                    yname = 'Abs. (OD)';
                else
                    yname = 'Abs. (mOD)';
                end

                minmaxIRy   = [min(IRy(xl_idx(1):xl_idx(2))) max(IRy(xl_idx(1):xl_idx(2)))];
                plot(axFTIR,app.PP_Data.SSx,IRy,'LineWidth',1.5,'Marker','none','color','black','DisplayName','FTIR');
                yline(axFTIR,0);

                xlim(axFTIR,xl);
                ylim(axFTIR,minmaxIRy);
                ylim(axFTIR,'padded');
                set(axFTIR, 'XTickLabel', []); % To remove only the labels, otherwise use XTick
                
                axFTIR.FontSize     = ax2D.FontSize;
                axFTIR.LineWidth    = ax2D.LineWidth;
                axFTIR.TickLength   = ax2D.TickLength;
                ylabel(axFTIR,yname,'FontWeight','bold','FontSize',12)
% %                 cb2= colorbar(axFTIR);
% %                 cb2.Visible='off';    
                set(colorbar,'visible','off');
                drawnow;

                % Fix aspect ratio of the plots
                ax2D.DataAspectRatioMode    = 'auto';
                ax2D.PlotBoxAspectRatioMode = 'auto';
                drawnow;

%                 AR = ax2D.PlotBoxAspectRatio;
                AR = diff(ylim(ax2D))./diff(xlim(ax2D));
                ax2D.PlotBoxAspectRatio     = [1 1 1];
                ax2D.DataAspectRatio        = [1 1 1];
                drawnow;

                axP = axFTIR.Position;
                axFTIR.Position(3) = axP(3).*AR;
                axFTIR.Position(1) = axP(1) + axP(3).*(1-AR)/2;
                drawnow;
            end
        end

        % Button pushed function: I2D_PlotKineticsButton
        function I2D_PlotKineticsButtonPushed(app, event)
            if strcmp(app.I2D_InteractivemodeSwitch.Value,'On')
                % Patch because ginput doesn't work on UIFigures
                fh = figure;
                fh.Position = app.TwoDAxes.Position;
                fh.Position(1:2) = fh.Position(1:2) + app.DataAnalysisGUI_UIFigure.Position(1:2);
                newaxes = axes('Parent',fh);
                UpdatePlot_I2D(app,newaxes,'Update');
            end
            k=app.I2D_ProbeDetSwitch.Value;
            plot2DIR_Kinetics(app,app.I2D_Data,k);
            
            if strcmp(app.I2D_InteractivemodeSwitch.Value,'On')
                % Close the additional figure
                close(fh)
            end
        end

        % Button pushed function: I2D_PlotSlicesButton
        function I2D_PlotSlicesButtonPushed(app, event)
            if strcmp(app.I2D_InteractivemodeSwitch.Value,'On')
                % Patch because ginput doesn't work on UIFigures
                fh = figure;
                fh.Position = app.TwoDAxes.Position;
                fh.Position(1:2) = fh.Position(1:2) + app.DataAnalysisGUI_UIFigure.Position(1:2);
                newaxes = axes('Parent',fh);
                UpdatePlot_I2D(app,newaxes,'Update');
            end
            k=app.I2D_ProbeDetSwitch.Value;
            plot2DIR_Slices(app,app.I2D_Data,k);
            
            if strcmp(app.I2D_InteractivemodeSwitch.Value,'On')
                % Close the additional figure
                close(fh)
            end
        end

        % Button pushed function: I2D_SurfaceplotButton
        function I2D_SurfaceplotButtonPushed(app, event)
            k=app.I2D_ProbeDetSwitch.Value;
            fh = figure;
            ax = axes(fh);
            ax.Visible='Off';
            UpdateInfo_I2D(app,'Read')
            SurfPlot_2DIR(app.I2D_Data.plotOptions,app.I2D_Data,ax,k);
            fh.Color = [1 1 1];
            % Make uniform, consistent format
            ax.FontSize = 12;
            ax.LineWidth = 1;
            ax.TickLength = [0.015 0.035];
            ax.Visible='On';    
        end

        % Button pushed function: I2D_SpectralDiffusion_button
        function I2D_SpectralDiffusion_buttonButtonPushed(app, event)
            k=app.I2D_ProbeDetSwitch.Value;
            if strcmp(app.I2D_InteractivemodeSwitch.Value,'On')
                % Patch because ginput doesn't work on UIFigures
                fh = figure;
                fh.Position = app.TwoDAxes.Position;
                fh.Position(1:2) = fh.Position(1:2) + app.DataAnalysisGUI_UIFigure.Position(1:2);
                newaxes = axes('Parent',fh);
                UpdatePlot_I2D(app,newaxes,'Update');
                app.I2D_Data = SpectralDiffusion_analysis(app,app.I2D_Data,newaxes,k);
            else
                app.I2D_Data = SpectralDiffusion_analysis(app,app.I2D_Data,app.TwoDAxes,k);
            end
                                  
            UpdateInfo_I2D(app,'Read');
            UpdatePlot_I2D(app,app.TwoDAxes,'NoChange');
                
            if strcmp(app.I2D_InteractivemodeSwitch.Value,'On')
                % Close the additional figure
                close(fh)
            end
        end

        % Button pushed function: I2D_SaveProbeCalibration_button
        function I2D_SaveProbeCalibration_buttonButtonPushed(app, event)
            ProbeAxis   = app.I2D_Data.ProbeAxis;
            probe       = [];
            for k=1:length(ProbeAxis)
                probe   = [probe;ProbeAxis{k}];
            end
            probecalfile= [app.rootdir filesep app.I2D_Data.datafilename filesep 'CalibratedProbe.csv'];
            if exist(probecalfile,'file') == 0
                csvwrite(probecalfile,ProbeAxis);
            else
                % Ask the user what to do
                choice = questdlg('A calibrated probe file already exists, overwrite?', ...
                    'Probe calibration', ...
                    'Yes','No','No');
                % Handle response
                switch choice
                    case 'Yes'
                        writematrix(probe,probecalfile);
                    case 'No'
                        % Do nothing
                end
            end
        end

        % Button pushed function: I2D_2DGCFit_button
        function I2D_2DGCFit_buttonButtonPushed(app, event)
            if strcmp(app.I2D_InteractivemodeSwitch.Value,'On')
                % Patch because ginput doesn't work on UIFigures
                fh = figure;
                fh.Position = app.TwoDAxes.Position;
                fh.Position(1:2) = fh.Position(1:2) + app.DataAnalysisGUI_UIFigure.Position(1:2);
                newaxes = axes('Parent',fh);
                UpdatePlot_I2D(app,newaxes,'NoChange');
            end
            
            [app.I2D_Data,exitcode] = Gaussian2D_analysis(app,app.I2D_Data);
            
            switch exitcode
                case 0
                    app.I2D_Show2DGCresults.Enable      = 'on';
                    app.I2D_Show2DGC_Fit.Enable         = 'on';
                    app.I2D_Show2DGC_Residuals.Enable   = 'on';
                    app.I2D_Show2DGCresults.Value       = 1;
                    app.I2D_Show2DGC_Fit.Value          = 1;
                case 1
                    app.I2D_Show2DGCresults.Enable      = 'off';
                    app.I2D_Show2DGC_Fit.Enable         = 'off';
                    app.I2D_Show2DGC_Residuals.Enable   = 'off';
                    app.I2D_Show2DGCresults.Value       = 0;
                    app.I2D_Show2DGC_Fit.Value          = 1;
            end
            
            UpdateInfo_I2D(app,'Read');
            UpdatePlot_I2D(app,app.TwoDAxes,'NoChange');
                
            if strcmp(app.I2D_InteractivemodeSwitch.Value,'On')
                % Close the additional figure
                close(fh)
            end
        end

        % Callback function: I2D_ProbeDetSwitch, 
        % ...and 1 other component
        function I2D_RestoredefaultlimitsButtonPushed(app, event)
                % Initialise defaults and update the Edits for the first time ('Init'):
                UpdateInfo_I2D(app,'Init');
                % Read other edits into the plotOptions structure ('Read'):
                UpdateInfo_I2D(app,'Read');
                % Plot the data in the main window (for preview)
                UpdatePlot_I2D(app,app.TwoDAxes,'Update');
        end

        % Button pushed function: PP_RecalcScans_Button
        function PP_RecalcScans_ButtonPushed(app, event)
            Nscans          = app.PP_Data.Nscans;
            datafilename    = app.PP_Data.datafilename;
            tempdir         = [app.rootdir filesep datafilename filesep 'temp'];
            tempdirOUT      = [app.rootdir filesep datafilename 'temp'];
            % Check if the temp dir is outside (Lab 1)
            if exist(tempdir,'dir') == 0
                tempdir     = tempdirOUT;
            end
            
            if  ~isnan(Nscans)
                PlotScans = inputdlg(['Select scans to average (format m:n, m&n < ' num2str(Nscans) ')']);
                if isempty(PlotScans)
                    return
                else
                    PlotScans = str2num(PlotScans{:});
                end
            else
                warndlg('No individual scan data available!')
                return
            end
            
            % Load the scan data depending the data format
            switch app.PP_DataFormat.Value
               case 'Pump-Probe [Lab 2]'
                    % The first scan has no _n.csv termination
                        TempScanData    = csvread([tempdir filesep datafilename '_signal.csv']);
                        TempScanNoise   = csvread([tempdir filesep datafilename '_signal_noise.csv']);
                        ScanData        = zeros(size(TempScanData,1),size(TempScanData,2),Nscans);
                        ScanNoise       = zeros(size(TempScanData,1),size(TempScanData,2),Nscans);
                    if Nscans > 1
                        ScanData(:,:,1) = TempScanData;
                        ScanNoise(:,:,2)= TempScanNoise;
                        % Load scans 2 to Nscans
                        for i=2:Nscans
                            ScanNrch        = [tempdir filesep datafilename '_signal_' num2str(i-1) '.csv'];
                            ScanNoiseNrch   = [tempdir filesep datafilename '_signal_noise_' num2str(i-1) '.csv'];
                            ScanData(:,:,i) = csvread(ScanNrch);
                            ScanNoise(:,:,i)= csvread(ScanNoiseNrch);
                        end
                        clear TempScanData;
                    else
                        ScanData(:,:,1) = TempScanData;
                    end
                case 'Pump-Probe [Lab 1 & Lab 4]' % Lab 1 & Lab 4
                    % The first scan has no _n.csv termination
                        TempScanData    = csvread([tempdir filesep datafilename '_signal_sp0_sm0_du0_0.csv']);
                        TempScanNoise   = csvread([tempdir filesep datafilename '_signal_noise_sp0_sm0_du0_0.csv']);
                        ScanData        = zeros(size(TempScanData,1),size(TempScanData,2),Nscans);
                        ScanNoise       = zeros(size(TempScanData,1),size(TempScanData,2),Nscans);
                    if Nscans > 1
                        ScanData(:,:,1) = TempScanData;
                        ScanNoise(:,:,1)= TempScanNoise;
                        % Load scans
                        for i=2:Nscans
                            ScanNrch        = [tempdir filesep datafilename '_signal_sp0_sm0_du0_' num2str(i-1) '.csv'];
                            ScanNoiseNrch   = [tempdir filesep datafilename '_signal_noise_sp0_sm0_du0_' num2str(i-1) '.csv'];
                            ScanData(:,:,i) = csvread(ScanNrch);
                            ScanNoise(:,:,i)= csvread(ScanNoiseNrch);
                        end
                        clear TempScanData;
                    else
                        ScanData(:,:,1)     = TempScanData;
                        ScanNoise(:,:,1)    = TempScanNoise;
                    end
                case 'UniGE TA'
                    ScanData    = app.PP_Data.scandata;
                    ScanNoise   = app.PP_Data.scanNoise;
                    ScanSigns   = inputdlg(sprintf(['Write an array of +1 or -1 to decide the sign of each scan. \n Selected scans: ' num2str(PlotScans)]));
                    if isempty(ScanSigns)
                        return
                    else
                        ScanSigns = str2num(ScanSigns{:});
                    end
                    
                    ScanSigns_3D = ones(size(ScanData{1}(:,:,PlotScans)));
                    for j=1:length(PlotScans)
                        ScanSigns_3D(:,:,j) = ScanSigns_3D(:,:,j).*ScanSigns(j);
                    end
                    ScanData{1}(:,:,PlotScans) = ScanData{1}(:,:,PlotScans).*ScanSigns_3D;
                case 'TRIR (Sheffield)'
                    ScanData    = app.PP_Data.scandata;
                    ScanNoise   = app.PP_Data.scanNoise;
                otherwise
                    warning('This feature is not implemented for the selected data format');
                    return
            end
            % Write the actual selected scans back into handles
            for i=1:length(ScanData)
                app.PP_Data.rawsignal{i}        = mean(ScanData{i}(:,:,PlotScans),3);
                app.PP_Data.noise{i}            = mean(ScanNoise{i}(:,:,PlotScans),3);
            end

            % Load the pump-probe data
            error = LoadPPData(app,1,'NONE',1,1);
            if error == 0
                % Initialise defaults and update the Edits for the first time ('Write'):
                UpdateInfo_PP(app,'Write');
                app.PP_BackgroundSubtract_timeLabel.Text = app.PP_Data.timescale;
                % Override default w/current status of linlog tick
                switch app.PP_LogTime_tick.Value
                    case 0
                        app.PP_Data.linlog = 'lin';
                    case 1
                        app.PP_Data.linlog = 'log';
                end
                % Disable the corresponding panel if no single-scan data is available
                if isnan(app.PP_Data.Nscans)
                    app.PP_AnalyseindividualscansPanel.Visible = 'off';
                else
                    app.PP_AnalyseindividualscansPanel.Visible = 'on';
                end
                % Show sample information, noise statistics and other
                app.PP_SampleInfo.Value = ReadPPSampleInfo(app);
                % Plot the data in the main window (for preview)
                UpdatePlot_PP(app,app.PumpProbeAxes);
            end
        end

        % Button pushed function: PP_KineticsPerScan_Button
        function PP_KineticsPerScan_ButtonPushed(app, event)
            app.PP_Data.BinScans = app.PP_NBinScans.Value;
            plot_KineticsPerScan_PP(app,app.PP_Data)
        end

        % Button pushed function: PP_SpectraPerScan_Button
        function PP_SpectraPerScan_ButtonPushed(app, event)
            app.PP_Data.BinScans = app.PP_NBinScans.Value;
            plot_SpectraPerScan_PP(app,app.PP_Data)
        end

        % Menu selected function: ReloaddatasetMenu
        function ReloaddatasetMenuSelected(app, event)
            switch app.MainTabs.SelectedTab.Title
                case 'Pump-Probe'
                    PP_DataFolderListValueChanged(app, event)
                case 'Interferometric 2D-IR'
                    I2D_DataFolderListValueChanged(app, event)
            end
        end

        % Value changed function: PP_Background_end, PP_Background_start, 
        % ...and 1 other component
        function PP_UpdateBackground(app, event)
            % Load the pump-probe data
            error = LoadPPData(app,1,'NONE',0,1);
            if error == 0
                % Initialise defaults and update the Edits for the first time ('Write'):
                UpdateInfo_PP(app,'Write');
                % Show sample information, noise statistics and other
                app.PP_SampleInfo.Value = ReadPPSampleInfo(app);
                % Plot the data in the main window (for preview)
                UpdatePlot_PP(app,app.PumpProbeAxes);
            end
        end

        % Menu selected function: ShowbasichelpMenu
        function ShowbasichelpMenuSelected(app, event)
            warndlg('The help system is not implemented yet. Do not panic. Please contact Ricardo directly: Ricardo.Fernandez@sheffield.ac.uk','No immediate help available :(');
        end

        % Button pushed function: GototodayButton
        function GototodayButtonPushed(app, event)
            % Go to the folder
            datedir     = datestr(now,'yyyymmdd');
            switch app.MainTabs.SelectedTab.Title
                case 'Pump-Probe'
                    switch app.PP_DataFormat.Value
                        case {'Helios TA','UniGE TA','UniGE nsTA','Pump-Probe [TRUVIS]'}
                            startDir = app.defaultTAdir;
                        case {'TRIR (Sheffield)','Pump-Probe [Lab 1 & Lab 4]','Pump-Probe [Lab 2]','Lab 5 (*.dat)'}
                            startDir = app.defaultTRIRdir;
                        case 'FLUPS (Sheffield)'
                            startDir = app.defaultFLUPSdir;
                        otherwise
                            startDir = app.defaultSSdir;
                    end
                case 'Interferometric 2D-IR'
                    startDir = app.default2DIRdir;
                otherwise
                    startDir = pwd;
            end
            todaydir    = [startDir filesep datedir];
            if exist(todaydir,'dir') ~= 0
                % Update the root dir and rebuild file lists
                app.rootdir = todaydir;
                app.RootDirEditField.Value = app.rootdir;
                switch app.MainTabs.SelectedTab.Title
                    case 'Pump-Probe'
                        UpdateDataFolderList_PP(app);
                    case 'Interferometric 2D-IR'
                        UpdateDataFolderList_I2D(app);  
                end
            else
                app.StatusMessage.Value      = "Today's folder doesn't exist! - try something else :(";
            end
        end

        % Button pushed function: PP_ILTfit_button
        function PP_ILTfit_buttonButtonPushed(app, event)
            probeWL = app.PP_Data.cmprobe;
            delays  = app.PP_Data.delays;
            % Check if RAW or CORR and give DATA
            switch app.PP_Data.rawcorr
                case 'RAW'
                    Zdata = app.PP_Data.rawsignal;
                case 'CORRECTED'
                    Zdata = app.PP_Data.corrdata;
            end
            Noise = app.PP_Data.noise;
            
            time = delays(delays>=0);
            Noise = Noise(delays>=0,:);
            Zdata = Zdata(delays>=0,:);
            ILT_fit(time,probeWL,Zdata,Noise);
        end

        % Button pushed function: PP_GlobalFitSVD_button
        function PP_GlobalFitSVD_buttonButtonPushed(app, event)
            k = app.PP_ProbeDetSwitch.Value;
            switch app.PP_InteractivemodeSwitch.Value
                case 'On'
                    % Read SVD components to fit from the control in the main window
                    SingVals    = sort(app.PP_SVDstart.Value:app.PP_SVDend.Value); 
                case 'Off'
                    % Ask the user for the SVD components to fit
                    prompt      = 'Enter desired SVD components to fit (i.e. 1 to 3 = 1:3):';
                    dlg_title   = 'Select SVD components';
                    num_lines   = [1 60];
                    SelSVD      = inputdlg(prompt,dlg_title,num_lines);
                    SingVals    = transpose(str2double(SelSVD{1}));
            end
            nSVD = length(SingVals);
            % Check if RAW or CORR and give DATA
            switch app.PP_Data.rawcorr
                case 'RAW'
                    data = app.PP_Data.rawsignal{k};
                case 'CORRECTED'
                    data = app.PP_Data.corrdata{k};
            end
            % Do the SVD and take only the components selected by the user
            [T,SV,WL] = svd(data);
            SVD = 1;
            SV_diag=diag(diag(SV),0);
            % Prepare the labels and required variables
            YLabel = '\DeltaAbs (mOD)';
            
            FitType=2; % i.e. 1= LOCAL, 2 = GLOBAL SVD fit
            % Now open the fit window
            [fitP,fitP_SD,FitFunc,Residuals,FitDone,tau_index,c_index,InputParams] = FitWindow(app.PP_Data.delays,T(:,SingVals)*SV_diag(SingVals,SingVals),SingVals,YLabel,app.PP_Data.timescale,FitType);
            % After the user has closed the window, save the global fit results to file
            % (overwrite by default)
                %%%% TO BE DONE
            % Get the Taus (and SD's)
                Taus            = fitP(tau_index);
                Taus_SD         = fitP_SD(tau_index);
                NumExp          = length(Taus);
                C_matrix        = fitP(c_index(:,1:NumExp));
                C_SD_matrix     = fitP_SD(c_index(:,1:length(Taus)));
            % Plot the DAS
                % Get the DAS
                SelWL = WL(:,SingVals);
                DAS = SelWL*C_matrix;  
                % Create a new figure with consistent format
                fh = figure();
                fh.Position(3)  = 800;
                fh.Position(4)  = 425;
                fh.Color        = [1 1 1];
                fh.Units        = 'normalized';
                
                % Define the axes
                axes2 			= axes('Parent',fh);
                axes2.Units     = 'pixels';
                axes2.Position  = [75 75 680 320];
                axes(axes2);
                % Plot the DAS
                cm=colormap(othercolor('Mrainbow',NumExp+1));
                legendlabel={};
                for n=1:NumExp
                   % Check if Tau > 1000, then convert to next prefix
                   if Taus(n) > 1000
                       switch app.PP_Data.timescale
                            case 'ns'
                                newtimescale = '\mus';
                            case 'ps'
                                newtimescale = 'ns';
                            case 'fs'
                                newtimescale = 'ps';
                       end
                       f=1000;
                   elseif Taus(n) < 1
                       switch app.PP_Data.timescale
                            case 'ns'
                                newtimescale = 'ps';
                            case 'ps'
                                newtimescale = 'fs';
                       end
                       f=1/1000;
                   else
                       f=1;
                       newtimescale = app.PP_Data.timescale;
                   end
                   strTau      = num2str(Taus(n)/f,'%.3g');
                   strTau_SD   = num2str(Taus_SD(n)/f,'%.3g');
                   
                   legendlabel{n}=['\tau_{' num2str(n) '}' ' = ' strTau ' \pm ' strTau_SD ' ' newtimescale];
                   plot(app.PP_Data.cmprobe{k},DAS(:,n),'LineWidth',2,'Marker','none','color',cm(n,:));
                   hold on
                end
                % Calculate the infinite component (if it exists)
                if prod(InputParams.C_holds(:,5)) == 0
                    cInfty          = fitP(c_index(:,5));
                    cInfty_SD       = fitP_SD(c_index(:,5));
                    INF = SelWL*cInfty;
                    % Plot the infinite component
                    plot(app.PP_Data.cmprobe{k},INF,'LineWidth',2,'Marker','none','color',cm(end,:));
                    legendlabel{NumExp+1}='\tau_{\infty}';
                elseif sum(InputParams.C_holds(:,5)) ~= 0
                    cInfty          = InputParams.C_values(:,6);
                end
                % Calculate the offset (if it exists)
                if sum(InputParams.C_holds(:,6)) ~= nSVD && sum(InputParams.C_values(:,6)) ~= 0 
                    Offset          = fitP(c_index(:,6));
                    Offset_SD       = fitP_SD(c_index(:,6));
                elseif sum(InputParams.C_values(:,6)) ~= 0
                    Offset          = InputParams.C_values(:,6);
                end
                
                % Formatting
                l=legend(legendlabel); legend('boxoff'); legend('Location','northeast');
                l.Interpreter = 'tex';
                set(gca,'FontSize',12);
                xlabel([ app.PP_Data.probeunits ' (' app.PP_Data.Xunits ')'],'FontSize',13,'FontWeight','bold')
                ylabel('Amplitude','FontSize',13,'FontWeight','bold')
                axis tight
                hline = refline(0,0); hline.Color = [0.5 0.5 0.5];
                set(get(get(hline,'Annotation'),'LegendInformation'),'IconDisplayStyle','off')
                
            % Plot the 2D residual surface
                % Initialise variables
                FitEval=zeros(length(app.PP_Data.delays),nSVD);
                % Evaluate the fit functions with the fitted params
                for m=1:nSVD
                    FitEval(:,m) = feval(FitFunc{m},fitP,app.PP_Data.delays);
                end
                % Retrieve the 2D maps (and residuals)
                GlobalFit{1}=FitEval*transpose(SelWL);
                GlobalRes{1}=data-GlobalFit{1};
                % Plot the fitted surface
                fh = figure;
                ax = axes();
                plot2D(app,GlobalFit,ax,'Off',app.PP_ProbeDetSwitch.Value);
                title({app.PP_Data.datafilename;['FITTED SURFACE';'']},'Interpreter','none')
                cl=caxis(ax);
                fh.Units        = 'normalized';
                % Plot the residual surface
                fh = figure;
                ax = axes();
                plot2D(app,GlobalRes,ax,'Off',app.PP_ProbeDetSwitch.Value)
                title({app.PP_Data.datafilename;['RESIDUAL SURFACE';'']},'Interpreter','none');
                caxis(ax,cl);
                fh.Units        = 'normalized';
                fh.Position(1)  =  fh.Position(1)-0.3;
                % Plot the residual surface with maximum scale
                fh = figure;
                ax = axes();
                plot2D(app,GlobalRes,ax,'Off',app.PP_ProbeDetSwitch.Value)
                caxis auto
                title({app.PP_Data.datafilename;['RESIDUAL SURFACE - max Z scale';'']},'Interpreter','none');
                fh.Units        = 'normalized';
                fh.Position(1)  =  fh.Position(1)-0.3;
                fh.Position(2)  =  fh.Position(2)-0.3;
            % Everything Done! :)
        end

        % Button pushed function: PP_MaskprobeWLButton
        function PP_MaskprobeWLButtonPushed(app, event)
            Ndet = length(app.PP_Data.rawsignal);
        
            % Ask the user for the probe ranges to mask (with NaN)
            mask_regions = inputdlg('Enter the probe regions to mask (a,b;c,d; etc): ',...
                         'Mask probe regions', [1 50],{'0,410;710,1000'});
            if isempty(mask_regions)
                figure(app.DataAnalysisGUI_UIFigure);
                return
            end
            
            mask_method  = questdlg('Select probe masking method:','Mask probe method','NaN','Delete','Cancel','NaN');
            if strcmp(mask_method,'Cancel')
                figure(app.DataAnalysisGUI_UIFigure);
                return
            end
            
            mask_regions = str2num(mask_regions{:});
            mask_regions = sort(mask_regions,1);
  
            DET = app.PP_ProbeDetSwitch.Value;
            % Get the values to find in the WAVELENGTH vector
            value = mask_regions(:); % column 1= Wavelength
            % k = index of the closest match for each selected trace
            L = size(mask_regions,1);
            
            % Ensure that we have the same number of regions to cut, otherwise something is wrong
            uId = unique(findClosestId2Val(app.PP_Data.cmprobe{DET},transpose(value)));
            if length(uId) ~= 2*L
                figure(app.DataAnalysisGUI_UIFigure);
                return
            end
            
            % Generate indices of pixels to mask
            k = reshape(uId,[2,L])';
            k_All = [];
            for i=1:L
                k_All = [k_All k(i,1):1:k(i,2)];
            end
            
            % Mask the pixels
            switch mask_method
                case 'NaN'
                    % For each mask region, replace everything in the region by NaNs
                        app.PP_Data.rawsignal{DET}(:,k_All)   = NaN;
                        app.PP_Data.corrdata{DET}(:,k_All)    = NaN;
                        app.PP_Data.noise{DET}(:,k_All)       = NaN;
                case 'Delete'
                    % For each mask region, cut the corresponding column(s) out from the matrix
                        app.PP_Data.rawsignal{DET}(:,k_All)   = [];
                        app.PP_Data.corrdata{DET}(:,k_All)    = [];
                        app.PP_Data.noise{DET}(:,k_All)       = [];
                        app.PP_Data.cmprobe{DET}(k_All)       = [];
                        app.PP_Data.bkg{DET}(k_All)           = [];
            end
            
            % Recalculate the sample statistics
            minabs          = min(app.PP_Data.rawsignal{DET}(:));
            maxabs          = max(app.PP_Data.rawsignal{DET}(:));
            zminmax         = round(max([abs(minabs) abs(maxabs)]),3);
            app.PP_Data.plotranges{DET}(5:6) = [minabs maxabs];
            
            % Calculate noise statistics
            app.PP_Data.AvgNoise     = mean(app.PP_Data.noise{DET}(:));
            app.PP_Data.MaxNoise     = max(app.PP_Data.noise{DET}(:));
            app.PP_Data.SNR          = abs(round(zminmax/app.PP_Data.AvgNoise,3));

            % Replot the data in the main window
            % Initialise defaults and update the Edits for the first time ('Write'):
            UpdateInfo_PP(app,'Write');
            % Show sample information, noise statistics and other
            app.PP_SampleInfo.Value = ReadPPSampleInfo(app);
            % Plot the data in the main window (for preview)
            UpdatePlot_PP(app,app.PumpProbeAxes);
        end

        % Callback function: I2D_LoadFit, LoadfitfromfileMenu
        function I2D_LoadFitButtonPushed(app, event)
            [file,path] = uigetfile({'*.mat','Fit result file'},'Select a File',app.rootdir);
            
            if file == 0
                return
            else
                ExtFit = load([path filesep file]);
                try 
                    app.I2D_Data.FitResults             = ExtFit.FitResults;
                    app.I2D_Data.t2_fitrange            = minmax(ExtFit.t2delays');
                    app.I2D_Data.t2_fitdelays           = ExtFit.t2delays;
                    try
                       app.I2D_Data.FitInput               = ExtFit.input_st;
                    catch
                       app.I2D_Data.FitInput               = ExtFit.input;
                    end
                    app.I2D_Show2DGCresults.Enable      = 'on';
                    app.I2D_Show2DGC_Fit.Enable         = 'on';
                    app.I2D_Show2DGC_Residuals.Enable   = 'on';
                    app.I2D_Show2DGCresults.Value       = 1;
                    app.I2D_Show2DGC_Fit.Value          = 1;
                    app.StatusMessage.Value             = 'Fit loaded successfully!';
                catch err
                    app.I2D_Show2DGCresults.Enable      = 'off';
                    app.I2D_Show2DGC_Fit.Enable         = 'off';
                    app.I2D_Show2DGC_Residuals.Enable   = 'off';
                    app.I2D_Show2DGCresults.Value       = 0;
                    app.I2D_Show2DGC_Fit.Value          = 1;
                    app.StatusMessage.Value             = err.message;
                end
            end
            
            UpdateInfo_I2D(app,'Read');
            UpdatePlot_I2D(app,app.TwoDAxes,'NoChange');
        end

        % Value changed function: I2D_PlotScaleNumber, I2D_PlotScaleSlider
        function I2D_UpdatePlotScaleNumberandSlider(app, event)
            app.I2D_PlotScaleNumber.Value   = event.Value;
            app.I2D_PlotScaleSlider.Value   = event.Value;
            UpdateInfo_I2D(app,'Read');
            UpdatePlot_I2D(app,app.TwoDAxes,'NoChange')
            UpdateInfo_I2D(app,'Update');
            I2D_ExtraPlotOptionSelectionChanged(app,event);
        end

        % Button pushed function: I2D_S_PlotTrajectory
        function I2D_S_PlotTrajectoryButtonPushed(app, event)
            if app.I2D_S_PlotTraject_All.Value == 1
                DistPlot = 'All';
            elseif app.I2D_S_PlotTraject_Single.Value == 1
                DistPlot = 'Single';
            end
            plotTrajectory(app.I2D_Data.simData,'no pie',app.I2D_S_WhichTraject.Value,DistPlot)
        end

        % Button pushed function: I2D_S_PlotRelConc
        function I2D_S_PlotRelConcButtonPushed(app, event)
            if app.I2D_S_PlotTraject_All.Value == 1
                DistPlot = 'All';
            elseif app.I2D_S_PlotTraject_Single.Value == 1
                DistPlot = 'Single';
            end
            plotTrajectory(app.I2D_Data.simData,'with pie',app.I2D_S_WhichTraject.Value,DistPlot)
        end

        % Button pushed function: I2D_S_CalcGr
        function I2D_S_CalcGrButtonPushed(app, event)
            plotRDF(app.I2D_Data.simData,app.I2D_Data.rootdir,app.I2D_Data.datafilename,app.I2D_SavetracesSwitch.Value)
        end

        % Button pushed function: I2D_S_Plot1Dspec
        function I2D_S_Plot1DspecButtonPushed(app, event)
            % New figure and axes
            fh              = figure();
            axes2           = axes('Parent',fh);
            % Plot the data
            specX = app.I2D_Data.simData.LinSpec_FD(:,1);
            specY = app.I2D_Data.simData.LinSpec_FD(:,2);
            
            plot(axes2,specX,specY/max(specY(:)),'LineWidth',1.5,'Marker','none','color','k');
            % Nice formatting
            axes2.FontSize  = 12;
            title(axes2,'Simulated linear absorption spectrum');
            xlabel(axes2,'Wavenumbers (cm^{-1})','FontSize',13,'FontWeight','bold')
            ylabel(axes2,'Normalised Absorbance','FontSize',13,'FontWeight','bold')
            axis(axes2,'tight')
            hline = yline(axes2,0,'HandleVisibility','off'); hline.Color = [0.5 0.5 0.5];
        end

        % Button pushed function: I2D_MakeMovie_button
        function I2D_MakeMovie_buttonButtonPushed(app, event)
            movierng    = inputdlg({'Type t2 range for movie (start,end):','Frame delay (s):','Loop count:'},'Hollywood',[1 50],{'','0.5','Inf'});
            range_t2    = str2num(movierng{1}); %#ok<*ST2NM>
            frameDelay  = str2num(movierng{2});
            loopCount   = str2num(movierng{3});
            t2delays    = app.I2D_Data.t2delays;
            t2_rng      = findClosestId2Val(t2delays,range_t2);
            Nplots      = max(t2_rng) - min(t2_rng) + 1;
            t2_array    = min(t2_rng):max(t2_rng);
            datafilename    = app.I2D_Data.datafilename;
            % Copy current plot limits    
                xl = xlim(app.TwoDAxes);
                yl = ylim(app.TwoDAxes);
            % Create figure    
                fh = figure('MenuBar',"none");
                ax = axes(fh);
                ax.Visible='Off';
                UpdateInfo_I2D(app,'Read');
                plotOptions = app.I2D_Data.plotOptions;
                MovieFrame(Nplots) = struct('cdata',[],'colormap',[]);
                k = app.I2D_ProbeDetSwitch.Value;
                for i=1:Nplots
                    plotOptions.popdelay    = t2_array(i);
                    app.I2D_Data.PlotRanges = ContourPlot_2DIR(plotOptions,app.I2D_Data,ax,k);
                    switch app.I2D_SquareAspectRatio_tick.Value
                        case 1
                            ax.PlotBoxAspectRatio     = [1 1 1];
                            ax.DataAspectRatio        = [1 1 1];
                            ax.DataAspectRatioMode    = 'manual';
                            ax.PlotBoxAspectRatioMode = 'manual';
                        case 0
                            ax.DataAspectRatioMode    = 'auto';
                            ax.PlotBoxAspectRatioMode = 'auto';
                    end
                    fh.Color        = [1 1 1];
                    % Make uniform, consistent format
                    ax.FontSize     = 21;
                    fh.Position(3:4)= [560 420]*1.5;
                    
                    ax.LineWidth    = 1;
                    ax.TickLength   = [0.015 0.035];
                    ax.Visible      = 'On';
                    % Restore axes limits
                    xlim(ax,xl)
                    ylim(ax,yl)
                    drawnow
                    MovieFrame(i) = getframe(fh);
                end
           movie2gif(MovieFrame,[app.rootdir filesep datafilename '_MOVIE.gif'],'LoopCount',loopCount,'DelayTime',frameDelay);
        end

        % Button pushed function: I2D_S_PlotAngleHist
        function I2D_S_PlotAngleHistButtonPushed(app, event)
            if app.I2D_S_PlotTraject_All.Value == 1
                HistPlot = 'All';
            elseif app.I2D_S_PlotTraject_Single.Value == 1
                HistPlot = 'Single';
            end
            plotAngleHist(app.I2D_Data.simData,app.I2D_S_WhichTraject.Value,HistPlot);
        end

        % Menu selected function: ExportPPdataaspdatMenu
        function SavePPData_MenuSelected(app, event)
            DET = app.PP_ProbeDetSwitch.Value;
            [~,datafilename,~] = fileparts(app.PP_Data.datafilename);
            if ~strcmp(app.MainTabs.SelectedTab.Title,'Pump-Probe')
                warndlg('Only works for Pump-probe/1D datasets!','Wrong data type!');
                return
            end
            m = length(app.PP_Data.delays);
            n = length(app.PP_Data.cmprobe{DET});
            %The signal has m rows and n columns
            XYZdata(1,2:n+1) = transpose(app.PP_Data.cmprobe{DET});
            XYZdata(2:m+1,1) = app.PP_Data.delays;
            switch app.PP_Data.rawcorr
                case 'RAW'
                    XYZdata(2:m+1,2:n+1) = app.PP_Data.rawsignal{DET};
                    savefilename = [datafilename '-RAW'];
                case 'CORRECTED'
                    XYZdata(2:m+1,2:n+1) = app.PP_Data.corrdata{DET};
                    savefilename = [datafilename '-CORRECTED'];
            end
            
            try 
                timeUnits = app.PP_Data.timescale;
                probeUnits = app.PP_Data.Xunits;     
                fname = [app.rootdir filesep savefilename '_DET' num2str(DET) '.pdat'];
                writecell({[timeUnits '*' probeUnits]},fname,'FileType','text');
                writematrix(XYZdata,fname,'FileType','text','WriteMode','append');
                helpdlg(["Data was saved in "; [app.rootdir filesep savefilename '.pdat']],'Data saved successfully!');
            catch ME
                errordlg('Error saving data')
                return
            end
        end

        % Button pushed function: I2D_SubtractSpectra_button
        function I2D_SubtractSpectra_buttonButtonPushed(app, event)
        msg = 'Select type of subtraction wanted:';
        title = 'Subtract 2D-IR datasets';
        SubtractionType = questdlg(msg,title,'One delay','Full Dataset','Cancel','Full Dataset');
            switch SubtractionType
                case 'Cancel'
                    return
                case 'One delay'
                    warndlg('Not implemented-- for now ;)')
                    return
                case 'Full Dataset'
                    % Get the phased 2D data (current dataset)
                    A_data  = app.I2D_Data.PROC_2D_DATA;

                    % Ask the user what to plot
                    [indx,tf] = listdlg('PromptString',{'Select a file','Select one dataset to be subtracted from currently loaded dataset...',''},'ListString',app.I2D_DataFolderList.Items,'SelectionMode','single');
                    if tf == 0
                        return
                    else
                        dataset = app.I2D_DataFolderList.Items{indx};
                    end
                    
                    [error,B_data] = LoadProcessI2DData(app,dataset);

                    % Calculate the differential 2D-IR spectra                     
                        % Check if the datasets have the number of t2 delays
                        if length(A_data) ~= length(B_data)
                            opts.Interpreter    = 'tex';
                            opts.WindowStyle    = 'replace';
                            warndlg('Datasets do not have the same number of \itt_2\rm delays!','Warning',opts);
                            return
                        end
                      
                        % Check if the datasets have the same FT and pixel size
                        if length(A_data{1,1}) ~= length(B_data{1,1})
                            opts.Interpreter    = 'tex';
                            opts.WindowStyle    = 'replace';
                            warndlg('Datasets do not have the same \omega_1 / \omega_3 sizes!','Warning',opts);
                            return
                        end
                        
                        % Preallocate variables
                        Ncols       = size(A_data,2);
                        diff_data   = cell(length(A_data),Ncols);
                        
                        % If everything is OK, do the subtraction
                        for i=1:length(A_data)
                            diff_data{i}    = A_data{i} - B_data{i};
                        end
                        
                    % Write back to main handles structure
                        app.I2D_Data.PROC_2D_DATA = diff_data;
                        
                    % Plot it on the main axes
                    if error == 0
                        % Initialise defaults and update the Edits for the first time ('Init'):
                        UpdateInfo_I2D(app,'Init');
                        % Read other edits into the plotOptions structure ('Read'):
                        UpdateInfo_I2D(app,'Read');
                        % Show sample information, noise statistics and other
                        app.I2D_SampleInfo.Value = ReadI2DSampleInfo(app);
                        % Plot the data in the main window (for preview)
                        UpdatePlot_I2D(app,app.TwoDAxes,'update');
                    end
            end
        end

        % Menu selected function: Export2DIRdataMenu
        function Export2DIRdataMenuSelected(app, event)
            
            if ~strcmp(app.MainTabs.SelectedTab.Title,'Interferometric 2D-IR')
                warndlg('Only works for 2D-IR datasets!','Wrong data type!');
                return
            end
            
            k = app.I2D_ProbeDetSwitch.Value;
            
            % Create "Export" folder
            exportfolder = [app.rootdir filesep app.I2D_Data.datafilename filesep 'Export'];
            [ec,msg] = mkdir(exportfolder);
            if ec==0
                errordlg(msg,'Error creating folder')
                return
            end
            
            % Get the 2D data and cut it
            minWL_pump          = app.I2D_Data.plotOptions.minWL_pump;
            maxWL_pump          = app.I2D_Data.plotOptions.maxWL_pump;
            minWL_probe         = app.I2D_Data.plotOptions.minWL_probe;
            maxWL_probe         = app.I2D_Data.plotOptions.maxWL_probe;
            Ndelays             = app.I2D_Data.Ndelays;
            t2delays            = app.I2D_Data.t2delays;
            ProbeAxis           = app.I2D_Data.ProbeAxis;
            PumpAxis            = app.I2D_Data.PumpAxis;
            PROC_2D_DATA        = app.I2D_Data.PROC_2D_DATA;
            
            % Find indices to cut the data
            probe_id = findClosestId2Val(ProbeAxis{k},[minWL_probe maxWL_probe]);
            Ndet     = length(ProbeAxis);
            % Write to .csv files in XYZ format
            for D=1:Ndet
                for i=1:Ndelays
                    pump_id  = findClosestId2Val(PumpAxis{i},[minWL_pump maxWL_pump]);
                    cut_data = PROC_2D_DATA{i,D}(pump_id(1):pump_id(2),probe_id(1):probe_id(2));
                    [Xid,Yid,Z] = mat2xyz(cut_data);
                    Xcm = PumpAxis{i,k}(pump_id(1):pump_id(2));
                    Ycm = ProbeAxis{k}(probe_id(1):probe_id(2));
                    X = Xcm(Xid);
                    Y = Ycm(Yid);
                    writematrix([X,Y,Z],[exportfolder filesep app.I2D_Data.datafilename '_processed_' num2str(t2delays(i)) 'ps_DET' num2str(D) '.csv']);
                end
            end 
            msgbox('Export Completed!','Export OK!','help','modal');
        end

        % Button pushed function: I2D_TDFit_button
        function I2D_TDFit_buttonButtonPushed(app, event)
            if strcmp(app.I2D_InteractivemodeSwitch.Value,'On')
                % Patch because ginput doesn't work on UIFigures
                fh = figure;
                fh.Position = app.TwoDAxes.Position;
                fh.Position(1:2) = fh.Position(1:2) + app.DataAnalysisGUI_UIFigure.Position(1:2);
                newaxes = axes('Parent',fh);
                UpdatePlot_I2D(app,newaxes,'NoChange');
            end
            
            [app.I2D_Data,exitcode] = TDfit2DIR_analysis(app,app.I2D_Data);
            
            switch exitcode
                case 0
                    app.I2D_Show2DGCresults.Enable      = 'on';
                    app.I2D_Show2DGC_Fit.Enable         = 'on';
                    app.I2D_Show2DGC_Residuals.Enable   = 'on';
                    app.I2D_Show2DGCresults.Value       = 1;
                    app.I2D_Show2DGC_Fit.Value          = 1;
                case 1
                    app.I2D_Show2DGCresults.Enable      = 'off';
                    app.I2D_Show2DGC_Fit.Enable         = 'off';
                    app.I2D_Show2DGC_Residuals.Enable   = 'off';
                    app.I2D_Show2DGCresults.Value       = 0;
                    app.I2D_Show2DGC_Fit.Value          = 1;
            end
            
            UpdateInfo_I2D(app,'Read');
            UpdatePlot_I2D(app,app.TwoDAxes,'NoChange');
                
            if strcmp(app.I2D_InteractivemodeSwitch.Value,'On')
                % Close the additional figure
                close(fh)
            end
        end

        % Value changed function: I2D_SMswitch
        function I2D_SMswitchValueChanged(app, event)
            value = app.I2D_SMswitch.Value;      
        end

        % Menu selected function: OpensettingsfileMenu
        function OpensettingsfileMenuSelected(app, event)
            open(app.SettingsPath);
        end

        % Button pushed function: CAL_Sp_LoadProbeSpectrumButton
        function CAL_Sp_LoadProbeSpectrumButtonPushed(app, event)
            try 
                cla(app.CAL_Axes11);
                cla(app.CAL_Axes12);
                cla(app.CAL_Axes21);
                cla(app.CAL_Axes22);
                
                data = LoadSpectrum(app,'Load Probe Spectrum (air)');
                figure(app.DataAnalysisGUI_UIFigure);
                
                app.CAL_Sp_Lamp1.Color  = [0 1 0];
                app.CAL_data.Air        = data;
                
                if length(data)==1
                    app.CAL_Axes11.Visible = 'on';
                    app.CAL_Axes21.Visible = 'off';
                    app.CAL_Axes12.Visible = 'off';
                    app.CAL_Axes22.Visible = 'off';
                    hold(app.CAL_Axes11,'on');
                        plot(app.CAL_Axes11,data{1},'k','LineWidth',1);
                        yline(app.CAL_Axes11,0);
                    hold(app.CAL_Axes11,'off');
                    xlabel(app.CAL_Axes11,'Pixel','FontWeight','bold');
                    ylabel(app.CAL_Axes11,'Counts/Int.','FontWeight','bold');
                    linkaxes([app.CAL_Axes11,app.CAL_Axes21],'x');
                    axis(app.CAL_Axes11,'tight');
                else
                    app.CAL_Axes11.Visible = 'on';
                    app.CAL_Axes21.Visible = 'off';
                    app.CAL_Axes12.Visible = 'on';
                    app.CAL_Axes22.Visible = 'off';
                    
                    hold([app.CAL_Axes11,app.CAL_Axes12],'on');
                        plot(app.CAL_Axes11,data{1},'k','LineWidth',1,'DisplayName','Air');
                        plot(app.CAL_Axes12,data{2},'k','LineWidth',1,'DisplayName','Air');
                        yline(app.CAL_Axes11,0);
                        yline(app.CAL_Axes12,0);
                    hold([app.CAL_Axes11,app.CAL_Axes12],'off');
                    
                    xlabel([app.CAL_Axes11,app.CAL_Axes12],'Pixel','FontWeight','bold');
                    ylabel([app.CAL_Axes11,app.CAL_Axes12],'Counts/Int.','FontWeight','bold');
                    linkaxes([app.CAL_Axes11,app.CAL_Axes21],'x');
                    linkaxes([app.CAL_Axes12,app.CAL_Axes22],'x');
                    axis(app.CAL_Axes11,'tight');
                    axis(app.CAL_Axes12,'tight');
                end
            catch ME
                app.CAL_Sp_Lamp1.Color  = [1 0 0];
                app.CAL_data.Air        = [];
                app.CAL_Axes11.Visible = 'off';
                app.CAL_Axes21.Visible = 'off';
                app.CAL_Axes12.Visible = 'off';
                app.CAL_Axes22.Visible = 'off';
            end
%             app.CAL_Sp_Lamp1.Color = 0.65*[1 1 1];
        end

        % Button pushed function: CAL_Sp_LoadSolventSpectrumButton
        function CAL_Sp_LoadSolventSpectrumButtonPushed(app, event)
            try 
                data = LoadSpectrum(app,'Load Solvent/Filter Spectrum');
                figure(app.DataAnalysisGUI_UIFigure);
                
                app.CAL_Sp_Lamp2.Color  = [0 1 0];
                app.CAL_data.Solvent    = data;
                
                if length(data)==1
                    hold(app.CAL_Axes11,'on');
                        plot(app.CAL_Axes11,data{1},'r','LineWidth',1);
                    hold(app.CAL_Axes11,'off');
                    xlabel([app.CAL_Axes11,app.CAL_Axes12],'Pixel','FontWeight','bold');
                    ylabel([app.CAL_Axes11,app.CAL_Axes12],'Counts','FontWeight','bold');
                    
                    app.CAL_Axes11.Visible = 'on';
                    app.CAL_Axes21.Visible = 'on';
                    app.CAL_Axes12.Visible = 'off';
                    app.CAL_Axes22.Visible = 'off';
                else
                    app.CAL_Axes11.Visible = 'on';
                    app.CAL_Axes21.Visible = 'on';
                    app.CAL_Axes12.Visible = 'on';
                    app.CAL_Axes22.Visible = 'on';
                    hold([app.CAL_Axes11,app.CAL_Axes12],'on');
                        plot(app.CAL_Axes11,data{1},'r','LineWidth',1);
                        plot(app.CAL_Axes12,data{2},'r','LineWidth',1);
                    hold([app.CAL_Axes11,app.CAL_Axes12],'off');
                   
                    xlabel([app.CAL_Axes11,app.CAL_Axes12],'Pixel','FontWeight','bold');
                    ylabel([app.CAL_Axes11,app.CAL_Axes12],'Counts','FontWeight','bold');
                end
            catch ME
                app.CAL_Sp_Lamp2.Color  = [1 0 0];
                app.CAL_data.Solvent    = [];
                app.CAL_Axes11.Visible = 'off';
                app.CAL_Axes21.Visible = 'off';
                app.CAL_Axes12.Visible = 'off';
                app.CAL_Axes22.Visible = 'off';
                return
            end

            cla(app.CAL_Axes21); cla(app.CAL_Axes22);
            if ~isempty(app.CAL_data.Air) && ~isempty(app.CAL_data.Solvent)
                app.CAL_data.AbsSolvent = cell(4,1);
                for i=1:length(data)
                    app.CAL_data.AbsSolvent{i} = real(log10(app.CAL_data.Air{i}./app.CAL_data.Solvent{i}));
                end
                if length(data)==1
                    hold(app.CAL_Axes21,'on');
                        plot(app.CAL_Axes21,app.CAL_data.AbsSolvent{1},'r','LineWidth',1.5,'DisplayName','Solvent/Filter');
                    hold(app.CAL_Axes21,'off');
                    yline(app.CAL_Axes21,0);
                    xlabel(app.CAL_Axes21,'Pixel','FontWeight','bold');
                    ylabel(app.CAL_Axes21,'Absorbance (OD)','FontWeight','bold');
                else
                    hold([app.CAL_Axes21,app.CAL_Axes22],'on');
                        plot(app.CAL_Axes21,app.CAL_data.AbsSolvent{1},'r','LineWidth',1.5,'DisplayName','Solvent/Filter');
                        plot(app.CAL_Axes22,app.CAL_data.AbsSolvent{2},'r','LineWidth',1.5,'DisplayName','Solvent/Filter');
                    hold([app.CAL_Axes21,app.CAL_Axes22],'off');
                    yline(app.CAL_Axes21,0);
                    yline(app.CAL_Axes22,0);
                    xlabel([app.CAL_Axes21,app.CAL_Axes22],'Pixel','FontWeight','bold');
                    ylabel([app.CAL_Axes21,app.CAL_Axes22],'Absorbance (OD)','FontWeight','bold');
                end
            end
        end

        % Button pushed function: PP_PlotNoiseButton
        function PP_PlotNoiseButtonPushed(app, event)
            fh      = figure();
            newaxes = axes('Parent',fh);
            plot3D(app,app.PP_Data.noise,newaxes,'Noise',app.PP_ProbeDetSwitch.Value);
            % Make the plot format constant
            fh.Units='pixels';
            fh.Position(2)=fh.Position(2).*0.7;
            fh.Position(3)=590; % Width in pixels
            fh.Position(4)=510; % Height in pixels
            fh.Color = [1 1 1];
            newaxes.FontSize = 12;
            newaxes.Units = 'pixels';
            newaxes.Position = [75 75 420 385];
            % Make uniform, consistent format
            newaxes.LineWidth = 1;
            newaxes.TickLength = [0.015 0.035];
            
            % Make the axes resizable
            newaxes.Units = 'normalized';
        end

        % Button pushed function: CAL_Sp_LoadFTIRCalibrationButton
        function CAL_Sp_LoadFTIRCalibrationButtonPushed(app, event)
             [fp,~,~] = fileparts(app.scriptPath);
             rd = [fp filesep 'Calibration'];
             currdir = pwd; 
             
             if exist(rd, 'dir')
                cd(rd);
             end
            
             [datafile, datapath, idx] = uigetfile({'*.csv','Cal. Spectrum'},'Load Calibration Spectrum');
             figure(app.DataAnalysisGUI_UIFigure);
             cd(currdir);
             
             if datafile == 0
                 return
             end
             
             data = readmatrix([datapath filesep datafile]);

             if isempty(data)
                 errordlg('Loaded spectrum is empty/does not contain valid data!','Empty/Invalid Spectrum');
                 app.CAL_data.CalX = [];
                 app.CAL_data.CalY = [];
                 app.CAL_Sp_Lamp3.Color  = [1 0 0];
                 return
             end
             
             app.CAL_data.CalX = data(:,1);
             app.CAL_data.CalY = data(:,2);
             app.CAL_Sp_Lamp3.Color  = [0 1 0];
        end

        % Button pushed function: CAL_Sp_AutoCalButton
        function CAL_Sp_AutoCalButtonPushed(app, event)
            [app.CAL_data.cm,app.CAL_data.lam] = CalibrateWLAxis(app.CAL_data);
            switch app.CAL_data.CalType
                case 1
                    app.CAL_AxesC1.Visible='on';
                    app.CAL_AxesC2.Visible='on';
                    
                    plot(app.CAL_AxesC1,app.CAL_data.lam(:,1),'b','LineWidth',2);
                    plot(app.CAL_AxesC2,app.CAL_data.lam(:,2),'b','LineWidth',2);
                    
                    xlabel(app.CAL_AxesC1,'Pixel','FontWeight','bold');
                    xlabel(app.CAL_AxesC2,'Pixel','FontWeight','bold');
                    ylabel(app.CAL_AxesC1,'Wavelength (nm)','FontWeight','bold');
                    ylabel(app.CAL_AxesC2,'Wavelength (nm)','FontWeight','bold');
                    axis(app.CAL_AxesC1,'tight');
                    axis(app.CAL_AxesC2,'tight');
                    
                    xline(app.CAL_AxesC1,length(app.CAL_data.lam(:,1))/2);
                    yline(app.CAL_AxesC1,app.CAL_data.lam(length(app.CAL_data.lam(:,1))/2,1));
                    xline(app.CAL_AxesC2,length(app.CAL_data.lam(:,2))/2);
                    yline(app.CAL_AxesC2,app.CAL_data.lam(length(app.CAL_data.lam(:,2))/2,2));
                    
                    FitProbeSpectrum(app.CAL_data);
                case {2,3}
                    app.CAL_AxesC1.Visible='on';
                    app.CAL_AxesC2.Visible='off';
                    
                    plot(app.CAL_AxesC1,app.CAL_data.lam(:,1),'b','LineWidth',2);
                    
                    xlabel(app.CAL_AxesC1,'Pixel','FontWeight','bold');
                    ylabel(app.CAL_AxesC1,'Wavelength (nm)','FontWeight','bold');
                    axis(app.CAL_AxesC1,'tight');
                    
                    xline(app.CAL_AxesC1,length(app.CAL_data.lam(:,1))/2);
                    yline(app.CAL_AxesC1,app.CAL_data.lam(length(app.CAL_data.lam)/2,1));
            end
        end

        % Button pushed function: CAL_Sp_SaveButton
        function CAL_Sp_SaveButtonPushed(app, event)
            %% Write Output Files
            rd = app.rootdir;
            if exist(rd,'dir') == 0
                rd = pwd;
            end
            
            outpath = uigetdir(rd,'Save Calibrated Probe File to...');
            figure(app.DataAnalysisGUI_UIFigure);
            
            if outpath == 0
                return
            end
            
            lam = app.CAL_data.lam;
            cm  = app.CAL_data.cm;
            switch app.CAL_data.CalType
                case {1,4}
                    writematrix(cm(:),[outpath filesep 'CalibratedProbe.csv']);
                case {2,3}
                    save([outpath filesep 'pix2lam.mat'],'lam');
            end
        end

        % Button pushed function: CAL_Sh_ResetButton, CAL_Sp_ResetButton
        function CAL_Sp_ResetButtonPushed(app, event)
            app.CAL_data = [];
            cla(app.CAL_Axes11);
            cla(app.CAL_Axes12);
            cla(app.CAL_Axes21);
            cla(app.CAL_Axes22);
            cla(app.CAL_AxesC1);
            cla(app.CAL_AxesC2);
            app.CAL_Axes11.Visible = 'off';
            app.CAL_Axes21.Visible = 'off';
            app.CAL_Axes12.Visible = 'off';
            app.CAL_Axes22.Visible = 'off';
            app.CAL_AxesC1.Visible = 'off';
            app.CAL_AxesC2.Visible = 'off';
            app.CAL_Sp_Lamp1.Color  = 0.65*[1 1 1];
            app.CAL_Sp_Lamp2.Color  = 0.65*[1 1 1];
            app.CAL_Sp_Lamp3.Color  = 0.65*[1 1 1];
            app.CAL_Sp_Lamp4.Color  = 0.65*[1 1 1];
            app.CAL_Sh_Lamp1.Color  = 0.65*[1 1 1];
            app.CAL_Sh_Lamp2.Color  = 0.65*[1 1 1];
            app.CAL_Sh_Lamp3.Color  = 0.65*[1 1 1];
            app.CAL_Sh_Lamp4.Color  = 0.65*[1 1 1];
        end

        % Button pushed function: CAL_Sh_LoadPumpSpectrumButton
        function CAL_Sh_LoadPumpSpectrumButtonPushed(app, event)
            try 
                cla(app.CAL_Axes11);
                cla(app.CAL_Axes12);
                cla(app.CAL_Axes21);
                cla(app.CAL_Axes22);
                
                data = LoadSpectrum(app,'Load Probe Spectrum (air)');
                figure(app.DataAnalysisGUI_UIFigure);
                
                app.CAL_Sh_Lamp1.Color  = [0 1 0];
                app.CAL_data.Air        = data;
                
                if length(data)==1
                    app.CAL_Axes11.Visible = 'on';
                    app.CAL_Axes21.Visible = 'off';
                    app.CAL_Axes12.Visible = 'off';
                    app.CAL_Axes22.Visible = 'off';
                    hold(app.CAL_Axes11,'on');
                        plot(app.CAL_Axes11,data{1},'k','LineWidth',1);
                        yline(app.CAL_Axes11,0);
                    hold(app.CAL_Axes11,'off');
                    xlabel(app.CAL_Axes11,'Pixel','FontWeight','bold');
                    ylabel(app.CAL_Axes11,'Counts/Int.','FontWeight','bold');
                    linkaxes([app.CAL_Axes11,app.CAL_Axes21],'x');
                    axis(app.CAL_Axes11,'tight');
                else
                    app.CAL_Axes11.Visible = 'on';
                    app.CAL_Axes21.Visible = 'off';
                    app.CAL_Axes12.Visible = 'on';
                    app.CAL_Axes22.Visible = 'off';
                    
                    hold([app.CAL_Axes11,app.CAL_Axes12],'on');
                        plot(app.CAL_Axes11,data{1},'k','LineWidth',1,'DisplayName','Air');
                        plot(app.CAL_Axes12,data{2},'k','LineWidth',1,'DisplayName','Air');
                        yline(app.CAL_Axes11,0);
                        yline(app.CAL_Axes12,0);
                    hold([app.CAL_Axes11,app.CAL_Axes12],'off');
                    
                    xlabel([app.CAL_Axes11,app.CAL_Axes12],'Pixel','FontWeight','bold');
                    ylabel([app.CAL_Axes11,app.CAL_Axes12],'Counts/Int.','FontWeight','bold');
                    linkaxes([app.CAL_Axes11,app.CAL_Axes21],'x');
                    linkaxes([app.CAL_Axes12,app.CAL_Axes22],'x');
                    axis(app.CAL_Axes11,'tight');
                    axis(app.CAL_Axes12,'tight');
                end
            catch ME
                app.CAL_Sh_Lamp1.Color  = [1 0 0];
                app.CAL_data.Air        = [];
                app.CAL_Axes11.Visible = 'off';
                app.CAL_Axes21.Visible = 'off';
                app.CAL_Axes12.Visible = 'off';
                app.CAL_Axes22.Visible = 'off';
            end
%             app.CAL_Sp_Lamp1.Color = 0.65*[1 1 1];
        end

        % Button pushed function: CAL_Sh_LoadSolventSpectrumButton
        function CAL_Sh_LoadSolventSpectrumButtonPushed(app, event)
            try 
                data = LoadSpectrum(app,'Load Solvent/Filter Spectrum');
                figure(app.DataAnalysisGUI_UIFigure);
                
                app.CAL_Sh_Lamp2.Color  = [0 1 0];
                app.CAL_data.Solvent    = data;
                
                if length(data)==1
                    hold(app.CAL_Axes11,'on');
                        plot(app.CAL_Axes11,data{1},'r','LineWidth',1);
                    hold(app.CAL_Axes11,'off');
                    xlabel([app.CAL_Axes11,app.CAL_Axes12],'Pixel','FontWeight','bold');
                    ylabel([app.CAL_Axes11,app.CAL_Axes12],'Counts','FontWeight','bold');
                    
                    app.CAL_Axes11.Visible = 'on';
                    app.CAL_Axes21.Visible = 'on';
                    app.CAL_Axes12.Visible = 'off';
                    app.CAL_Axes22.Visible = 'off';
                else
                    app.CAL_Axes11.Visible = 'on';
                    app.CAL_Axes21.Visible = 'on';
                    app.CAL_Axes12.Visible = 'on';
                    app.CAL_Axes22.Visible = 'on';
                    hold([app.CAL_Axes11,app.CAL_Axes12],'on');
                        plot(app.CAL_Axes11,data{1},'r','LineWidth',1);
                        plot(app.CAL_Axes12,data{2},'r','LineWidth',1);
                    hold([app.CAL_Axes11,app.CAL_Axes12],'off');
                   
                    xlabel([app.CAL_Axes11,app.CAL_Axes12],'Pixel','FontWeight','bold');
                    ylabel([app.CAL_Axes11,app.CAL_Axes12],'Counts','FontWeight','bold');
                end
            catch ME
                app.CAL_Sh_Lamp2.Color  = [1 0 0];
                app.CAL_data.Solvent    = [];
                app.CAL_Axes11.Visible = 'off';
                app.CAL_Axes21.Visible = 'off';
                app.CAL_Axes12.Visible = 'off';
                app.CAL_Axes22.Visible = 'off';
                return
            end

            cla(app.CAL_Axes21); cla(app.CAL_Axes22);
            if ~isempty(app.CAL_data.Air) && ~isempty(app.CAL_data.Solvent)
                app.CAL_data.AbsSolvent = cell(4,1);
                for i=1:length(data)
                    app.CAL_data.AbsSolvent{i} = real(log10(app.CAL_data.Air{i}./app.CAL_data.Solvent{i}));
                end
                if length(data)==1
                    warndlg('Wrong datatype!');
                    return
                else
                    hold([app.CAL_Axes21,app.CAL_Axes22],'on');
                        plot(app.CAL_Axes21,app.CAL_data.AbsSolvent{1},'r','LineWidth',1.5,'DisplayName','Solvent/Filter');
                        plot(app.CAL_Axes22,app.CAL_data.AbsSolvent{2},'r','LineWidth',1.5,'DisplayName','Solvent/Filter');
                    hold([app.CAL_Axes21,app.CAL_Axes22],'off');
                    yline(app.CAL_Axes21,0);
                    yline(app.CAL_Axes22,0);
                    xlabel([app.CAL_Axes21,app.CAL_Axes22],'Pixel','FontWeight','bold');
                    ylabel([app.CAL_Axes21,app.CAL_Axes22],'Absorbance (OD)','FontWeight','bold');
                end
            end
        end

        % Button pushed function: CAL_Sh_LoadFTIRCalibrationButton
        function CAL_Sh_LoadFTIRCalibrationButtonPushed(app, event)
             [fp,~,~] = fileparts(app.scriptPath);
             rd = [fp filesep 'Calibration'];
             currdir = pwd; 
             cd(rd);
            
             [datafile, datapath, idx] = uigetfile({'*.csv','UoS TRIR';'*.csv','UniGE TA';'*.csv','UniGE nsTA'},'Load Calibration Spectrum');
             figure(app.DataAnalysisGUI_UIFigure);
             cd(currdir);
             
             if datafile == 0
                 return
             end
             
             switch idx
                 case 1
                     data = readmatrix([datapath filesep datafile]);
                 case {2,3}
                     data = readmatrix([datapath filesep datafile]);
             end

             if isempty(data)
                 errordlg('Loaded spectrum is empty/does not contain valid data!','Empty/Invalid Spectrum');
                 app.CAL_data.CalX = [];
                 app.CAL_data.CalY = [];
                 app.CAL_Sh_Lamp3.Color  = [1 0 0];
                 return
             end
             
             app.CAL_data.CalX = data(:,1);
             app.CAL_data.CalY = data(:,2);
             app.CAL_Sh_Lamp3.Color  = [0 1 0];
        end

        % Button pushed function: CAL_Sh_LoadMaskScansButton
        function CAL_Sh_LoadMaskScansButtonPushed(app, event)
            try 
                dataM = LoadSpectrum(app,'Load Multiple Mask Spectrum');
                figure(app.DataAnalysisGUI_UIFigure);
                
                dataS = LoadSpectrum(app,'Load Single Mask Spectrum');
                figure(app.DataAnalysisGUI_UIFigure);
                
                app.CAL_Sh_Lamp4.Color  = [0 1 0];
                app.CAL_data.dataS    = dataS;
                app.CAL_data.dataM    = dataM;
                
                if length(dataM)==1 || length(dataS)==1
                    warndlg('Wrong datatype!');
                    return
                else
                    app.CAL_Axes11.Visible = 'on';
                    app.CAL_Axes21.Visible = 'on';
                    app.CAL_Axes12.Visible = 'on';
                    app.CAL_Axes22.Visible = 'on';
                    hold([app.CAL_Axes11,app.CAL_Axes12],'on');
                        plot(app.CAL_Axes11,dataM{1},'Color',[0 0.5 0],'LineWidth',1);
                        plot(app.CAL_Axes12,dataM{2},'Color',[0 0.5 0],'LineWidth',1);
                        plot(app.CAL_Axes11,dataS{1},'b','LineWidth',2);
                        plot(app.CAL_Axes12,dataS{2},'b','LineWidth',2);
                    hold([app.CAL_Axes11,app.CAL_Axes12],'off');
                   
                    xlabel([app.CAL_Axes11,app.CAL_Axes12],'Pixel','FontWeight','bold');
                    ylabel([app.CAL_Axes11,app.CAL_Axes12],'Counts','FontWeight','bold');
                end
            catch ME
                app.CAL_Sh_Lamp4.Color  = [1 0 0];
                app.CAL_data.MaskS    = [];
                app.CAL_data.MaskM    = [];
                app.CAL_Axes11.Visible = 'off';
                app.CAL_Axes21.Visible = 'off';
                app.CAL_Axes12.Visible = 'off';
                app.CAL_Axes22.Visible = 'off';
                return
            end
        end

        % Button pushed function: CAL_Sh_AutoCalButton
        function CAL_Sh_AutoCalButtonPushed(app, event)
            [app.CAL_data.cm,app.CAL_data.lam] = CalibrateWLAxis(app.CAL_data);
            switch app.CAL_data.CalType
                case 1
                    app.CAL_AxesC1.Visible='on';
                    app.CAL_AxesC2.Visible='on';
                    
                    plot(app.CAL_AxesC1,app.CAL_data.lam(:,1),'b','LineWidth',2);
                    plot(app.CAL_AxesC2,app.CAL_data.lam(:,2),'b','LineWidth',2);
                    
                    xlabel(app.CAL_AxesC1,'Pixel','FontWeight','bold');
                    xlabel(app.CAL_AxesC2,'Pixel','FontWeight','bold');
                    ylabel(app.CAL_AxesC1,'Wavelength (nm)','FontWeight','bold');
                    ylabel(app.CAL_AxesC2,'Wavelength (nm)','FontWeight','bold');
                    axis(app.CAL_AxesC1,'tight');
                    axis(app.CAL_AxesC2,'tight');
                    
                    xline(app.CAL_AxesC1,length(app.CAL_data.lam(:,1))/2);
                    yline(app.CAL_AxesC1,app.CAL_data.lam(length(app.CAL_data.lam(:,1))/2,1));
                    xline(app.CAL_AxesC2,length(app.CAL_data.lam(:,2))/2);
                    yline(app.CAL_AxesC2,app.CAL_data.lam(length(app.CAL_data.lam(:,2))/2,2));
                case {2,3}
                    warndlg('Wrong datatype!');
                    return
            end
            
        fh = figure(3);
        fh.Color = 'w';
        fh.Position(3:4) = [600 350];
        clf(fh);
        ax = axes('parent',fh);
        
        % Plot only for detector 1 (since the pump is measured on Det1)
        hold(ax,'on');
            plot(ax,app.CAL_data.lam(:,1),app.CAL_data.dataM{1},'Color',[0 0.5 0],'LineWidth',1);    
            plot(ax,app.CAL_data.lam(:,1),app.CAL_data.dataS{1},'b','LineWidth',1);
        hold(ax,'off');
        xlabel(ax,'Wavelength (nm)','FontWeight','bold');
        ylabel(ax,'Intensity (counts)','FontWeight','bold');
        title(ax,'Calibrated Mask Scans','FontWeight','bold');
        ax.FontSize = 16;  
        axis(ax,'tight');
        ax.XDir='reverse';
        ax.Box='on';

        FitProbeSpectrum(app.CAL_data);
        end

        % Button pushed function: CAL_Sh_SaveButton
        function CAL_Sh_SaveButtonPushed(app, event)
            multi   = [app.CAL_data.lam(:,1) app.CAL_data.dataM{1}'];
            single  = [app.CAL_data.lam(:,1) app.CAL_data.dataS{1}'];
            
            rootdir = app.rootdir;
            if isempty(rootdir)
              rootdir = pwd;
            end
            currdir = pwd; 
            cd(rootdir);
            
            exp_path = uigetdir(rootdir,'Export Mask Scans to Folder...');
            
            writematrix(multi,[exp_path filesep 'MultipleMask.txt'],'Delimiter','\t');
            writematrix(single,[exp_path filesep 'SingleMask.txt'],'Delimiter','\t');
            
        end

        % Button pushed function: PP_FitChirp_button
        function PP_FitChirp_buttonButtonPushed(app, event)
            app.PP_Data.chirpSt = FitChirpCorr(app.PP_Data,app.rootdir,app.PP_ProbeDetSwitch.Value);
            
            if isempty(app.PP_Data.chirpSt)
                return
            end

            app.PP_Data = ApplyChirpCorr(app.PP_Data,app.PP_Data.chirpSt,app.rootdir,'After fit',app.PP_ProbeDetSwitch.Value);
            UpdateInfo_PP(app,'Write');
            UpdatePlot_PP(app,app.PumpProbeAxes);
        end

        % Button pushed function: PP_LoadChirpCorr_button
        function PP_LoadChirpCorr_buttonButtonPushed(app, event)
            app.PP_Data = ApplyChirpCorr(app.PP_Data,[],app.rootdir,'Load fitted chirp',app.PP_ProbeDetSwitch.Value);
            figure(app.DataAnalysisGUI_UIFigure);
            
            UpdateInfo_PP(app,'Write');
            UpdatePlot_PP(app,app.PumpProbeAxes);
        end

        % Button pushed function: PP_ShiftT0_button
        function PP_ShiftT0_buttonButtonPushed(app, event)
            prompt = {['Shift time axis - location of new t0 (in ' app.PP_Data.timescale '):']};
            dlgtitle = 'Shift t0';
            dims = [1 50];
            definput = {'0.1'};
            newt0str = inputdlg(prompt,dlgtitle,dims,definput);
            
            app.PP_Data.delays = app.PP_Data.delays - str2double(newt0str);
            
            UpdateInfo_PP(app,'Write');
            UpdatePlot_PP(app,app.PumpProbeAxes);
        end

        % Button pushed function: I2D_SynclimitsButton
        function I2D_SynclimitsButtonPushed(app, event)
            XL = xlim(app.TwoDAxes);
            YL = ylim(app.TwoDAxes);
            m  = app.I2D_t2delays.Value;
            k  = app.I2D_ProbeDetSwitch.Value;
            
            switch app.I2D_PumpAxisOrientation.Value
                case 'Horizontal'
                    PuL = XL;
                    PrL = YL;
                case 'Vertical'
                    PuL = YL;
                    PrL = XL;
            end
            
            % Read the axes
            PumpAxis  = app.I2D_Data.PumpAxis{m,k};
            ProbeAxis = app.I2D_Data.ProbeAxis{k};
            
            
            % Apply limits, ensuring the pump and probe limits are within measurement range
            app.I2D_MinWL_pump.Value    = max([min(PuL),min(PumpAxis)]);
            app.I2D_MaxWL_pump.Value    = min([max(PuL),max(PumpAxis)]);
            app.I2D_MinWL_probe.Value   = max([min(PrL),min(ProbeAxis)]);
            app.I2D_MaxWL_probe.Value   = min([max(PrL),max(ProbeAxis)]);
            
            UpdateInfo_I2D(app,'Read');
            UpdatePlot_I2D(app,app.TwoDAxes,'NoChange')
            UpdateInfo_I2D(app,'Update');
        end

        % Button pushed function: PP_RestoredefaultlimitsButton
        function PP_RestoredefaultlimitsButtonPushed(app, event)
            k   = app.PP_ProbeDetSwitch.Value;
            
            delays      = app.PP_Data.delays;
            cmprobe     = app.PP_Data.cmprobe{k};
            rawsignal   = app.PP_Data.rawsignal{k};
            
            %% Read the plot ranges
            mintime     = min(delays);
            maxtime     = max(delays);
            minabs      = min(rawsignal(:));
            maxabs      = max(rawsignal(:));
            minwl       = min(cmprobe);
            maxwl       = max(cmprobe);
            Ncontours   = 40; % 40 contours by default is OK
            
            app.PP_Data.plotranges{k}  = [mintime maxtime minwl maxwl minabs maxabs Ncontours];
            app.PP_LogTime_tick.Value  = true;
            
            UpdateInfo_PP(app,'Write');
            
            app.PP_PlotScaleNumber.Value = 100;
            app.PP_PlotScaleSlider.Value = 100;
            
            UpdatePlot_PP(app,app.PumpProbeAxes);
        end

        % Value changed function: PP_ProbeDetSwitch
        function PP_ProbeDetSwitchValueChanged(app, event)
            UpdateInfo_PP(app,'Write')
            UpdateInfo_PP(app,'Read')
            UpdatePlot_PP(app,app.PumpProbeAxes)
        end

        % Selection change function: MainTabs
        function MainTabsSelectionChanged(app, event)
            selectedTab = app.MainTabs.SelectedTab;
            switch selectedTab.Title
                case 'Pump-Probe'
                    UpdateDataFolderList_PP(app);
                case 'Interferometric 2D-IR'
                    UpdateDataFolderList_I2D(app);  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataAnalysisGUI_UIFigure = uifigure('Visible', 'off');
            app.DataAnalysisGUI_UIFigure.Name = 'Data Analysis GUI';
            app.MainmenuMenu = uimenu(app.DataAnalysisGUI_UIFigure);
            app.MainmenuMenu.Text = '  Main menu  ';
            app.OpensettingsfileMenu = uimenu(app.MainmenuMenu);
            app.OpensettingsfileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpensettingsfileMenuSelected, true);
            app.OpensettingsfileMenu.Accelerator = 's';
            app.OpensettingsfileMenu.Text = 'Open settings file...';
            app.GoonefolderupMenu = uimenu(app.MainmenuMenu);
            app.GoonefolderupMenu.MenuSelectedFcn = createCallbackFcn(app, @UpDirectoryButtonPushed, true);
            app.GoonefolderupMenu.Accelerator = 'w';
            app.GoonefolderupMenu.Text = 'Go one folder up';
            app.EntercurrentfolderMenu = uimenu(app.MainmenuMenu);
            app.EntercurrentfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @EntercurrentfolderMenuSelected, true);
            app.EntercurrentfolderMenu.Accelerator = 'e';
            app.EntercurrentfolderMenu.Text = 'Enter current folder';
            app.ReloaddatasetMenu = uimenu(app.MainmenuMenu);
            app.ReloaddatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @ReloaddatasetMenuSelected, true);
            app.ReloaddatasetMenu.Separator = 'on';
            app.ReloaddatasetMenu.Accelerator = 'r';
            app.ReloaddatasetMenu.Text = '(Re)load dataset';
            app.LoadfitfromfileMenu = uimenu(app.MainmenuMenu);
            app.LoadfitfromfileMenu.MenuSelectedFcn = createCallbackFcn(app, @I2D_LoadFitButtonPushed, true);
            app.LoadfitfromfileMenu.Enable = 'off';
            app.LoadfitfromfileMenu.Accelerator = 'f';
            app.LoadfitfromfileMenu.Text = 'Load fit from file...';
            app.ShowbasichelpMenu = uimenu(app.MainmenuMenu);
            app.ShowbasichelpMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowbasichelpMenuSelected, true);
            app.ShowbasichelpMenu.Separator = 'on';
            app.ShowbasichelpMenu.Accelerator = 'H';
            app.ShowbasichelpMenu.Text = 'Show basic help';
            app.ExitprogramMenu = uimenu(app.MainmenuMenu);
            app.ExitprogramMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitprogramMenuSelected, true);
            app.ExitprogramMenu.Accelerator = 'Q';
            app.ExitprogramMenu.Text = 'Exit program';
            app.SteadyStateSpectraMenu = uimenu(app.DataAnalysisGUI_UIFigure);
            app.SteadyStateSpectraMenu.Text = '  Steady-State Spectra  ';
            app.LoadSSdataMenu = uimenu(app.SteadyStateSpectraMenu);
            app.LoadSSdataMenu.MenuSelectedFcn = createCallbackFcn(app, @PP_LoadFTIRdataButtonPushed, true);
            app.LoadSSdataMenu.Text = 'Load SS data...';
            app.ShowSSdataMenu = uimenu(app.SteadyStateSpectraMenu);
            app.ShowSSdataMenu.MenuSelectedFcn = createCallbackFcn(app, @PP_ShowFTIRdataButtonPushed, true);
            app.ShowSSdataMenu.Enable = 'off';
            app.ShowSSdataMenu.Text = 'Show SS data...';
            app.ClearSSdataMenu = uimenu(app.SteadyStateSpectraMenu);
            app.ClearSSdataMenu.MenuSelectedFcn = createCallbackFcn(app, @PP_ClearFTIRdataButtonPushed, true);
            app.ClearSSdataMenu.Enable = 'off';
            app.ClearSSdataMenu.Text = 'Clear SS data';
            app.ExportMenu = uimenu(app.DataAnalysisGUI_UIFigure);
            app.ExportMenu.Text = '  Export...  ';
            app.ExportPPdataaspdatMenu = uimenu(app.ExportMenu);
            app.ExportPPdataaspdatMenu.MenuSelectedFcn = createCallbackFcn(app, @SavePPData_MenuSelected, true);
            app.ExportPPdataaspdatMenu.Accelerator = '1';
            app.ExportPPdataaspdatMenu.Text = 'Export PP data as .pdat';
            app.Export2DIRdataMenu = uimenu(app.ExportMenu);
            app.Export2DIRdataMenu.MenuSelectedFcn = createCallbackFcn(app, @Export2DIRdataMenuSelected, true);
            app.Export2DIRdataMenu.Accelerator = '2';
            app.Export2DIRdataMenu.Text = 'Export 2D-IR data...';
            app.RootfolderPanel = uipanel(app.DataAnalysisGUI_UIFigure);
            app.RootfolderPanel.Tooltip = {'Sets the current root folder and asociated operations'};
            app.RootfolderPanel.Title = 'Root folder';
            app.Refresh = uibutton(app.RootfolderPanel, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.Refresh.Icon = 'Refresh_image.png';
            app.Refresh.Tooltip = {''};
            app.Refresh.Text = '';
            app.UpDirectory = uibutton(app.RootfolderPanel, 'push');
            app.UpDirectory.ButtonPushedFcn = createCallbackFcn(app, @UpDirectoryButtonPushed, true);
            app.UpDirectory.Icon = 'UpDirectory_image.png';
            app.UpDirectory.Tooltip = {''};
            app.UpDirectory.Text = '';
            app.BrowserootdirButton = uibutton(app.RootfolderPanel, 'push');
            app.BrowserootdirButton.ButtonPushedFcn = createCallbackFcn(app, @BrowserootdirButtonPushed, true);
            app.BrowserootdirButton.Tooltip = {''};
            app.BrowserootdirButton.Text = 'Browse root dir.';
            app.GototodayButton = uibutton(app.RootfolderPanel, 'push');
            app.GototodayButton.ButtonPushedFcn = createCallbackFcn(app, @GototodayButtonPushed, true);
            app.GototodayButton.Tooltip = {'Goes to today''s data folder'};
            app.GototodayButton.Text = 'Go to today...';
            app.CurrentdirectoryEditFieldLabel = uilabel(app.RootfolderPanel);
            app.CurrentdirectoryEditFieldLabel.Text = 'Current directory: ';
            app.RootDirEditField = uieditfield(app.RootfolderPanel, 'text');
            app.RootDirEditField.ValueChangedFcn = createCallbackFcn(app, @RootDirEditFieldValueChanged, true);
            app.RootDirEditField.Tooltip = {''};
            app.RootDirEditField.Value = 'No root folder selected :(';
            app.MainTabs = uitabgroup(app.DataAnalysisGUI_UIFigure);
            app.MainTabs.SelectionChangedFcn = createCallbackFcn(app, @MainTabsSelectionChanged, true);
            app.PumpProbeTab = uitab(app.MainTabs);
            app.PumpProbeTab.Tooltip = {''};
            app.PumpProbeTab.Title = 'Pump-Probe';
            app.PumpProbeAxes = uiaxes(app.PumpProbeTab);
            xlabel(app.PumpProbeAxes, 'X')
            ylabel(app.PumpProbeAxes, 'Y')
            app.PumpProbeAxes.Visible = 'off';
            app.DataformatDropDownLabel = uilabel(app.PumpProbeTab);
            app.DataformatDropDownLabel.Text = 'Data format';
            app.PP_DataFormat = uidropdown(app.PumpProbeTab);
            app.PP_DataFormat.Items = {'Processed DAT files', 'TRIR (Sheffield)', 'Helios TA', 'UniGE TA', 'UniGE nsTA', 'Pump-Probe [Lab 1 & Lab 4]', 'Pump-Probe [Lab 2]', 'Pump-Probe [TRUVIS]', 'TRES (*.dat)', 'Lab 5 (*.dat)'};
            app.PP_DataFormat.ValueChangedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.PP_DataFormat.Tooltip = {''};
            app.PP_DataFormat.Value = 'TRIR (Sheffield)';
            app.PP_ReloadButton = uibutton(app.PumpProbeTab, 'push');
            app.PP_ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @PP_DataFolderListValueChanged, true);
            app.PP_ReloadButton.Text = 'Reload';
            app.PP_SelectdatasetPanel = uipanel(app.PumpProbeTab);
            app.PP_SelectdatasetPanel.Title = 'Select dataset';
            app.PP_DataFolderList = uilistbox(app.PP_SelectdatasetPanel);
            app.PP_DataFolderList.Items = {'Available data folders will appear here ', 'once a root folder is selected'};
            app.PP_DataFolderList.ValueChangedFcn = createCallbackFcn(app, @PP_DataFolderListValueChanged, true);
            app.PP_DataFolderList.Value = {};
            app.PP_PreprocessingPanel = uipanel(app.PumpProbeTab);
            app.PP_PreprocessingPanel.Title = 'Pre-processing';
            app.PP_PreprocessingPanel.Visible = 'off';
            app.PP_FitChirp_button = uibutton(app.PP_PreprocessingPanel, 'push');
            app.PP_FitChirp_button.ButtonPushedFcn = createCallbackFcn(app, @PP_FitChirp_buttonButtonPushed, true);
            app.PP_FitChirp_button.Text = 'Fit Chirp';
            app.PP_LoadChirpCorr_button = uibutton(app.PP_PreprocessingPanel, 'push');
            app.PP_LoadChirpCorr_button.ButtonPushedFcn = createCallbackFcn(app, @PP_LoadChirpCorr_buttonButtonPushed, true);
            app.PP_LoadChirpCorr_button.Text = 'Load Chirp Corr.';
            app.PP_SvtBkgSubtract_button = uibutton(app.PP_PreprocessingPanel, 'push');
            app.PP_SvtBkgSubtract_button.Enable = 'off';
            app.PP_SvtBkgSubtract_button.Text = 'Solvent Bkg.';
            app.PP_SubtractBackground_checkbox = uicheckbox(app.PP_PreprocessingPanel);
            app.PP_SubtractBackground_checkbox.ValueChangedFcn = createCallbackFcn(app, @PP_UpdateBackground, true);
            app.PP_SubtractBackground_checkbox.Text = 'Subtract Background from                     to';
            app.PP_SubtractBackground_checkbox.Value = true;
            app.PP_Background_start = uieditfield(app.PP_PreprocessingPanel, 'numeric');
            app.PP_Background_start.ValueDisplayFormat = '%.3f';
            app.PP_Background_start.ValueChangedFcn = createCallbackFcn(app, @PP_UpdateBackground, true);
            app.PP_Background_start.Value = -25;
            app.PP_Background_end = uieditfield(app.PP_PreprocessingPanel, 'numeric');
            app.PP_Background_end.ValueDisplayFormat = '%.3f';
            app.PP_Background_end.ValueChangedFcn = createCallbackFcn(app, @PP_UpdateBackground, true);
            app.PP_BackgroundSubtract_timeLabel = uilabel(app.PP_PreprocessingPanel);
            app.PP_BackgroundSubtract_timeLabel.Text = 'ns';
            app.PP_PlotBackgroundButton = uibutton(app.PP_PreprocessingPanel, 'push');
            app.PP_PlotBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @PP_PlotBackgroundButtonPushed, true);
            app.PP_PlotBackgroundButton.Text = 'Plot Background';
            app.PP_MaskprobeWLButton = uibutton(app.PP_PreprocessingPanel, 'push');
            app.PP_MaskprobeWLButton.ButtonPushedFcn = createCallbackFcn(app, @PP_MaskprobeWLButtonPushed, true);
            app.PP_MaskprobeWLButton.Text = 'Mask Probe Region';
            app.PP_PlotNoiseButton = uibutton(app.PP_PreprocessingPanel, 'push');
            app.PP_PlotNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @PP_PlotNoiseButtonPushed, true);
            app.PP_PlotNoiseButton.Text = 'Plot Noise';
            app.PP_PlotsandcutsPanel = uipanel(app.PumpProbeTab);
            app.PP_PlotsandcutsPanel.Title = 'Plots and cuts';
            app.PP_PlotsandcutsPanel.Visible = 'off';
            app.GridLayout7 = uigridlayout(app.PP_PlotsandcutsPanel);
            app.PP_ContourplotButton = uibutton(app.GridLayout7, 'push');
            app.PP_ContourplotButton.ButtonPushedFcn = createCallbackFcn(app, @PP_ContourplotButtonPushed, true);
            app.PP_ContourplotButton.Text = 'Contour plot';
            app.PP_PlotKineticsButton = uibutton(app.GridLayout7, 'push');
            app.PP_PlotKineticsButton.ButtonPushedFcn = createCallbackFcn(app, @PP_PlotKineticsButtonPushed, true);
            app.PP_PlotKineticsButton.Text = 'Plot Kinetics';
            app.PP_SurfaceplotButton = uibutton(app.GridLayout7, 'push');
            app.PP_SurfaceplotButton.ButtonPushedFcn = createCallbackFcn(app, @PP_SurfaceplotButtonPushed, true);
            app.PP_SurfaceplotButton.Text = '3D surface plot';
            app.PP_PlotTrSpectraButton = uibutton(app.GridLayout7, 'push');
            app.PP_PlotTrSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @PP_PlotTrSpectraButtonPushed, true);
            app.PP_PlotTrSpectraButton.Text = 'Plot Tr. Spectra';
            app.PP_IncludeSS_CheckBox = uicheckbox(app.GridLayout7);
            app.PP_IncludeSS_CheckBox.Enable = 'off';
            app.PP_IncludeSS_CheckBox.Tooltip = {'If there is FTIR data loaded'; ' will include it on the top of the transient absorption spectra'};
            app.PP_IncludeSS_CheckBox.Text = 'Include SS-Sp';
            app.PP_NormaliseCheckBox = uicheckbox(app.GridLayout7);
            app.PP_NormaliseCheckBox.Tooltip = {'Tick to normalise the transient absorption spectra or kinetic traces to the maximum value.'};
            app.PP_NormaliseCheckBox.Text = 'Normalise';
            app.InteractivemodeSwitch_2Label = uilabel(app.PumpProbeTab);
            app.InteractivemodeSwitch_2Label.Text = 'Interactive mode';
            app.PP_InteractivemodeSwitch = uiswitch(app.PumpProbeTab, 'slider');
            app.PP_InteractivemodeSwitch.ValueChangedFcn = createCallbackFcn(app, @PP_InteractivemodeSwitchValueChanged, true);
            app.PP_InteractivemodeSwitch.Value = 'On';
            app.SavetracesSwitchLabel = uilabel(app.PumpProbeTab);
            app.SavetracesSwitchLabel.Text = 'Save traces';
            app.PP_SavetracesSwitch = uiswitch(app.PumpProbeTab, 'slider');
            app.PP_SavetracesSwitch.Tooltip = {'Enable in order to save the traces that are plotted as a .dat file on the same directory'};
            app.PP_OverlayPlots_check = uicheckbox(app.PumpProbeTab);
            app.PP_OverlayPlots_check.Visible = 'off';
            app.PP_OverlayPlots_check.Text = 'Overlay plots';
            app.PP_AnalyseindividualscansPanel = uipanel(app.PumpProbeTab);
            app.PP_AnalyseindividualscansPanel.Title = 'Analyse individual scans';
            app.PP_AnalyseindividualscansPanel.Visible = 'off';
            app.GridLayout8 = uigridlayout(app.PP_AnalyseindividualscansPanel);
            app.PP_KineticsPerScan_Button = uibutton(app.GridLayout8, 'push');
            app.PP_KineticsPerScan_Button.ButtonPushedFcn = createCallbackFcn(app, @PP_KineticsPerScan_ButtonPushed, true);
            app.PP_KineticsPerScan_Button.Text = 'Plot Kinetics';
            app.BinscansSpinnerLabel = uilabel(app.GridLayout8);
            app.BinscansSpinnerLabel.Text = 'Bin scans';
            app.PP_NBinScans = uispinner(app.GridLayout8);
            app.PP_NBinScans.Limits = [1 Inf];
            app.PP_NBinScans.RoundFractionalValues = 'on';
            app.PP_NBinScans.ValueDisplayFormat = '%.0f';
            app.PP_NBinScans.Value = 1;
            app.PP_SpectraPerScan_Button = uibutton(app.GridLayout8, 'push');
            app.PP_SpectraPerScan_Button.ButtonPushedFcn = createCallbackFcn(app, @PP_SpectraPerScan_ButtonPushed, true);
            app.PP_SpectraPerScan_Button.Text = 'Plot Tr. Spectra';
            app.PP_RecalcScans_Button = uibutton(app.GridLayout8, 'push');
            app.PP_RecalcScans_Button.ButtonPushedFcn = createCallbackFcn(app, @PP_RecalcScans_ButtonPushed, true);
            app.PP_RecalcScans_Button.Text = 'Recalc. Scans';
            app.PP_LoadSSdataButton = uibutton(app.PumpProbeTab, 'push');
            app.PP_LoadSSdataButton.ButtonPushedFcn = createCallbackFcn(app, @PP_LoadFTIRdataButtonPushed, true);
            app.PP_LoadSSdataButton.Text = 'Load SS data';
            app.PP_ShowSSdataButton = uibutton(app.PumpProbeTab, 'push');
            app.PP_ShowSSdataButton.ButtonPushedFcn = createCallbackFcn(app, @PP_ShowFTIRdataButtonPushed, true);
            app.PP_ShowSSdataButton.Enable = 'off';
            app.PP_ShowSSdataButton.Text = 'Show SS data';
            app.PP_ClearSSdataButton = uibutton(app.PumpProbeTab, 'push');
            app.PP_ClearSSdataButton.ButtonPushedFcn = createCallbackFcn(app, @PP_ClearFTIRdataButtonPushed, true);
            app.PP_ClearSSdataButton.Enable = 'off';
            app.PP_ClearSSdataButton.Text = 'Clear SS data';
            app.PP_AnalysissubroutinesPanel = uipanel(app.PumpProbeTab);
            app.PP_AnalysissubroutinesPanel.Title = 'Analysis subroutines';
            app.PP_AnalysissubroutinesPanel.Visible = 'off';
            app.PP_ShowSVD_button = uibutton(app.PP_AnalysissubroutinesPanel, 'push');
            app.PP_ShowSVD_button.ButtonPushedFcn = createCallbackFcn(app, @PP_ShowSVD_buttonButtonPushed, true);
            app.PP_ShowSVD_button.Text = 'Show SVD';
            app.PP_GlobalFitSVD_button = uibutton(app.PP_AnalysissubroutinesPanel, 'push');
            app.PP_GlobalFitSVD_button.ButtonPushedFcn = createCallbackFcn(app, @PP_GlobalFitSVD_buttonButtonPushed, true);
            app.PP_GlobalFitSVD_button.Text = 'SVD global fit';
            app.PP_SVDstart = uispinner(app.PP_AnalysissubroutinesPanel);
            app.PP_SVDstart.Limits = [1 Inf];
            app.PP_SVDstart.ValueDisplayFormat = '%.0f';
            app.PP_SVDstart.Value = 1;
            app.PP_SVDend = uispinner(app.PP_AnalysissubroutinesPanel);
            app.PP_SVDend.Limits = [1 Inf];
            app.PP_SVDend.ValueDisplayFormat = '%.0f';
            app.PP_SVDend.Value = 2;
            app.PP_SVDtoLabel = uilabel(app.PP_AnalysissubroutinesPanel);
            app.PP_SVDtoLabel.Text = 'to';
            app.PP_RemoveOffset_button = uibutton(app.PP_AnalysissubroutinesPanel, 'push');
            app.PP_RemoveOffset_button.Text = 'Remove Offset';
            app.PP_ILTfit_button = uibutton(app.PP_AnalysissubroutinesPanel, 'push');
            app.PP_ILTfit_button.ButtonPushedFcn = createCallbackFcn(app, @PP_ILTfit_buttonButtonPushed, true);
            app.PP_ILTfit_button.Text = 'ILT fit';
            app.PP_ConcPowerDep_button = uibutton(app.PP_AnalysissubroutinesPanel, 'push');
            app.PP_ConcPowerDep_button.Enable = 'off';
            app.PP_ConcPowerDep_button.Text = 'Conc./Power dep.';
            app.PP_PlotDifference_button = uibutton(app.PP_AnalysissubroutinesPanel, 'push');
            app.PP_PlotDifference_button.Enable = 'off';
            app.PP_PlotDifference_button.Text = 'Plot Difference';
            app.PP_Anisotropy_button = uibutton(app.PP_AnalysissubroutinesPanel, 'push');
            app.PP_Anisotropy_button.ButtonPushedFcn = createCallbackFcn(app, @PP_Anisotropy_buttonButtonPushed, true);
            app.PP_Anisotropy_button.Text = 'Anisotropy/MA';
            app.PP_ShiftT0_button = uibutton(app.PP_AnalysissubroutinesPanel, 'push');
            app.PP_ShiftT0_button.ButtonPushedFcn = createCallbackFcn(app, @PP_ShiftT0_buttonButtonPushed, true);
            app.PP_ShiftT0_button.Text = 'Shift t0';
            app.PP_ProbeDetectorLabel = uilabel(app.PumpProbeTab);
            app.PP_ProbeDetectorLabel.Enable = 'off';
            app.PP_ProbeDetectorLabel.Visible = 'off';
            app.PP_ProbeDetectorLabel.Text = 'Probe Detector';
            app.PP_ProbeDetSwitch = uispinner(app.PumpProbeTab);
            app.PP_ProbeDetSwitch.Limits = [1 2];
            app.PP_ProbeDetSwitch.RoundFractionalValues = 'on';
            app.PP_ProbeDetSwitch.ValueDisplayFormat = '%.0f';
            app.PP_ProbeDetSwitch.ValueChangedFcn = createCallbackFcn(app, @PP_ProbeDetSwitchValueChanged, true);
            app.PP_ProbeDetSwitch.Enable = 'off';
            app.PP_ProbeDetSwitch.Visible = 'off';
            app.PP_ProbeDetSwitch.Value = 1;
            app.PP_PlotcontrolsPanel = uipanel(app.PumpProbeTab);
            app.PP_PlotcontrolsPanel.Title = 'Plot controls';
            app.PP_PlotcontrolsPanel.Visible = 'off';
            app.MinWLEditFieldLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.MinWLEditFieldLabel.Text = 'Min WL';
            app.PP_MinWL = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_MinWL.ValueDisplayFormat = '%.2f';
            app.PP_MinWL.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.PP_MaxWL = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_MaxWL.ValueDisplayFormat = '%.2f';
            app.PP_MaxWL.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.MintimeLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.MintimeLabel.Text = 'Min time';
            app.PP_MinTime = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_MinTime.ValueDisplayFormat = '%.3f';
            app.PP_MinTime.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.PP_MaxTime = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_MaxTime.ValueDisplayFormat = '%.3f';
            app.PP_MaxTime.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.NofwhitelevelsLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.NofwhitelevelsLabel.Text = 'Nº of white levels';
            app.PP_NWhites = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_NWhites.Limits = [0 Inf];
            app.PP_NWhites.ValueDisplayFormat = '%.0f';
            app.PP_NWhites.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.PP_NWhites.Value = 2;
            app.ColourschemeDropDownLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.ColourschemeDropDownLabel.Text = 'Colour scheme';
            app.PP_ColourScheme = uidropdown(app.PP_PlotcontrolsPanel);
            app.PP_ColourScheme.Items = {'RdOr/Wh/Bl', 'DkRd/Wh/DkBl', 'Rd/Wh/Bl', 'Spectral', 'Jet', 'Turbo'};
            app.PP_ColourScheme.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.PP_ColourScheme.Tooltip = {'Select the colour scheme for the plots'};
            app.PP_ColourScheme.Value = 'RdOr/Wh/Bl';
            app.MaxWLEditFieldLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.MaxWLEditFieldLabel.Text = 'Max WL';
            app.MaxtimeLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.MaxtimeLabel.Text = 'Max time';
            app.AbsrangeEditFieldLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.AbsrangeEditFieldLabel.Text = '∆Abs range';
            app.PP_DeltaAbsPlotRange = uieditfield(app.PP_PlotcontrolsPanel, 'text');
            app.PP_DeltaAbsPlotRange.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.PP_DeltaAbsPlotRange.Value = '0, 0';
            app.PP_SymmetricColourScale = uicheckbox(app.PP_PlotcontrolsPanel);
            app.PP_SymmetricColourScale.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.PP_SymmetricColourScale.Text = 'Symmetric colour scale';
            app.PP_SymmetricColourScale.Value = true;
            app.PP_FilledContours_tick = uicheckbox(app.PP_PlotcontrolsPanel);
            app.PP_FilledContours_tick.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.PP_FilledContours_tick.Text = 'Filled contours';
            app.PP_FilledContours_tick.Value = true;
            app.PP_LogTime_tick = uicheckbox(app.PP_PlotcontrolsPanel);
            app.PP_LogTime_tick.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.PP_LogTime_tick.Text = 'Logarithmic time axis';
            app.PP_LogTime_tick.Value = true;
            app.MaxAbsmODLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.MaxAbsmODLabel.Text = 'Max ∆Abs (mOD)';
            app.PP_MaxDeltaAbs = uieditfield(app.PP_PlotcontrolsPanel, 'text');
            app.PP_MaxDeltaAbs.Editable = 'off';
            app.PP_MaxDeltaAbs.Value = '0, 0';
            app.PP_RestoredefaultlimitsButton = uibutton(app.PP_PlotcontrolsPanel, 'push');
            app.PP_RestoredefaultlimitsButton.ButtonPushedFcn = createCallbackFcn(app, @PP_RestoredefaultlimitsButtonPushed, true);
            app.PP_RestoredefaultlimitsButton.Text = 'Restore Limits';
            app.ScaleSliderLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.ScaleSliderLabel.Text = 'Scale %';
            app.PP_PlotScaleSlider = uislider(app.PP_PlotcontrolsPanel);
            app.PP_PlotScaleSlider.Limits = [1e-06 100];
            app.PP_PlotScaleSlider.MajorTicks = [1e-06 20.000001 40.000001 60.000001 80.000001 100];
            app.PP_PlotScaleSlider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.PP_PlotScaleSlider.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.PP_PlotScaleSlider.ValueChangingFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanging, true);
            app.PP_PlotScaleSlider.MinorTicks = [1e-06 4.000001 8.000001 12.000001 16.000001 20.000001 24.000001 28.000001 32.000001 36.000001 40.000001 44.000001 48.000001 52.000001 56.000001 60.000001 64.000001 68.000001 72.000001 76.000001 80.000001 84.000001 88.000001 92.000001 96.000001 100];
            app.PP_PlotScaleSlider.Tooltip = {'Set the Z scale of the contour plot'; ' relative to the maximum transient absorption signal. For anisotropy plots'; ' the scale is fixed.'};
            app.PP_PlotScaleSlider.Value = 100;
            app.NofcontoursEditFieldLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.NofcontoursEditFieldLabel.Text = 'Nº of contours';
            app.PP_ShowContours_tick = uicheckbox(app.PP_PlotcontrolsPanel);
            app.PP_ShowContours_tick.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.PP_ShowContours_tick.Text = 'Show contour lines, every          levels. Format:';
            app.PP_NContours = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_NContours.Limits = [1 Inf];
            app.PP_NContours.RoundFractionalValues = 'on';
            app.PP_NContours.ValueDisplayFormat = '%.0f';
            app.PP_NContours.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.PP_NContours.Value = 40;
            app.PP_NskipContours = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_NskipContours.Limits = [1 Inf];
            app.PP_NskipContours.RoundFractionalValues = 'on';
            app.PP_NskipContours.ValueDisplayFormat = '%.0f';
            app.PP_NskipContours.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.PP_NskipContours.Value = 4;
            app.PP_ContourFormat = uieditfield(app.PP_PlotcontrolsPanel, 'text');
            app.PP_ContourFormat.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.PP_ContourFormat.Value = '-';
            app.PP_PlotScaleNumber = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_PlotScaleNumber.LowerLimitInclusive = 'off';
            app.PP_PlotScaleNumber.Limits = [1e-06 100];
            app.PP_PlotScaleNumber.ValueDisplayFormat = '%.3f';
            app.PP_PlotScaleNumber.ValueChangedFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanged, true);
            app.PP_PlotScaleNumber.Value = 100;
            app.PP_SampleInfo = uitextarea(app.PumpProbeTab);
            app.PP_SampleInfo.Editable = 'off';
            app.PP_SampleInfo.Visible = 'off';
            app.Interferometric2DIRTab = uitab(app.MainTabs);
            app.Interferometric2DIRTab.Tooltip = {''};
            app.Interferometric2DIRTab.Title = 'Interferometric 2D-IR';
            app.TwoDAxes = uiaxes(app.Interferometric2DIRTab);
            xlabel(app.TwoDAxes, 'X')
            ylabel(app.TwoDAxes, 'Y')
            app.TwoDAxes.Visible = 'off';
            app.DataformatDropDownLabel_2 = uilabel(app.Interferometric2DIRTab);
            app.DataformatDropDownLabel_2.Text = 'Data format';
            app.I2D_Dataformat = uidropdown(app.Interferometric2DIRTab);
            app.I2D_Dataformat.Items = {'2D-IR (Sheffield)', '2D-IR (RAL/CLF proc.)', '2D-IR [Lab 1 & Lab 4]', '2D-IR [Lab 5]', '2D-IR simulation', 'Transient 2D-IR', '2D-IR [Lab 3]'};
            app.I2D_Dataformat.ValueChangedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.I2D_Dataformat.Tooltip = {'Select the format of the data to load.'};
            app.I2D_Dataformat.Value = '2D-IR (Sheffield)';
            app.I2D_ReloadButton = uibutton(app.Interferometric2DIRTab, 'push');
            app.I2D_ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @I2D_DataFolderListValueChanged, true);
            app.I2D_ReloadButton.Text = 'Reload';
            app.I2D_SelectdatasetPanel = uipanel(app.Interferometric2DIRTab);
            app.I2D_SelectdatasetPanel.Title = 'Select dataset';
            app.I2D_DataFolderList = uilistbox(app.I2D_SelectdatasetPanel);
            app.I2D_DataFolderList.Items = {'Available data folders will appear here ', 'once a root folder is selected'};
            app.I2D_DataFolderList.ValueChangedFcn = createCallbackFcn(app, @I2D_DataFolderListValueChanged, true);
            app.I2D_DataFolderList.Value = {};
            app.tdelaypsLabel = uilabel(app.I2D_SelectdatasetPanel);
            app.tdelaypsLabel.Text = 't₂ delay (ps)';
            app.I2D_t2delays = uilistbox(app.I2D_SelectdatasetPanel);
            app.I2D_t2delays.Items = {'Select', 't₂ delay'};
            app.I2D_t2delays.ValueChangedFcn = createCallbackFcn(app, @I2D_t2delaysValueChanged, true);
            app.I2D_t2delays.Enable = 'off';
            app.I2D_t2delays.Value = 'Select';
            app.I2D_SubTabs = uitabgroup(app.Interferometric2DIRTab);
            app.I2D_SubTabs.Visible = 'off';
            app.I2D_ProcessingandplottingTab = uitab(app.I2D_SubTabs);
            app.I2D_ProcessingandplottingTab.Title = 'Process & Plot';
            app.I2D_SubtractScatteringCheckBox = uicheckbox(app.I2D_ProcessingandplottingTab);
            app.I2D_SubtractScatteringCheckBox.Text = 'Subtract scattering, delay:';
            app.I2D_SubtractScatteringCheckBox.Value = true;
            app.I2D_ScatteringSubDelay = uispinner(app.I2D_ProcessingandplottingTab);
            app.I2D_ScatteringSubDelay.Limits = [1 Inf];
            app.I2D_ScatteringSubDelay.RoundFractionalValues = 'on';
            app.I2D_ScatteringSubDelay.Visible = 'off';
            app.I2D_ScatteringSubDelay.Value = 1;
            app.I2D_ApplychangesButton = uibutton(app.I2D_ProcessingandplottingTab, 'push');
            app.I2D_ApplychangesButton.ButtonPushedFcn = createCallbackFcn(app, @I2D_ApplychangesButtonPushed, true);
            app.I2D_ApplychangesButton.Text = 'Apply changes';
            app.I2D_AutocalibrateprobeaxisCheckBox = uicheckbox(app.I2D_ProcessingandplottingTab);
            app.I2D_AutocalibrateprobeaxisCheckBox.Text = 'Auto calibrate probe axis';
            app.I2D_PumpcorrectionCheckBox = uicheckbox(app.I2D_ProcessingandplottingTab);
            app.I2D_PumpcorrectionCheckBox.Text = 'Pump correction';
            app.I2D_PumpcorrectionCheckBox.Value = true;
            app.I2D_SaveProbeCalibration_button = uibutton(app.I2D_ProcessingandplottingTab, 'push');
            app.I2D_SaveProbeCalibration_button.ButtonPushedFcn = createCallbackFcn(app, @I2D_SaveProbeCalibration_buttonButtonPushed, true);
            app.I2D_SaveProbeCalibration_button.Text = 'Save probe cal.';
            app.I2D_ProcessingAndPlottingPanel = uipanel(app.I2D_ProcessingandplottingTab);
            app.I2D_ProcessingAndPlottingPanel.Title = 'Phasing, zeropadding and apodisation';
            app.PhasefitDropDownLabel = uilabel(app.I2D_ProcessingAndPlottingPanel);
            app.PhasefitDropDownLabel.Text = 'Phase fit';
            app.I2D_PhaseFitFunction = uidropdown(app.I2D_ProcessingAndPlottingPanel);
            app.I2D_PhaseFitFunction.Items = {'Constant', 'Linear', 'Quadratic', 'Cubic', 'No fit'};
            app.I2D_PhaseFitFunction.Value = 'Linear';
            app.I2D_PhaseFitRangeEdit = uieditfield(app.I2D_ProcessingAndPlottingPanel, 'numeric');
            app.I2D_PhaseFitRangeEdit.Value = 100;
            app.I2D_pmLabel = uilabel(app.I2D_ProcessingAndPlottingPanel);
            app.I2D_pmLabel.Text = '±';
            app.cmLabel = uilabel(app.I2D_ProcessingAndPlottingPanel);
            app.cmLabel.Text = 'cm⁻¹';
            app.ApodisationDropDownLabel = uilabel(app.I2D_ProcessingAndPlottingPanel);
            app.ApodisationDropDownLabel.Text = 'Apodisation';
            app.CoeffsLabel = uilabel(app.I2D_ProcessingAndPlottingPanel);
            app.CoeffsLabel.Text = 'Coeffs.';
            app.I2D_PhaseCoeffs = uieditfield(app.I2D_ProcessingAndPlottingPanel, 'text');
            app.I2D_PhaseCoeffs.Editable = 'off';
            app.I2D_PhaseCoeffs.Value = '0, 0';
            app.I2D_ApodisationFunction = uidropdown(app.I2D_ProcessingAndPlottingPanel);
            app.I2D_ApodisationFunction.Items = {'Box', 'Cos', 'Cos²', 'Cos³'};
            app.I2D_ApodisationFunction.ItemsData = {'0', '1', '2', '3'};
            app.I2D_ApodisationFunction.Value = '1';
            app.I2D_DoZeropaddingCheckBox = uicheckbox(app.I2D_ProcessingAndPlottingPanel);
            app.I2D_DoZeropaddingCheckBox.Text = 'Zeropad, factor';
            app.I2D_DoZeropaddingCheckBox.Value = true;
            app.I2D_ZeropadFactor = uispinner(app.I2D_ProcessingAndPlottingPanel);
            app.I2D_ZeropadFactor.Limits = [1 Inf];
            app.I2D_ZeropadFactor.RoundFractionalValues = 'on';
            app.I2D_ZeropadFactor.Value = 2;
            app.I2D_ZeropadToNext2K = uicheckbox(app.I2D_ProcessingAndPlottingPanel);
            app.I2D_ZeropadToNext2K.Text = 'to next 2ᵏ';
            app.I2D_ZeropadToNext2K.Value = true;
            app.SMDuLabel = uilabel(app.I2D_ProcessingAndPlottingPanel);
            app.SMDuLabel.Visible = 'off';
            app.SMDuLabel.Text = 'SM/Du';
            app.I2D_SMswitch = uispinner(app.I2D_ProcessingAndPlottingPanel);
            app.I2D_SMswitch.Limits = [1 Inf];
            app.I2D_SMswitch.RoundFractionalValues = 'on';
            app.I2D_SMswitch.ValueChangedFcn = createCallbackFcn(app, @I2D_SMswitchValueChanged, true);
            app.I2D_SMswitch.Visible = 'off';
            app.I2D_SMswitch.Value = 1;
            app.I2D_PlotsandcutsPanel = uipanel(app.I2D_ProcessingandplottingTab);
            app.I2D_PlotsandcutsPanel.Title = 'Plots and cuts';
            app.GridLayout3 = uigridlayout(app.I2D_PlotsandcutsPanel);
            app.I2D_ContourplotButton = uibutton(app.GridLayout3, 'push');
            app.I2D_ContourplotButton.ButtonPushedFcn = createCallbackFcn(app, @I2D_ContourplotButtonPushed, true);
            app.I2D_ContourplotButton.Text = 'Contour plot';
            app.I2D_PlotKineticsButton = uibutton(app.GridLayout3, 'push');
            app.I2D_PlotKineticsButton.ButtonPushedFcn = createCallbackFcn(app, @I2D_PlotKineticsButtonPushed, true);
            app.I2D_PlotKineticsButton.Text = 'Plot Kinetics';
            app.I2D_SurfaceplotButton = uibutton(app.GridLayout3, 'push');
            app.I2D_SurfaceplotButton.ButtonPushedFcn = createCallbackFcn(app, @I2D_SurfaceplotButtonPushed, true);
            app.I2D_SurfaceplotButton.Text = '3D surface plot';
            app.I2D_PlotSlicesButton = uibutton(app.GridLayout3, 'push');
            app.I2D_PlotSlicesButton.ButtonPushedFcn = createCallbackFcn(app, @I2D_PlotSlicesButtonPushed, true);
            app.I2D_PlotSlicesButton.Text = 'Plot Slices';
            app.I2D_IncludeFTIRCheckBox = uicheckbox(app.GridLayout3);
            app.I2D_IncludeFTIRCheckBox.Enable = 'off';
            app.I2D_IncludeFTIRCheckBox.Tooltip = {'Tick to normalise the transient absorption spectra or kinetic traces to the maximum value.'};
            app.I2D_IncludeFTIRCheckBox.Text = 'Include FTIR';
            app.I2D_NormaliseCheckBox = uicheckbox(app.GridLayout3);
            app.I2D_NormaliseCheckBox.Tooltip = {'Tick to normalise the transient absorption spectra or kinetic traces to the maximum value.'};
            app.I2D_NormaliseCheckBox.Text = 'Normalise';
            app.InteractivemodeSwitch_2Label_2 = uilabel(app.I2D_ProcessingandplottingTab);
            app.InteractivemodeSwitch_2Label_2.Text = 'Interactive mode';
            app.I2D_InteractivemodeSwitch = uiswitch(app.I2D_ProcessingandplottingTab, 'slider');
            app.I2D_InteractivemodeSwitch.ValueChangedFcn = createCallbackFcn(app, @PP_InteractivemodeSwitchValueChanged, true);
            app.I2D_InteractivemodeSwitch.Value = 'On';
            app.SavetracesSwitchLabel_2 = uilabel(app.I2D_ProcessingandplottingTab);
            app.SavetracesSwitchLabel_2.Text = 'Save traces';
            app.I2D_SavetracesSwitch = uiswitch(app.I2D_ProcessingandplottingTab, 'slider');
            app.I2D_SavetracesSwitch.Tooltip = {'Enable in order to save the traces that are plotted as a .dat file on the same directory'};
            app.OtherPlotsTab = uitab(app.I2D_SubTabs);
            app.OtherPlotsTab.Title = 'Other Plots';
            app.GridLayout4 = uigridlayout(app.OtherPlotsTab);
            app.I2D_SecondaryAxes = uiaxes(app.GridLayout4);
            xlabel(app.I2D_SecondaryAxes, 'X')
            ylabel(app.I2D_SecondaryAxes, 'Y')
            app.I2D_SecondaryAxes.Visible = 'off';
            app.I2D_ExtraPlotOption = uibuttongroup(app.GridLayout4);
            app.I2D_ExtraPlotOption.SelectionChangedFcn = createCallbackFcn(app, @I2D_ExtraPlotOptionSelectionChanged, true);
            app.I2D_sec_TD = uitogglebutton(app.I2D_ExtraPlotOption);
            app.I2D_sec_TD.Text = 'Time-domain data';
            app.I2D_sec_PH = uitogglebutton(app.I2D_ExtraPlotOption);
            app.I2D_sec_PH.Text = 'Phasing';
            app.I2D_sec_PH.Value = true;
            app.I2D_sec_PC = uitogglebutton(app.I2D_ExtraPlotOption);
            app.I2D_sec_PC.Text = 'Probe calibration';
            app.PixelSpinnerLabel = uilabel(app.GridLayout4);
            app.PixelSpinnerLabel.Text = 'Pixel:';
            app.I2D_TDPixel = uispinner(app.GridLayout4);
            app.I2D_TDPixel.ValueChangedFcn = createCallbackFcn(app, @I2D_ExtraPlotOptionSelectionChanged, true);
            app.I2D_sec_ShowPixelLine = uicheckbox(app.GridLayout4);
            app.I2D_sec_ShowPixelLine.ValueChangedFcn = createCallbackFcn(app, @I2D_ExtraPlotOptionSelectionChanged, true);
            app.I2D_sec_ShowPixelLine.Text = 'Display pixel line';
            app.Resultstr2DTab = uitab(app.I2D_SubTabs);
            app.Resultstr2DTab.Title = 'Results & tr2D';
            app.GridLayout5 = uigridlayout(app.Resultstr2DTab);
            app.I2D_ShowSpecDiff = uicheckbox(app.GridLayout5);
            app.I2D_ShowSpecDiff.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_ShowSpecDiff.Enable = 'off';
            app.I2D_ShowSpecDiff.Text = 'Show spectral diffusion lines (CLS & IvCLS)';
            app.I2D_Show2DGCresults = uicheckbox(app.GridLayout5);
            app.I2D_Show2DGCresults.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_Show2DGCresults.Enable = 'off';
            app.I2D_Show2DGCresults.Text = 'Show fit results (2DGC or TD)';
            app.I2D_LoadFit = uibutton(app.GridLayout5, 'push');
            app.I2D_LoadFit.ButtonPushedFcn = createCallbackFcn(app, @I2D_LoadFitButtonPushed, true);
            app.I2D_LoadFit.Enable = 'off';
            app.I2D_LoadFit.Text = {'Load fit'; 'from file'};
            app.I2D_Show2DGCResultsType = uibuttongroup(app.GridLayout5);
            app.I2D_Show2DGCResultsType.SelectionChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_Show2DGC_Fit = uitogglebutton(app.I2D_Show2DGCResultsType);
            app.I2D_Show2DGC_Fit.Enable = 'off';
            app.I2D_Show2DGC_Fit.Text = 'Fit';
            app.I2D_Show2DGC_Fit.Value = true;
            app.I2D_Show2DGC_Residuals = uitogglebutton(app.I2D_Show2DGCResultsType);
            app.I2D_Show2DGC_Residuals.Enable = 'off';
            app.I2D_Show2DGC_Residuals.Text = 'Residuals';
            app.Transient2DcalcLabel = uilabel(app.GridLayout5);
            app.Transient2DcalcLabel.Enable = 'off';
            app.Transient2DcalcLabel.Text = 'Transient 2D calc.';
            app.I2D_Transient2Dtype = uidropdown(app.GridLayout5);
            app.I2D_Transient2Dtype.Items = {'Spectrum 1 (UV on)', 'Spectrum 2 (UV off)', 'Difference (1-2)', 'Difference (2-1)', 'Sum', 'Spectroelectrochemistry'};
            app.I2D_Transient2Dtype.Enable = 'off';
            app.I2D_Transient2Dtype.Tooltip = {'Select the format of the data to load.'};
            app.I2D_Transient2Dtype.Value = 'Difference (2-1)';
            app.I2D_SimulationextraplotsPanel = uipanel(app.GridLayout5);
            app.I2D_SimulationextraplotsPanel.Title = 'Simulation extra plots';
            app.I2D_SimulationextraplotsPanel.Visible = 'off';
            app.GridLayout6 = uigridlayout(app.I2D_SimulationextraplotsPanel);
            app.I2D_S_PlotTrajectory = uibutton(app.GridLayout6, 'push');
            app.I2D_S_PlotTrajectory.ButtonPushedFcn = createCallbackFcn(app, @I2D_S_PlotTrajectoryButtonPushed, true);
            app.I2D_S_PlotTrajectory.Text = 'Plot trajectory';
            app.I2D_S_WhichTraject = uispinner(app.GridLayout6);
            app.I2D_S_WhichTraject.Limits = [0 Inf];
            app.I2D_S_WhichTraject.RoundFractionalValues = 'on';
            app.I2D_S_WhichTraject.ValueDisplayFormat = '%.0f';
            app.I2D_S_randomLabel = uilabel(app.GridLayout6);
            app.I2D_S_randomLabel.Text = '(0 = random)';
            app.I2D_S_PlotRelConc = uibutton(app.GridLayout6, 'push');
            app.I2D_S_PlotRelConc.ButtonPushedFcn = createCallbackFcn(app, @I2D_S_PlotRelConcButtonPushed, true);
            app.I2D_S_PlotRelConc.Text = 'Plot rel. conc.';
            app.I2D_S_WhichTotal = uibuttongroup(app.GridLayout6);
            app.I2D_S_PlotTraject_All = uiradiobutton(app.I2D_S_WhichTotal);
            app.I2D_S_PlotTraject_All.Text = 'All';
            app.I2D_S_PlotTraject_All.Value = true;
            app.I2D_S_PlotTraject_Single = uiradiobutton(app.I2D_S_WhichTotal);
            app.I2D_S_PlotTraject_Single.Text = 'Single';
            app.I2D_S_CalcGr = uibutton(app.GridLayout6, 'push');
            app.I2D_S_CalcGr.ButtonPushedFcn = createCallbackFcn(app, @I2D_S_CalcGrButtonPushed, true);
            app.I2D_S_CalcGr.Text = 'Calc. g(r)';
            app.I2D_S_PlotAngleHist = uibutton(app.GridLayout6, 'push');
            app.I2D_S_PlotAngleHist.ButtonPushedFcn = createCallbackFcn(app, @I2D_S_PlotAngleHistButtonPushed, true);
            app.I2D_S_PlotAngleHist.Text = 'Plot angle hist.';
            app.I2D_S_Plot1Dspec = uibutton(app.GridLayout6, 'push');
            app.I2D_S_Plot1Dspec.ButtonPushedFcn = createCallbackFcn(app, @I2D_S_Plot1DspecButtonPushed, true);
            app.I2D_S_Plot1Dspec.Text = 'Plot 1D spec.';
            app.I2D_ProbeDetectorLabel = uilabel(app.Interferometric2DIRTab);
            app.I2D_ProbeDetectorLabel.Enable = 'off';
            app.I2D_ProbeDetectorLabel.Visible = 'off';
            app.I2D_ProbeDetectorLabel.Text = 'Probe Detector';
            app.I2D_ProbeDetSwitch = uispinner(app.Interferometric2DIRTab);
            app.I2D_ProbeDetSwitch.Limits = [1 2];
            app.I2D_ProbeDetSwitch.RoundFractionalValues = 'on';
            app.I2D_ProbeDetSwitch.ValueDisplayFormat = '%.0f';
            app.I2D_ProbeDetSwitch.ValueChangedFcn = createCallbackFcn(app, @I2D_RestoredefaultlimitsButtonPushed, true);
            app.I2D_ProbeDetSwitch.Enable = 'off';
            app.I2D_ProbeDetSwitch.Visible = 'off';
            app.I2D_ProbeDetSwitch.Value = 1;
            app.I2D_AnalysissubroutinesPanel = uipanel(app.Interferometric2DIRTab);
            app.I2D_AnalysissubroutinesPanel.Title = 'Analysis subroutines';
            app.I2D_AnalysissubroutinesPanel.Visible = 'off';
            app.GridLayout2 = uigridlayout(app.I2D_AnalysissubroutinesPanel);
            app.I2D_IntegralDynamics_button = uibutton(app.GridLayout2, 'push');
            app.I2D_IntegralDynamics_button.Enable = 'off';
            app.I2D_IntegralDynamics_button.Text = 'Integral dynamics';
            app.I2D_2DGCFit_button = uibutton(app.GridLayout2, 'push');
            app.I2D_2DGCFit_button.ButtonPushedFcn = createCallbackFcn(app, @I2D_2DGCFit_buttonButtonPushed, true);
            app.I2D_2DGCFit_button.Text = '2D Gaussian fit';
            app.I2D_TDFit_button = uibutton(app.GridLayout2, 'push');
            app.I2D_TDFit_button.ButtonPushedFcn = createCallbackFcn(app, @I2D_TDFit_buttonButtonPushed, true);
            app.I2D_TDFit_button.Text = 'Time-domain fit';
            app.I2D_SubtractSpectra_button = uibutton(app.GridLayout2, 'push');
            app.I2D_SubtractSpectra_button.ButtonPushedFcn = createCallbackFcn(app, @I2D_SubtractSpectra_buttonButtonPushed, true);
            app.I2D_SubtractSpectra_button.Text = 'Subtract spectra';
            app.I2D_SpectralDiffusion_button = uibutton(app.GridLayout2, 'push');
            app.I2D_SpectralDiffusion_button.ButtonPushedFcn = createCallbackFcn(app, @I2D_SpectralDiffusion_buttonButtonPushed, true);
            app.I2D_SpectralDiffusion_button.Text = 'Spectral diffusion';
            app.I2D_MakeMovie_button = uibutton(app.GridLayout2, 'push');
            app.I2D_MakeMovie_button.ButtonPushedFcn = createCallbackFcn(app, @I2D_MakeMovie_buttonButtonPushed, true);
            app.I2D_MakeMovie_button.Text = 'Make GIF movie';
            app.I2D_PlotControls_Panel = uipanel(app.Interferometric2DIRTab);
            app.I2D_PlotControls_Panel.Title = 'Plot controls';
            app.I2D_PlotControls_Panel.Visible = 'off';
            app.PumpWLProbeWLLabel = uilabel(app.I2D_PlotControls_Panel);
            app.PumpWLProbeWLLabel.Text = 'Pump WL  Probe WL';
            app.LegendLabel = uilabel(app.I2D_PlotControls_Panel);
            app.LegendLabel.Text = 'Legend ';
            app.I2D_PlotLegendStyle = uidropdown(app.I2D_PlotControls_Panel);
            app.I2D_PlotLegendStyle.Items = {'Omega', 'Pump-probe', 'ω pump-probe'};
            app.I2D_PlotLegendStyle.ItemsData = {'1', '2', '3'};
            app.I2D_PlotLegendStyle.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_PlotLegendStyle.Tooltip = {'Select the axis legend style'};
            app.I2D_PlotLegendStyle.Value = '1';
            app.PumpAxisLabel = uilabel(app.I2D_PlotControls_Panel);
            app.PumpAxisLabel.Text = 'Pump Axis';
            app.I2D_PumpAxisOrientation = uidropdown(app.I2D_PlotControls_Panel);
            app.I2D_PumpAxisOrientation.Items = {'Horizontal', 'Vertical'};
            app.I2D_PumpAxisOrientation.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_PumpAxisOrientation.Tooltip = {'Select the orientation of the pump axis.'};
            app.I2D_PumpAxisOrientation.Value = 'Horizontal';
            app.ColSchemeLabel = uilabel(app.I2D_PlotControls_Panel);
            app.ColSchemeLabel.Text = 'Col. Scheme';
            app.I2D_ColourScheme = uidropdown(app.I2D_PlotControls_Panel);
            app.I2D_ColourScheme.Items = {'RdOr/Wh/Bl', 'DkRd/Wh/DkBl', 'Rd/Wh/Bl', 'Jet', 'Turbo'};
            app.I2D_ColourScheme.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_ColourScheme.Tooltip = {'Select the colour scheme for the plots'};
            app.I2D_ColourScheme.Value = 'DkRd/Wh/DkBl';
            app.MinLabel = uilabel(app.I2D_PlotControls_Panel);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.I2D_PlotControls_Panel);
            app.MaxLabel.Text = 'Max';
            app.I2D_MinWL_pump = uieditfield(app.I2D_PlotControls_Panel, 'numeric');
            app.I2D_MinWL_pump.ValueDisplayFormat = '%.2f';
            app.I2D_MinWL_pump.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_MaxWL_pump = uieditfield(app.I2D_PlotControls_Panel, 'numeric');
            app.I2D_MaxWL_pump.ValueDisplayFormat = '%.2f';
            app.I2D_MaxWL_pump.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_MinWL_probe = uieditfield(app.I2D_PlotControls_Panel, 'numeric');
            app.I2D_MinWL_probe.ValueDisplayFormat = '%.2f';
            app.I2D_MinWL_probe.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_MaxWL_probe = uieditfield(app.I2D_PlotControls_Panel, 'numeric');
            app.I2D_MaxWL_probe.ValueDisplayFormat = '%.2f';
            app.I2D_MaxWL_probe.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.NofwhitelvlsLabel = uilabel(app.I2D_PlotControls_Panel);
            app.NofwhitelvlsLabel.Text = 'Nº of white lvls.';
            app.I2D_NWhites = uieditfield(app.I2D_PlotControls_Panel, 'numeric');
            app.I2D_NWhites.Limits = [0 Inf];
            app.I2D_NWhites.RoundFractionalValues = 'on';
            app.I2D_NWhites.ValueDisplayFormat = '%.0f';
            app.I2D_NWhites.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_NWhites.Value = 2;
            app.ArangeLabel = uilabel(app.I2D_PlotControls_Panel);
            app.ArangeLabel.Text = '∆A range';
            app.I2D_DeltaAbsPlotRange = uieditfield(app.I2D_PlotControls_Panel, 'text');
            app.I2D_DeltaAbsPlotRange.Value = '0, 0';
            app.I2D_CutPlot_tick = uicheckbox(app.I2D_PlotControls_Panel);
            app.I2D_CutPlot_tick.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_CutPlot_tick.Text = 'Cut plot';
            app.I2D_CutPlot_tick.Value = true;
            app.I2D_SymmetricColourScale = uicheckbox(app.I2D_PlotControls_Panel);
            app.I2D_SymmetricColourScale.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_SymmetricColourScale.Text = 'Symmetric colour scale';
            app.I2D_SymmetricColourScale.Value = true;
            app.I2D_RestoredefaultlimitsButton = uibutton(app.I2D_PlotControls_Panel, 'push');
            app.I2D_RestoredefaultlimitsButton.ButtonPushedFcn = createCallbackFcn(app, @I2D_RestoredefaultlimitsButtonPushed, true);
            app.I2D_RestoredefaultlimitsButton.Text = 'Restore';
            app.I2D_SynclimitsButton = uibutton(app.I2D_PlotControls_Panel, 'push');
            app.I2D_SynclimitsButton.ButtonPushedFcn = createCallbackFcn(app, @I2D_SynclimitsButtonPushed, true);
            app.I2D_SynclimitsButton.Text = 'Sync Limits';
            app.I2D_FilledContours_tick = uicheckbox(app.I2D_PlotControls_Panel);
            app.I2D_FilledContours_tick.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_FilledContours_tick.Text = 'Filled contours';
            app.I2D_FilledContours_tick.Value = true;
            app.MaxAauLabel = uilabel(app.I2D_PlotControls_Panel);
            app.MaxAauLabel.Text = 'Max ∆A (a.u.)';
            app.I2D_MaxDeltaAbs = uieditfield(app.I2D_PlotControls_Panel, 'text');
            app.I2D_MaxDeltaAbs.Editable = 'off';
            app.I2D_MaxDeltaAbs.Value = '0, 0';
            app.I2D_TextBackground_tick = uicheckbox(app.I2D_PlotControls_Panel);
            app.I2D_TextBackground_tick.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_TextBackground_tick.Text = 'Text white background';
            app.I2D_SquareAspectRatio_tick = uicheckbox(app.I2D_PlotControls_Panel);
            app.I2D_SquareAspectRatio_tick.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_SquareAspectRatio_tick.Text = 'Square Plot Box';
            app.I2D_SquareAspectRatio_tick.Value = true;
            app.ScaleSliderLabel_2 = uilabel(app.I2D_PlotControls_Panel);
            app.ScaleSliderLabel_2.Text = 'Scale %';
            app.I2D_PlotScaleSlider = uislider(app.I2D_PlotControls_Panel);
            app.I2D_PlotScaleSlider.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotScaleNumberandSlider, true);
            app.I2D_PlotScaleSlider.ValueChangingFcn = createCallbackFcn(app, @I2D_PlotScaleSliderValueChanging, true);
            app.I2D_PlotScaleSlider.Tooltip = {'Set the Z scale of the contour plot'; ' relative to the maximum transient absorption signal. For anisotropy plots'; ' the scale is fixed.'};
            app.I2D_PlotScaleSlider.Value = 100;
            app.NofcontoursEditFieldLabel_2 = uilabel(app.I2D_PlotControls_Panel);
            app.NofcontoursEditFieldLabel_2.Text = 'Nº of contours';
            app.I2D_NContours = uieditfield(app.I2D_PlotControls_Panel, 'numeric');
            app.I2D_NContours.Limits = [1 Inf];
            app.I2D_NContours.RoundFractionalValues = 'on';
            app.I2D_NContours.ValueDisplayFormat = '%.0f';
            app.I2D_NContours.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_NContours.Value = 40;
            app.I2D_ShowContours_tick = uicheckbox(app.I2D_PlotControls_Panel);
            app.I2D_ShowContours_tick.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_ShowContours_tick.Text = 'Show contour lines, every          levels. Format:';
            app.I2D_NskipContours = uieditfield(app.I2D_PlotControls_Panel, 'numeric');
            app.I2D_NskipContours.Limits = [1 Inf];
            app.I2D_NskipContours.ValueDisplayFormat = '%.0f';
            app.I2D_NskipContours.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_NskipContours.Value = 2;
            app.I2D_ContourLineStyle = uieditfield(app.I2D_PlotControls_Panel, 'text');
            app.I2D_ContourLineStyle.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotOther, true);
            app.I2D_ContourLineStyle.Value = '-';
            app.I2D_PlotScaleNumber = uieditfield(app.I2D_PlotControls_Panel, 'numeric');
            app.I2D_PlotScaleNumber.LowerLimitInclusive = 'off';
            app.I2D_PlotScaleNumber.Limits = [0 100];
            app.I2D_PlotScaleNumber.ValueDisplayFormat = '%.4g';
            app.I2D_PlotScaleNumber.ValueChangedFcn = createCallbackFcn(app, @I2D_UpdatePlotScaleNumberandSlider, true);
            app.I2D_PlotScaleNumber.Value = 100;
            app.I2D_SampleInfo = uitextarea(app.Interferometric2DIRTab);
            app.I2D_SampleInfo.Editable = 'off';
            app.I2D_SampleInfo.Visible = 'off';
            app.SpectrometerCalibration = uitab(app.MainTabs);
            app.SpectrometerCalibration.Title = 'Spectrometer & Shaper Calibration';
            app.CAL_Axes12 = uiaxes(app.SpectrometerCalibration);
            xlabel(app.CAL_Axes12, 'X')
            ylabel(app.CAL_Axes12, 'Y')
            app.CAL_Axes12.Visible = 'off';
            app.CAL_Axes22 = uiaxes(app.SpectrometerCalibration);
            xlabel(app.CAL_Axes22, 'X')
            ylabel(app.CAL_Axes22, 'Y')
            app.CAL_Axes22.Visible = 'off';
            app.CAL_Axes11 = uiaxes(app.SpectrometerCalibration);
            xlabel(app.CAL_Axes11, 'X')
            ylabel(app.CAL_Axes11, 'Y')
            app.CAL_Axes11.Visible = 'off';
            app.CAL_Axes21 = uiaxes(app.SpectrometerCalibration);
            xlabel(app.CAL_Axes21, 'X')
            ylabel(app.CAL_Axes21, 'Y')
            app.CAL_Axes21.Visible = 'off';
            app.CAL_AxesC1 = uiaxes(app.SpectrometerCalibration);
            xlabel(app.CAL_AxesC1, 'X')
            ylabel(app.CAL_AxesC1, 'Y')
            app.CAL_AxesC1.Visible = 'off';
            app.CAL_AxesC2 = uiaxes(app.SpectrometerCalibration);
            xlabel(app.CAL_AxesC2, 'X')
            ylabel(app.CAL_AxesC2, 'Y')
            app.CAL_AxesC2.Visible = 'off';
            app.CAL_SpectrometerCalibrationPanel = uipanel(app.SpectrometerCalibration);
            app.CAL_SpectrometerCalibrationPanel.Title = 'Spectrometer Calibration';
            app.CAL_Sp_LoadProbeSpectrumButton = uibutton(app.CAL_SpectrometerCalibrationPanel, 'push');
            app.CAL_Sp_LoadProbeSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @CAL_Sp_LoadProbeSpectrumButtonPushed, true);
            app.CAL_Sp_LoadProbeSpectrumButton.Text = 'Load Probe Spectrum';
            app.CAL_Sp_Lamp1 = uilamp(app.CAL_SpectrometerCalibrationPanel);
            app.CAL_Sp_LoadSolventSpectrumButton = uibutton(app.CAL_SpectrometerCalibrationPanel, 'push');
            app.CAL_Sp_LoadSolventSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @CAL_Sp_LoadSolventSpectrumButtonPushed, true);
            app.CAL_Sp_LoadSolventSpectrumButton.Text = 'Load Svt./Filt. Spectrum';
            app.CAL_Sp_Lamp2 = uilamp(app.CAL_SpectrometerCalibrationPanel);
            app.CAL_Sp_LoadFTIRCalibrationButton = uibutton(app.CAL_SpectrometerCalibrationPanel, 'push');
            app.CAL_Sp_LoadFTIRCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CAL_Sp_LoadFTIRCalibrationButtonPushed, true);
            app.CAL_Sp_LoadFTIRCalibrationButton.Text = 'Load Calibration Spectrum';
            app.CAL_Sp_Lamp3 = uilamp(app.CAL_SpectrometerCalibrationPanel);
            app.CAL_Sp_InputCalibrationPointsButton = uibutton(app.CAL_SpectrometerCalibrationPanel, 'push');
            app.CAL_Sp_InputCalibrationPointsButton.Enable = 'off';
            app.CAL_Sp_InputCalibrationPointsButton.Visible = 'off';
            app.CAL_Sp_InputCalibrationPointsButton.Text = 'Input Calibration Points';
            app.CAL_Sp_Lamp4 = uilamp(app.CAL_SpectrometerCalibrationPanel);
            app.CAL_Sp_Lamp4.Enable = 'off';
            app.CAL_Sp_Lamp4.Visible = 'off';
            app.CAL_Sp_FitButton = uibutton(app.CAL_SpectrometerCalibrationPanel, 'push');
            app.CAL_Sp_FitButton.Enable = 'off';
            app.CAL_Sp_FitButton.Visible = 'off';
            app.CAL_Sp_FitButton.Text = 'Fit Manually';
            app.CAL_Sp_AutoCalButton = uibutton(app.CAL_SpectrometerCalibrationPanel, 'push');
            app.CAL_Sp_AutoCalButton.ButtonPushedFcn = createCallbackFcn(app, @CAL_Sp_AutoCalButtonPushed, true);
            app.CAL_Sp_AutoCalButton.Text = 'Do Automatic Calibration';
            app.CAL_Sp_SaveButton = uibutton(app.CAL_SpectrometerCalibrationPanel, 'push');
            app.CAL_Sp_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @CAL_Sp_SaveButtonPushed, true);
            app.CAL_Sp_SaveButton.Text = 'Save';
            app.CAL_Sp_ResetButton = uibutton(app.CAL_SpectrometerCalibrationPanel, 'push');
            app.CAL_Sp_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @CAL_Sp_ResetButtonPushed, true);
            app.CAL_Sp_ResetButton.Text = 'Reset';
            app.CAL_ShaperCalibrationPanel = uipanel(app.SpectrometerCalibration);
            app.CAL_ShaperCalibrationPanel.Title = 'Pulse Shaper Calibration';
            app.CAL_Sh_LoadPumpSpectrumButton = uibutton(app.CAL_ShaperCalibrationPanel, 'push');
            app.CAL_Sh_LoadPumpSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @CAL_Sh_LoadPumpSpectrumButtonPushed, true);
            app.CAL_Sh_LoadPumpSpectrumButton.Text = 'Load Pump Spectrum';
            app.CAL_Sh_Lamp1 = uilamp(app.CAL_ShaperCalibrationPanel);
            app.CAL_Sh_LoadSolventSpectrumButton = uibutton(app.CAL_ShaperCalibrationPanel, 'push');
            app.CAL_Sh_LoadSolventSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @CAL_Sh_LoadSolventSpectrumButtonPushed, true);
            app.CAL_Sh_LoadSolventSpectrumButton.Text = 'Load Svt./Filt. Spectrum';
            app.CAL_Sh_Lamp2 = uilamp(app.CAL_ShaperCalibrationPanel);
            app.CAL_Sh_LoadFTIRCalibrationButton = uibutton(app.CAL_ShaperCalibrationPanel, 'push');
            app.CAL_Sh_LoadFTIRCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CAL_Sh_LoadFTIRCalibrationButtonPushed, true);
            app.CAL_Sh_LoadFTIRCalibrationButton.Text = 'Load Calibration Spectrum';
            app.CAL_Sh_Lamp3 = uilamp(app.CAL_ShaperCalibrationPanel);
            app.CAL_Sh_LoadMaskScansButton = uibutton(app.CAL_ShaperCalibrationPanel, 'push');
            app.CAL_Sh_LoadMaskScansButton.ButtonPushedFcn = createCallbackFcn(app, @CAL_Sh_LoadMaskScansButtonPushed, true);
            app.CAL_Sh_LoadMaskScansButton.Text = 'Load Mask Scans';
            app.CAL_Sh_Lamp4 = uilamp(app.CAL_ShaperCalibrationPanel);
            app.CAL_Sh_FitButton = uibutton(app.CAL_ShaperCalibrationPanel, 'push');
            app.CAL_Sh_FitButton.Enable = 'off';
            app.CAL_Sh_FitButton.Visible = 'off';
            app.CAL_Sh_FitButton.Text = 'Fit Manually';
            app.CAL_Sh_AutoCalButton = uibutton(app.CAL_ShaperCalibrationPanel, 'push');
            app.CAL_Sh_AutoCalButton.ButtonPushedFcn = createCallbackFcn(app, @CAL_Sh_AutoCalButtonPushed, true);
            app.CAL_Sh_AutoCalButton.Text = 'Do Automatic Calibration';
            app.CAL_Sh_SaveButton = uibutton(app.CAL_ShaperCalibrationPanel, 'push');
            app.CAL_Sh_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @CAL_Sh_SaveButtonPushed, true);
            app.CAL_Sh_SaveButton.Text = 'Save';
            app.CAL_Sh_ResetButton = uibutton(app.CAL_ShaperCalibrationPanel, 'push');
            app.CAL_Sh_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @CAL_Sp_ResetButtonPushed, true);
            app.CAL_Sh_ResetButton.Text = 'Reset';
            app.Panel = uipanel(app.DataAnalysisGUI_UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.DataloadingstatusLampLabel = uilabel(app.GridLayout);
            app.DataloadingstatusLampLabel.Text = 'Data loading status';
            app.DataStatusLamp = uilamp(app.GridLayout);
            app.DataStatusLamp.Tooltip = {'Green indicates that there is FTIR data loaded in the current session. Can be potentially used in the transient spectra and 2D-IR plots.'};
            app.SSSploadedLampLabel = uilabel(app.GridLayout);
            app.SSSploadedLampLabel.Text = 'SS-Sp loaded';
            app.SSSploadedLamp = uilamp(app.GridLayout);
            app.SSSploadedLamp.Tooltip = {'Green indicates that there is FTIR data loaded in the current session. Can be potentially used in the transient spectra and 2D-IR plots.'};
            app.InteractivemodeLampLabel = uilabel(app.GridLayout);
            app.InteractivemodeLampLabel.Text = 'Interactive mode';
            app.InteractivemodeLamp = uilamp(app.GridLayout);
            app.InteractivemodeLamp.Tooltip = {'Green if Interactive mode is enabled'; ' Red if manual mode is enabled.'};
            app.StatusEditFieldLabel = uilabel(app.GridLayout);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusMessage = uieditfield(app.GridLayout, 'text');
            app.StatusMessage.Editable = 'off';
            app.StatusMessage.Value = 'Everything is OK! :)';
            app.VersionTextLabel = uilabel(app.GridLayout);
            app.VersionTextLabel.Text = 'Version Text';
            % Show the figure after all components are created
            app.DataAnalysisGUI_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewGUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DataAnalysisGUI_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DataAnalysisGUI_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataAnalysisGUI_UIFigure)
        end
    end
end